classdef WellenpaketGenerator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup10                      matlab.ui.container.TabGroup
        WellenpaketgenerierenTab        matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup7                       matlab.ui.container.TabGroup
        ExportWaveTUBTab                matlab.ui.container.Tab
        TabGroup9                       matlab.ui.container.TabGroup
        DatenausgabeWaveTUBTab          matlab.ui.container.Tab
        GridLayout22                    matlab.ui.container.GridLayout
        ExternesWellenpaketPanel        matlab.ui.container.Panel
        GridLayout24                    matlab.ui.container.GridLayout
        AusgabedatenladenCheckBox_2     matlab.ui.control.CheckBox
        WaveTUBfrexternesWellenpaketausfhrenButton  matlab.ui.control.Button
        AktuellgeladenesWellenpaketPanel  matlab.ui.container.Panel
        GridLayout23                    matlab.ui.container.GridLayout
        AusgabedatenladenCheckBox       matlab.ui.control.CheckBox
        WaveTUBausfhrenCheckBox         matlab.ui.control.CheckBox
        neuenAusgabeOrdnererstellenCheckBox  matlab.ui.control.CheckBox
        prowbxproparVariablenAusgabeWaveTUBButton  matlab.ui.control.Button
        KlappenfunktionprowbxTab        matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        PistonTab                       matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        KlappenmodusDropDown            matlab.ui.control.DropDown
        KlappenmodusDropDownLabel       matlab.ui.control.Label
        longEditField                   matlab.ui.control.NumericEditField
        longEditField_2Label            matlab.ui.control.Label
        T_versatzEditField              matlab.ui.control.NumericEditField
        T_versatzEditFieldLabel         matlab.ui.control.Label
        a_max_sollEditField             matlab.ui.control.NumericEditField
        a_max_sollEditFieldLabel        matlab.ui.control.Label
        a_max_istEditField              matlab.ui.control.NumericEditField
        a_max_istEditFieldLabel         matlab.ui.control.Label
        Z1EditField                     matlab.ui.control.NumericEditField
        Z1EditFieldLabel                matlab.ui.control.Label
        ExportprowbxButton              matlab.ui.control.Button
        AnwendenPlotButton              matlab.ui.control.Button
        singleFlapTab                   matlab.ui.container.Tab
        dobbleFlapTab                   matlab.ui.container.Tab
        ParametereingabeproparTab       matlab.ui.container.Tab
        GridLayout12                    matlab.ui.container.GridLayout
        PositionenYEditField            matlab.ui.control.EditField
        PositionenYEditFieldLabel       matlab.ui.control.Label
        PostionenXEditField             matlab.ui.control.EditField
        PostionenXEditFieldLabel        matlab.ui.control.Label
        NPEGELPositionenX1X2Y1Y2Label   matlab.ui.control.Label
        mitLEditField                   matlab.ui.control.NumericEditField
        mitLEditFieldLabel              matlab.ui.control.Label
        NPEGELPositionenmitLeerzichengetrenntLabel  matlab.ui.control.Label
        Empfehlungalpha225Nx32mLNz20Label  matlab.ui.control.Label
        PositionenEditField             matlab.ui.control.EditField
        PositionenEditFieldLabel        matlab.ui.control.Label
        ExportProDateiButton            matlab.ui.control.Button
        NTRACKEditField                 matlab.ui.control.NumericEditField
        NTRACKEditFieldLabel            matlab.ui.control.Label
        NPEGELEditField                 matlab.ui.control.NumericEditField
        NPEGELEditFieldLabel            matlab.ui.control.Label
        NFOTOEditField                  matlab.ui.control.NumericEditField
        NFOTOEditFieldLabel             matlab.ui.control.Label
        ENDTIMEEditField_anzeige        matlab.ui.control.NumericEditField
        ENDTIMEEditField_2Label         matlab.ui.control.Label
        DTEditField_anzeige             matlab.ui.control.NumericEditField
        DTEditField_2Label              matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        NZEditField                     matlab.ui.control.NumericEditField
        NZEditFieldLabel                matlab.ui.control.Label
        NXEditField                     matlab.ui.control.NumericEditField
        NXEditFieldLabel                matlab.ui.control.Label
        longEditField_anzeige           matlab.ui.control.NumericEditField
        longEditFieldLabel_2            matlab.ui.control.Label
        Z1EditField_anzeige             matlab.ui.control.NumericEditField
        Z1EditFieldLabel_2              matlab.ui.control.Label
        TIEFEEditField_anzeige          matlab.ui.control.NumericEditField
        TIEFEEditField_2Label           matlab.ui.control.Label
        LAENGEEditField_anzeige         matlab.ui.control.NumericEditField
        LAENGEEditField_2Label          matlab.ui.control.Label
        PlotTab                         matlab.ui.container.Tab
        TabGroup8                       matlab.ui.container.TabGroup
        OrtsbereichTab                  matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        plotSteilheitCheckBox           matlab.ui.control.CheckBox
        sLabel_10                       matlab.ui.control.Label
        ZeitpunktabsolutEditField       matlab.ui.control.NumericEditField
        ZeitpunktabsolutEditFieldLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        tTLabel                         matlab.ui.control.Label
        ZeitpunktSliderLabel            matlab.ui.control.Label
        ZeitpunktSlider                 matlab.ui.control.Slider
        ZeitpunktrelativEditFieldLabel  matlab.ui.control.Label
        ZeitpunktrelativEditField       matlab.ui.control.NumericEditField
        ZeitbereichTab                  matlab.ui.container.Tab
        GridLayout8_2                   matlab.ui.container.GridLayout
        mLabel_11                       matlab.ui.control.Label
        OrtspunktabsolutEditField       matlab.ui.control.NumericEditField
        OrtspunktabsolutEditFieldLabel  matlab.ui.control.Label
        xLLabel_5                       matlab.ui.control.Label
        OrtspunktSliderLabel            matlab.ui.control.Label
        OrtspunktSlider                 matlab.ui.control.Slider
        OrtspunktrelativEditFieldLabel  matlab.ui.control.Label
        OrtspunktrelativEditField       matlab.ui.control.NumericEditField
        VideoTab                        matlab.ui.container.Tab
        GridLayout8_3                   matlab.ui.container.GridLayout
        tTLabel_2                       matlab.ui.control.Label
        sLabel_12                       matlab.ui.control.Label
        sLabel_11                       matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        STOPCheckBox                    matlab.ui.control.CheckBox
        FertigLamp_2                    matlab.ui.control.Lamp
        VideoErstellenButton            matlab.ui.control.Button
        VideolaengeberechnetEditFieldLabel  matlab.ui.control.Label
        VideolaengeberechnetEditField   matlab.ui.control.NumericEditField
        FramerateberechnetEditFieldLabel  matlab.ui.control.Label
        FramerateberechnetEditField     matlab.ui.control.NumericEditField
        t_v_endEditField                matlab.ui.control.NumericEditField
        bisEditFieldLabel               matlab.ui.control.Label
        t_v_begEditField                matlab.ui.control.NumericEditField
        ZeitfenstervonEditFieldLabel    matlab.ui.control.Label
        ZoomOrtEditField_2Label         matlab.ui.control.Label
        ZoomOrtEditField_2              matlab.ui.control.NumericEditField
        AbspielgeschindigkeitEditField  matlab.ui.control.NumericEditField
        AbspielgeschindigkeitEditFieldLabel  matlab.ui.control.Label
        KomprimierungSpinner            matlab.ui.control.Spinner
        KomprimierungSpinnerLabel       matlab.ui.control.Label
        ExportWellenkanalTab            matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        StandortdatenTab                matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        EingabefrWavetubTab             matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        ENDTIMEEditField                matlab.ui.control.NumericEditField
        ENDTIMEEditFieldLabel           matlab.ui.control.Label
        KonzpunktEditField_WaveTUB      matlab.ui.control.NumericEditField
        KonzentrationszeitpunktLabel    matlab.ui.control.Label
        ausw_endLabel                   matlab.ui.control.Label
        ParameterverwendenWaveTUBCheckBox  matlab.ui.control.CheckBox
        NEditField                      matlab.ui.control.NumericEditField
        NEditFieldLabel                 matlab.ui.control.Label
        sLabel_2                        matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        xLLabel_2                       matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        MaxAmplitudeEditField_WaveTUB   matlab.ui.control.NumericEditField
        AmplitudeLabel                  matlab.ui.control.Label
        ReferenzstelleAmplitudenkaliebrierungLabel  matlab.ui.control.Label
        MaxAmplitudeOrtEditField_WaveTUB  matlab.ui.control.NumericEditField
        AuflsungOrtsbereichEditField_3Label  matlab.ui.control.Label
        AuflsungOrtsbereichEditField_WaveTUB  matlab.ui.control.NumericEditField
        DTEditField                     matlab.ui.control.NumericEditField
        DTEditFieldLabel                matlab.ui.control.Label
        TIEFEEditField                  matlab.ui.control.NumericEditField
        TIEFEEditFieldLabel             matlab.ui.control.Label
        LAENGEEditField                 matlab.ui.control.NumericEditField
        LAENGEEditFieldLabel            matlab.ui.control.Label
        EingabeStandardTab              matlab.ui.container.Tab
        GridLayout3_2                   matlab.ui.container.GridLayout
        ParameterverwendenKanalCheckBox  matlab.ui.control.CheckBox
        mLabel_8                        matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        xLLabel_4                       matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        xLLabel_3                       matlab.ui.control.Label
        sLabel_3                        matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        MaxAmplitudeEditField           matlab.ui.control.NumericEditField
        istEditFieldLabel               matlab.ui.control.Label
        maximaleAplitudebeiEditFieldLabel  matlab.ui.control.Label
        MaxAmplitudeOrtEditField        matlab.ui.control.NumericEditField
        AuflsungOrtsbereichEditFieldLabel  matlab.ui.control.Label
        AuflsungOrtsbereichEditField    matlab.ui.control.NumericEditField
        Konzpunkt_absolutEditField      matlab.ui.control.NumericEditField
        beiLEditField_2Label_2          matlab.ui.control.Label
        KonzentrationspunktEditFieldLabel_2  matlab.ui.control.Label
        Konzpunkt_relativEditField      matlab.ui.control.NumericEditField
        AbtastfrequenzEditField         matlab.ui.control.NumericEditField
        AbtastfrequenzEditFieldLabel    matlab.ui.control.Label
        TiefeEditField                  matlab.ui.control.NumericEditField
        TiefeEditFieldLabel             matlab.ui.control.Label
        LaengeEditField                 matlab.ui.control.NumericEditField
        LaengeEditFieldLabel            matlab.ui.control.Label
        ErdbeschleunigungEditFieldLabel  matlab.ui.control.Label
        ErdbeschleunigungEditField      matlab.ui.control.NumericEditField
        AmplitudenverlaufTab            matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        AmplitudenverlaufladenTab       matlab.ui.container.Tab
        GridLayout25                    matlab.ui.container.GridLayout
        plotbiswLabel                   matlab.ui.control.Label
        plotbiswAmplitudenverlaufLaden  matlab.ui.control.NumericEditField
        AmplitudeLadenButton            matlab.ui.control.Button
        UIAxesAmplituedenverlaufGeladen  matlab.ui.control.UIAxes
        HenningscherVerlaufTab          matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        GridLayout5                     matlab.ui.container.GridLayout
        sLabel_5                        matlab.ui.control.Label
        sLabel_4                        matlab.ui.control.Label
        PlotHenningButton               matlab.ui.control.Button
        w_endEditFieldLabel             matlab.ui.control.Label
        w_endEditField                  matlab.ui.control.NumericEditField
        w_begEditFieldLabel             matlab.ui.control.Label
        w_begEditField                  matlab.ui.control.NumericEditField
        UIAxes2                         matlab.ui.control.UIAxes
        regulreWellenTab                matlab.ui.container.Tab
        GridLayout10                    matlab.ui.container.GridLayout
        AusgabenachBerechnenLabel_2     matlab.ui.control.Label
        AusgabenachBerechnenLabel       matlab.ui.control.Label
        sLabel_7                        matlab.ui.control.Label
        mLabel_10                       matlab.ui.control.Label
        WellenperiodeIstwertEditField   matlab.ui.control.NumericEditField
        WellenperiodeIstwertEditFieldLabel  matlab.ui.control.Label
        WellenlngeIstwertEditField      matlab.ui.control.NumericEditField
        WellenlngeIstwertEditFieldLabel  matlab.ui.control.Label
        sLabel_6                        matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        WellenperiodeSollwertEditField  matlab.ui.control.NumericEditField
        WellenperiodeSollwertEditFieldLabel  matlab.ui.control.Label
        WellenlngeSollwertEditField     matlab.ui.control.NumericEditField
        WellenlngeSollwertEditFieldLabel  matlab.ui.control.Label
        konstanteWellensteilheitTab     matlab.ui.container.Tab
        GridLayout4_2                   matlab.ui.container.GridLayout
        PlotkonstanteWellensteilheitButton  matlab.ui.control.Button
        HllkurveanwendenCheckBox        matlab.ui.control.CheckBox
        TabGroup4                       matlab.ui.container.TabGroup
        GrenzfrequnezenTab              matlab.ui.container.Tab
        GridLayout5_2                   matlab.ui.container.GridLayout
        AchtungSteilheitskaliertsichberAmlitudeLabel  matlab.ui.control.Label
        SteilheitEditField              matlab.ui.control.NumericEditField
        SteilheitEditFieldLabel         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        sLabel_9                        matlab.ui.control.Label
        sLabel_8                        matlab.ui.control.Label
        w_endEditField_2Label           matlab.ui.control.Label
        w_endEditField_2                matlab.ui.control.NumericEditField
        w_begEditField_2Label           matlab.ui.control.Label
        w_begEditField_2                matlab.ui.control.NumericEditField
        HllkurveTab                     matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        a_rechtsSliderLabel             matlab.ui.control.Label
        a_rechtsSlider                  matlab.ui.control.Slider
        a_linksSlider                   matlab.ui.control.Slider
        a_linksSliderLabel              matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        PlotVideoLabel                  matlab.ui.control.Label
        BerechnungEinlesenLabel         matlab.ui.control.Label
        DateneingabeLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        neuenAusgabeOrdnererstellenCheckBox_2  matlab.ui.control.CheckBox
        ladenButton                     matlab.ui.control.Button
        speichernButton                 matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        StopCheckBox                    matlab.ui.control.CheckBox
        FertigLamp                      matlab.ui.control.Lamp
        FortschrittEditFieldLabel       matlab.ui.control.Label
        FortschrittEditField            matlab.ui.control.NumericEditField
        AmplitudenmodusDropDownLabel    matlab.ui.control.Label
        AmplitudenmodusDropDown         matlab.ui.control.DropDown
        BerechnenButton                 matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        PlotWavetubTab                  matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        GridLayout17                    matlab.ui.container.GridLayout
        DataDateispeichernautomatischbeimLadenCheckBox  matlab.ui.control.CheckBox
        Lamp                            matlab.ui.control.Lamp
        VersuchLadenButton              matlab.ui.control.Button
        TabGroup12                      matlab.ui.container.TabGroup
        VersuchsdatenTab                matlab.ui.container.Tab
        TableVersuchsdaten              matlab.ui.control.Table
        DateiInfosTab                   matlab.ui.container.Tab
        TableDateiinfos                 matlab.ui.control.Table
        TabGroup11                      matlab.ui.container.TabGroup
        Surface_OrtsplotTab             matlab.ui.container.Tab
        TabGroup13                      matlab.ui.container.TabGroup
        OrtsbereichTab_2                matlab.ui.container.Tab
        GridLayout8_5                   matlab.ui.container.GridLayout
        Zeitpunktabsolut_WaveTUBEditField  matlab.ui.control.NumericEditField
        ZeitpunktabsolutEditField_2Label  matlab.ui.control.Label
        plotWellensteilheitundbrechenCheckBox_2  matlab.ui.control.CheckBox
        sLabel_15                       matlab.ui.control.Label
        tTLabel_4                       matlab.ui.control.Label
        ZeitpunktSlider_2Label          matlab.ui.control.Label
        Zeitpunkt_WaveTUBSlider         matlab.ui.control.Slider
        ZeitpunktrelativEditField_2Label  matlab.ui.control.Label
        Zeitpunktrelativsurface_WaveTUBEditField  matlab.ui.control.NumericEditField
        VideoTab_2                      matlab.ui.container.Tab
        GridLayout8_4                   matlab.ui.container.GridLayout
        IdealverlaufplottenCheckBox     matlab.ui.control.CheckBox
        STOP_WaveTUBCheckBox            matlab.ui.control.CheckBox
        WellensteilheitundBrechenCheckBox  matlab.ui.control.CheckBox
        tTLabel_3                       matlab.ui.control.Label
        sLabel_14                       matlab.ui.control.Label
        sLabel_13                       matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Fertig_WaveTUBLamp              matlab.ui.control.Lamp
        VideoErstellen_WaveTUBButton    matlab.ui.control.Button
        VideolaengeberechnetEditField_2Label  matlab.ui.control.Label
        Videolaengeberechnet_WaveTUBEditField  matlab.ui.control.NumericEditField
        FramerateberechnetEditField_2Label  matlab.ui.control.Label
        Framerateberechnet_WaveTUBEditField  matlab.ui.control.NumericEditField
        t_v_end_WaveTUBEditField        matlab.ui.control.NumericEditField
        bisEditFieldLabel_2             matlab.ui.control.Label
        t_v_beg_WaveTUBEditField        matlab.ui.control.NumericEditField
        ZeitfenstervonEditFieldLabel_2  matlab.ui.control.Label
        Abspielgeschindigkeit_WaveTUBEditField  matlab.ui.control.NumericEditField
        AbspielgeschindigkeitEditField_2Label  matlab.ui.control.Label
        Komprimierung_WaveTUBSpinner    matlab.ui.control.Spinner
        KomprimierungSpinner_2Label     matlab.ui.control.Label
        Surface_ZeitplotTab             matlab.ui.container.Tab
        GridLayout8_6                   matlab.ui.container.GridLayout
        plotWellensteilheitundbrechenCheckBox  matlab.ui.control.CheckBox
        mLabel_12                       matlab.ui.control.Label
        Ortspunktabsolut_WaveTUBEditField  matlab.ui.control.NumericEditField
        ZeitpunktabsolutEditField_2Label_2  matlab.ui.control.Label
        mLLabel                         matlab.ui.control.Label
        ZeitpunktSlider_2Label_2        matlab.ui.control.Label
        Ortspunkt_WaveTUBSlider         matlab.ui.control.Slider
        ZeitpunktrelativEditField_2Label_2  matlab.ui.control.Label
        Ortspunktrelativsurface_WaveTUBEditField  matlab.ui.control.NumericEditField
        PartikelTrackTab                matlab.ui.container.Tab
        GridLayout18_2                  matlab.ui.container.GridLayout
        GridLayout19_2                  matlab.ui.container.GridLayout
        PlotUITrackButton               matlab.ui.control.Button
        PlotnachTrackDropDown           matlab.ui.control.DropDown
        FensterauswhlenLabel_2          matlab.ui.control.Label
        TrackauswhlenDropDown           matlab.ui.control.DropDown
        TrackauswhlenDropDownLabel      matlab.ui.control.Label
        FouriertransformationTab        matlab.ui.container.Tab
        GridLayout21                    matlab.ui.container.GridLayout
        plotbiswEditField               matlab.ui.control.NumericEditField
        plotbiswEditFieldLabel          matlab.ui.control.Label
        plotUIFourierButton             matlab.ui.control.StateButton
        PlotnachFourierDropDown         matlab.ui.control.DropDown
        FensterauswhlenLabel            matlab.ui.control.Label
        PegelauswhlenDropDown           matlab.ui.control.DropDown
        PegelauswhlenDropDownLabel      matlab.ui.control.Label
        EinzelpegelTab                  matlab.ui.container.Tab
        GridLayout18                    matlab.ui.container.GridLayout
        GridLayout19_3                  matlab.ui.container.GridLayout
        PlotUIPegelButton               matlab.ui.control.Button
        PegelDropDown                   matlab.ui.control.DropDown
        PegelauswahlDropDownLabel       matlab.ui.control.Label
        UIAxesWaveTUBSurface            matlab.ui.control.UIAxes
        UIAxesWaveTUBKlein02            matlab.ui.control.UIAxes
        UIAxesWaveTUBKlein01            matlab.ui.control.UIAxes
        UIAxesWaveTUBPegel              matlab.ui.control.UIAxes
        UIAxesWaveTUBKlein03            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %Testbecken und Messgeräte
        WP={}
                                        %Paramertereingabe: Vergrößert Zeitfenster gleichmäig davor und dannach 
       
                            
        WaveTUBData={};
        dirname=[]                                     %Versuchsverzeichnis
        Fourierdata={}                                              %Fouierbereich gemessene Pegel Wavetub 
         % Description
        Varapp={}
        surface={}
        track={}
        Gesamtenversuchladendirekt=false;
        TableGesamt={}; 
        Amplitudenkalibrierung=3; 
                                              %Wellengenerator Variablen
                                        %Grundsätizlich: (örtliche) Auflösung immer so groß dass kleinste Frequnenz dargestellt werden kann * aufl_Ort                      
        t_plotslider=0;                                %Plot: relativer Zeitpunkt, Slider 
        t_plotslider_WaveTUB=0
        t_plotbutton=0;
        t_plotbutton_WaveTUB=0; 
                                 %Maximale Amplitude in Ort und Zeit 
        x_plotslider=0;                                %Plot: relativer Ortspunkt Slider
        zoom_Ort=1;                                    %Plot: Zoom Ort 
        zoom_Ort_WaveTUB=1;
        zoom_Zeit=1;                                   %Plot: Zoom Zeit
        framerate=0;                                   %Video: Framerate                             %
        framerate_WaveTUB=0;
        T_v_beg=0;                                     %Video: absoluter Startzeitpunkt Aufnahme 
        T_v_end=0;                                     %Video: absoluter Endzeitpunkt Aufnahme 
        T_v_beg_WaveTUB=0;                             %Video: absoluter Startzeitpunkt Aufnahme 
        T_v_end_WaveTUB=0;
        kompressionZeit=0;                             %Video: "Ausdünnung" um Faktor kompression zeit,immer jeder n. Frame für kleinere Videos/Framerate
        kompressionZeit_WaveTUB=0;
        T_video=0;                                     %Video: absloute Gesamtzeit des Videos
        T_video_WaveTUB=0;   
        x_plotslider_WaveTUB=0; 
      
        x_plotEditField_WaveTUB=0;  % Description
    end
    
    
    methods (Access = private)
        
        function updateplotOrt(app,xi,a,N_anz,zoom) 
            %Updated Ortsverlauf und Plotet in UIAxes, skalier auf 
            %Maximale Amplitude 
            %N_anz: Indize für Benötigten Zeitpunkt (N_anz aus N) 
            
            x = xi; 
            y = a(:,N_anz)*100;
            axis(app.UIAxes, 'tight');
            plot(app.UIAxes,x,y); 
            app.UIAxes.YLim = [-app.WP.a_maxmax*100/zoom app.WP.a_maxmax*100/zoom];
            title(app.UIAxes,['Ortsverlauf bei T=',num2str(N_anz/app.WP.N*app.WP.T)])
            xlabel(app.UIAxes,'Ort in [m]') 
            ylabel(app.UIAxes,'Auslenkung in [cm]') 
        end
        
        function updateplotZeit(app,t,a,N_anz,zoom)
            %Updated Zeitverlauf und Plotet in UIAxes, skaliert auf
            %Maximale Amplitude
            %N_anz: Indize für Benötigten Ortspunkt (N_anz aus N_x) 
            
            x = t;
            y = a(N_anz,:)*100;
            axis(app.UIAxes, 'tight');
            plot(app.UIAxes,x,y); 
            app.UIAxes.YLim = [-app.WP.a_maxmax*100/zoom app.WP.a_maxmax*100/zoom];
            title(app.UIAxes,'Zeitverlauf')
            xlabel(app.UIAxes,'Zeit in [s]') 
            ylabel(app.UIAxes,'Auslenkung in [cm]') 
            
        end
        
        function konstWellensteilhheit_Huellkurve(app)
            
            
            Grundvariablen(app)
            app.WP.a_huellkurve_links=app.a_linksSlider.Value;
            app.WP.a_huellkurve_rechts=app.a_rechtsSlider.Value;
            
          
            app.WP.Huellkurve=zeros(app.WP.N,1);
            
            N_beg_Rampe_hoch=round(app.WP.w_beg/app.WP.d_w);
  
            N_end_Rampe_hoch=round(((app.WP.w_end-app.WP.w_beg)/2*app.WP.a_huellkurve_links+app.WP.w_beg)/app.WP.d_w);

            N_beg_Rampe_runter=round((app.WP.w_end-((app.WP.w_end-app.WP.w_beg)/2*app.WP.a_huellkurve_rechts))/app.WP.d_w);

            N_end_Rampe_runter=round(app.WP.w_end/app.WP.d_w);
  

            app.WP.Huellkurve(N_beg_Rampe_hoch:N_end_Rampe_hoch)=sin(pi()/(app.WP.w_end-app.WP.w_beg).*app.WP.w(1:N_end_Rampe_hoch-N_beg_Rampe_hoch+1)/app.WP.a_huellkurve_links).^2;
           
            app.WP.Huellkurve(N_beg_Rampe_runter:N_end_Rampe_runter)=cos(pi()/(app.WP.w_end-app.WP.w_beg).*app.WP.w(1:N_end_Rampe_runter-N_beg_Rampe_runter+1)/app.WP.a_huellkurve_rechts).^2;
            
             
            app.WP.Huellkurve(N_end_Rampe_hoch+1:N_beg_Rampe_runter-1)=1;


        end
        
        function Grundvariablen(app)
            
        if app.ParameterverwendenWaveTUBCheckBox.Value==true
            
            app.WP.L=app.LAENGEEditField.Value; 
            app.WP.d=app.TIEFEEditField.Value;
            app.WP.d_t=app.DTEditField.Value; 
            
            k_wend=app.WP.w_end^2/app.WP.g;

            k_alt=0; 
        
            i=0; 
          

            while (max(abs(k_alt-k_wend))>0.00001 && i<10000)
                k_alt=k_wend; 
                k_wend=app.WP.w_end.^2./(tanh(k_wend*app.WP.d)*app.WP.g); 
                i=i+1;
            end
            
            app.WP.k(1) = 0;

            app.WP.T = round(app.WP.L/(1/2*app.WP.w_end/k_wend*(1+2*k_wend*app.WP.d/sinh(2*k_wend*app.WP.d))));
            
            app.ENDTIMEEditField.Value=app.WP.T; 
            app.WP.t_shift=app.KonzpunktEditField_WaveTUB.Value; 
            app.WP.aufl_Ort=app.AuflsungOrtsbereichEditField_WaveTUB.Value;
            app.WP.x_skal=app.MaxAmplitudeOrtEditField_WaveTUB.Value; 
            app.WP.a_skal=app.MaxAmplitudeEditField_WaveTUB.Value; 
            app.NEditField.Value=round(app.WP.T/app.WP.d_t);


            %Anzahl der Stüttstellen: N=T*f (wie viele pro sekunde, wie viele
            %sekunden lang) 
            app.WP.N=round(app.WP.T/app.WP.d_t);
            
            %Zeitbereich mit N stellen mit d_t als abstand
            
            app.WP.t=linspace(0,app.WP.T-app.WP.T/app.WP.N,app.WP.N); 
            
            
            %kleinste (also 1.) Freqenz enstpricht einer Welle mit einer
            %Periode von T (Gesamtzeit) => T=1/f => w=2*pi/f =>
            %w=2pi/T=2pi/(d_t*N) die kleinste Freq enspricht der Schrittweite
            %d_w
            app.WP.d_w=2*pi()/(app.WP.d_t*app.WP.N);
            
            %größte Freuenz w_N mit w=2pi*f=2pi*1/(2*d_t)=pi/f
            w_N=2*pi()/app.WP.d_t;
            
            %Kreisfrequenzbereich mit N stellen mit d_w als abstand 
            app.WP.w=linspace(0,w_N-app.WP.d_w,app.WP.N);
          
            %Dispersionsgleichung lösen von nach k 
            k0=app.WP.w.^2/app.WP.g;
            
            app.WP.k=k0;
            
            N_wend=round(app.WP.w_end/app.WP.d_w);
            app.WP.d_x=2*pi()/(2*app.WP.k(N_wend))/app.WP.aufl_Ort;
            
            app.WP.k=k0;
            k_alt=0; 
            i=0; 
            while (max(abs(k_alt-app.WP.k))>0.00001 && i<10000)
                k_alt=app.WP.k; 
                app.WP.k=app.WP.w.^2./(tanh(app.WP.k.*app.WP.d)*app.WP.g); 
                i=i+1; 
            end
            
            app.WP.k(1)=0;
             
        end 
         
        
        if app.ParameterverwendenKanalCheckBox.Value==true
            
            app.WP.L=app.LaengeEditField.Value; 
            app.WP.d=app.TiefeEditField.Value;
            app.WP.f=app.AbtastfrequenzEditField.Value;
            %app.ENDTIMEEditField.Value=app.T; 
            app.WP.t_shift=app.Konzpunkt_relativEditField.Value;  
            app.WP.aufl_Ort=app.AuflsungOrtsbereichEditField_2.Value;
            app.WP.x_skal=app.MaxAmplitudeOrtEditField.Value; 
            app.WP.a_skal=app.MaxAmplitudeEditField.Value; 
            
            %berechnet T, N, t, w, d_w, d_t, k 
            
            %lösen der Dispersionsgleichung 
            k0=app.WP.w_end^2/app.WP.g;
            app.WP.k=k0;
            k_alt=0; 
        
            k0=app.WP.w_end^2/app.WP.g;
            k_wend=k0;
            i=0; 
            while (max(abs(k_alt-k_w_end))>0.0000001 && i<1000)
                k_alt=k_wend; 
                k_wend=app.WP.w_end^2./(tanh(k_wend*app.WP.d)*app.WP.g);
                i=i+1; 
            end
            app.WP.T=round(app.WP.L/(1/2*app.WP.w_end/k_wend*(1+2*k_wend*app.WP.d/sinh(2*k_wend*app.WP.d))));
            
            
            %berechnen der Gesamtzeit T aus: c_gr=L/T=c/2*(1+...) mit c=w/k
            %Zeit, die die langsamste (w_end) Welle braucht, um den kanal zu
            %durchlaufen (mit c_gr) 
            app.WP.T=round(app.WP.L/(1/2*app.WP.w_end/k_wend*(1+2*k_wend*app.WP.d/sinh(2*k_wend*app.WP.d))));
            
            %Anzahl der Stüttstellen: N=T*f (wie viele pro sekunde, wie viele
            %sekunden lang) 
            app.WP.N=round(app.WP.T*app.WP.f);
            
            %Zeitbereich mit N stellen mit d_t als abstand
            app.WP.d_t=app.WP.T/app.WP.N;
            app.WP.t=linspace(0,app.WP.T-app.WP.T/app.WP.N,app.WP.N); 
            
            
            %kleinste (also 1.) Freqenz enstpricht einer Welle mit einer
            %Periode von T (Gesamtzeit) => T=1/f => w=2*pi/f =>
            %w=2pi/T=2pi/(d_t*N) die kleinste Freq enspricht der Schrittweite
            %d_w
            app.WP.d_w=2*pi()/(app.WP.d_t*app.WP.N);
            
            %größte Freuenz w_N mit w=2pi*f=2pi*1/(2*d_t)=pi/f
            w_N=2*pi()*app.WP.f;
            
            %Kreisfrequenzbereich mit N stellen mit d_w als abstand 
            app.WP.w=linspace(0,w_N-app.WP.d_w,app.WP.N);
            
            %Dispersionsgleichung lösen von nach k 
            k0=app.WP.w.^2/app.WP.g;
            
            app.WP.k=k0;
            k_alt=0; 
            i=0; 
        
            
            while (max(abs(k_alt-app.WP.k))>0.00001 && i<10000)
                k_alt=app.WP.k; 
                app.WP.k=app.WP.w.^2./(tanh(app.WP.k.*app.WP.d)*app.WP.g); 
                i=i+1; 
            end
            
            app.WP.k(1)=0;
             
        end 
    end
        
    function updateVariablenAnzeige(app)
        %app.ZeitfensterstreckungEditField=aa 
        app.ENDTIMEEditField.Value=app.WP.T;
        app.KonzpunktEditField_WaveTUB.Value=app.WP.t_shift;
        app.NEditField.Value=app.WP.N;
        app.MaxAmplitudeEditField_WaveTUB.Value=app.WP.a_skal;
        app.MaxAmplitudeOrtEditField_WaveTUB.Value=app.WP.x_skal; 
        app.AuflsungOrtsbereichEditField_WaveTUB.Value=app.WP.aufl_Ort;    
        app.DTEditField.Value=app.WP.d_t;
        app.TIEFEEditField.Value=app.WP.d;
        app.LAENGEEditField.Value=app.WP.L; 
        app.w_endEditField.Value=app.WP.w_end;
        app.w_begEditField.Value=app.WP.w_beg;      
        app.WellenlngeIstwertEditField.Value=app.WP.lamda_reg; 
        app.WellenlngeSollwertEditField.Value=0;
        app.WellenperiodeIstwertEditField.Value=app.WP.T_reg; 
        
        app.w_endEditField.Value=app.WP.w_end; 
        app.w_begEditField.Value=app.WP.w_beg; 
        app.w_endEditField_2.Value=app.WP.w_end; 
        app.w_begEditField_2.Value=app.WP.w_beg; 
        app.WP.z_1=-app.WP.d; 
        app.Z1EditField.Value=app.WP.z_1;
        
        
        app.a_rechtsSlider.Value=app.WP.a_huellkurve_rechts;
        app.a_linksSlider.Value=app.WP.a_huellkurve_links;
        app.AmplitudenmodusDropDown.Value=app.WP.Amplitudenmodus; 
        app.NEditField.Value=app.WP.N; 
       
        
        app.WellenlngeSollwertEditField.Value=0;
        app.WellenlngeSollwertEditField.Value=app.WP.T_reg; 

        end
        
        function plotAmplituenverlaufGeladen(app)
            
            N_wend=app.plotbiswAmplitudenverlaufLaden.Value/app.WP.w(end)*size(app.WP.w,2);
            plot(app.UIAxesAmplituedenverlaufGeladen,app.WP.w(1:N_wend),app.WP.A_real(1:N_wend))
            cla(app.UIAxesAmplituedenverlaufGeladen) 
         
            %plot
            hold(app.UIAxesAmplituedenverlaufGeladen,'on')
            scatter(app.UIAxesAmplituedenverlaufGeladen,app.WP.w(1:N_wend),app.WP.A_real(1:N_wend))
            plot(app.UIAxesAmplituedenverlaufGeladen,app.WP.w(1:N_wend),app.WP.A_real(1:N_wend))
            
            
            title(app.UIAxesAmplituedenverlaufGeladen,'Amplitudenverlauf')
            xlabel(app.UIAxesAmplituedenverlaufGeladen,'Kreisfrequenz in [1/s]') 
            ylabel(app.UIAxesAmplituedenverlaufGeladen,'Amplitude in [1]') 
            
            xl=max(xlim(app.UIAxesAmplituedenverlaufGeladen));
            yl=max(ylim(app.UIAxesAmplituedenverlaufGeladen));


            text(app.UIAxesAmplituedenverlaufGeladen,0.8*xl,0.9*yl,['w_{beg}: ',num2str(app.WP.w_beg)])
            text(app.UIAxesAmplituedenverlaufGeladen,0.8*xl,0.8*yl,['w_{end}: ',num2str(app.WP.w_end)])
          
            
            hold(app.UIAxesAmplituedenverlaufGeladen,'off')
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.WP.g=9.8070;                                        %Erdbeschleunigung
            app.WP.L=10;                                          %Beckenlänge
            app.WP.d=30;                                          %Beckentiefe
            app.WP.f=10;                                          %Abtastfrequenz Pegelmesser bzw Wellenmaschine
            app.WP.t_shift=0.5;                                   %Verschiebung (zeitlicht) des Konzentrationspunktes (relativ)
            app.WP.x_skal=0.5;                                    %Position (örtlich, relativ) der Maximalen Amlitude
            app.WP.a_skal=1;                                      %max Amplitude bei x_skal 
            app.WP.w_beg=1;                                       %Henning, kleinste Wellenkreisfrequenz
            app.WP.w_end=10;                                      %Henning, größte Wellenkreisfrequenz
            app.WP.aufl_Ort=10;                                   %Grundsätizlich: (örtliche) Auflösung immer so groß dass kleinste Frequnenz dargestellt werden kann * aufl_Ort                      
            
            app.WP.a=[];                                          %Feld der größe [N,1], Zeitbereich von Fourier, Pegelstände an N Punkten
            app.WP.t=0;                                           %Feld der größe [N,1], äquidistante Stütstellen, Zeit 
            app.WP.T=0;                                           %Gesamtzeit 
            app.WP.N=0;                                           %Anzahl der Zeitlichen Stützstellen
            app.WP.xi=0;                                          %Feld der größe [N_x,1], äquidistante Stütstellen, Ort
            app.WP.N_x=0;                                         %Anzahl der örtlichen Stützstellen
            app.WP.a_maxmax=0;                                    %Maximale Amplitude in Ort und Zeit 
            
            app.WP.Amplitudenmodus='Henning';                     %Art der Amplitudenverteilung 
            app.WP.w=0;                                           %Feld der größe [N,1], Frequenzbereich von Fourier, Amplitudenhöhe an N Punkten
            app.WP.A_real=[];                                     %Feld der Größe [N,1], Realteil des Komplexen Feld des Frequentbereichs Feld der Größe [N,1]
            app.WP.WB=[];                                         %kompexer Frequnezbereich des Wellenblatbewegung 
            app.WP.wb=[];                                         %reeler Zeitbereich der Wellenblartbewegung 
            app.WP.z_1=1;                                         %Dimension Wellenblatt
            app.WP.k=0;                                           %Feld der größe [N,1], nicht äquidistante Stütstellen, Wellenzahl, korrespondiert zu Feld w 
            app.WP.A=[];                                          %Feld der Größe [N,1], komplex, Frequenzbereich der Oberflächenauslenung 
            app.WP.lamda_reg=0;                                   %regelmäßige Wellen: Wellenlänge 
            app.WP.k_reg=0;                                       %regelmäßige Wellen: Wellenzahl 
            app.WP.w_reg=0;                                       %regelmäßige Wellen: Wellenkreisfrequenz Sollwert
            app.WP.T_reg=0;                                       %regelmäßige Wellen: Wellenperiode Sollwert
            app.WP.lamda_reg_ist=0;                               %regelmäßige Wellen: Wellenlänge Istwert
            app.WP.T_reg_ist=0;                                   %regelmäßige Wellen: Wellenperiode Istwert 
            app.WP.steilheit_max=0.2;                             %maximale Wellensteilheit: örtliche Ableitung der Oberflächenlinie an nulldurchgang 
            app.WP.a_huellkurve_links=1;                          %maximale Wellensteilheit: Steilheit der Hüllkurve links 1=flach 0=steil
            app.WP.a_huellkurve_rechts=1;                         %maximale Wellensteilheit: Steilheit der Hüllkurve rechts 1=flach 0=steil
            app.WP.n_huellkurve_links=1;
            app.WP.n_huellkurve_rechts=1; 
            app.WP.Huellkurve=[];
            app.WP.d_w=0; 
            app.WP.d_t=0; 
            app.WP.d_x=0; 
            app.WP.Steilheit_max=[];
   
            app.WP.long=0;
            app.WP.lamda_reg_ist=0; 
            app.WP.Klappenmodus='Piston';

            
            app.Amplitudenkalibrierung=1; 

            %Lampe auf Rot setzen 
            app.FertigLamp.Color='red'; 
 
            drawnow(); pause(.05)        
            
            %Plots initialisieren 
            
            %UIAxes: Großer Plot unten 
            
            plot(app.UIAxes,0,0); 
            
            title(app.UIAxes,'Ortsverlauf')
            xlabel(app.UIAxes,'Ort in [m]') 
            ylabel(app.UIAxes,'Auslenkung in [cm]') 
            
            
            %UIAxes2: Plot Amplitudenverlauf Henning 
            
            plot(app.UIAxes2,0,0)
            
            title(app.UIAxes2,'Amplituudenverlauf')
            xlabel(app.UIAxes2,'Kreisfrequenz in [1/s]') 
            ylabel(app.UIAxes2,'Amplitude in [1]') 
            
            Grundvariablen(app)
            RegWellenWellenperiodeSollwertEditFieldValueChanged(app)
            app.NXEditField.Value=round(app.WP.L*32); %Empfehlung aus Sasha Koslek Vortrag 
            
            app.WP.d=app.TIEFEEditField.Value; 
            app.Z1EditField.Value=-app.WP.d;
            app.WP.z_1=-app.WP.d; 


        end

        % Value changed function: ErdbeschleunigungEditField
        function ErdbeschleunigungEditFieldValueChanged(app, event)
            %Einlesen: g
            
            app.WP.g = app.ErdbeschleunigungEditField.Value;
            app.FertigLamp.Color='red';
        end

        % Value changed function: TiefeEditField
        function LaengeEditFieldValueChanged(app, event)
            %Einlesen: Länge
            %Wenn geändert wird ändert sich: 
            %   absolute Position des Konzentrationspunkt 
            
            app.WP.L = app.LaengeEditField.Value;
            app.Konzpunkt_absolutEditField.Value=app.WP.L*app.WP.t_shift; 
            
            app.FertigLamp.Color='r';
            
        end

        % Callback function
        function TiefeEditFieldValueChanged(app, event)
           %Einlesen: Tiefe 
           
           app.WP.d = app.TiefeEditField.Value;
           app.FertigLamp.Color='r';
          
        end

        % Value changed function: AbtastfrequenzEditField
        function AbtastfrequenzEditFieldValueChanged(app, event)
           %Einlesen: Abtastfrequenz
            
           app.WP.f = app.AbtastfrequenzEditField.Value;
           app.FertigLamp.Color='r';
          
        end

        % Value changed function: Konzpunkt_relativEditField
        function Konzpunkt_relativEditFieldValueChanged(app, event)
          %Einlesen: relative Position Konzentrationspunkt
          %Wenn geändert wird ändert sich: 
            %   absolute Position des Konzentrationspunkt 
            
          app.WP.t_shift = app.Konzpunkt_relativEditField.Value;
          app.Konzpunkt_absolutEditField.Value=app.WP.t_shift*app.WP.L; 
          app.FertigLamp.Color='r';
        
        end

        % Value changed function: Konzpunkt_absolutEditField
        function Konzpunkt_absolutEditFieldValueChanged(app, event)
          %Einlesen: absolute Position Konzentrationspunkt
          %Wenn geändert wird ändert sich: 
            %   relative Position des Konzentrationspunkt 
            
          app.Konzpunkt_relativEditField.Value=app.Konzpunkt_absolutEditField.Value/app.WP.L;
          app.WP.t_shift=app.Konzpunkt_relativEditField.Value;
          app.FertigLamp.Color='r';
          
        end

        % Value changed function: AuflsungOrtsbereichEditField
        function AuflsungOrtsbereichEditFieldValueChanged(app, event)
            %Einlesen: Auflösung Orstbereich
            
            app.WP.aufl_Ort = app.AuflsungOrtsbereichEditField_2.Value;
            app.FertigLamp.Color='r';
            
        end

        % Value changed function: AuflsungOrtsbereichEditField_WaveTUB
        function AuflsungOrtsbereichEditField_WaveTUBValueChanged(app, event)

            %Einlesen: Auflösung Orstbereich
            
            app.WP.aufl_Ort = app.AuflsungOrtsbereichEditField_WaveTUB.Value;
            app.FertigLamp.Color='r';
        end

        % Value changed function: MaxAmplitudeOrtEditField
        function MaxAmplitudeOrtditFieldValueChanged(app, event)
           %Einlesen: x_skal (Ort Maximale Amplitude)  
            
           app.WP.x_skal = app.MaxAmplitudeOrtEditField.Value;
           app.FertigLamp.Color='r';
        
        end

        % Value changed function: MaxAmplitudeOrtEditField_WaveTUB
        function MaxAmplitudeOrtEditField_WaveTUBValueChanged(app, event)
   
            %Einlesen: x_skal (Ort Maximale Amplitude)  
            
           app.WP.x_skal = app.MaxAmplitudeOrtEditField_WaveTUB.Value;
           
           app.FertigLamp.Color='r';
        end

        % Value changed function: MaxAmplitudeEditField
        function MaxAmplitudeEditFieldValueChanged(app, event)
           %Einlesen: a_skal (Maximale Amplitude)  
            
           app.WP.a_skal = app.MaxAmplitudeEditField.Value;
           app.FertigLamp.Color='r';
           
        end

        % Value changed function: MaxAmplitudeEditField_WaveTUB
        function MaxAmplitudeEditField_WaveTUBValueChanged(app, event)

            %Einlesen: a_skal (Maximale Amplitude)  
            
           app.WP.a_skal = app.MaxAmplitudeEditField_WaveTUB.Value;
           app.FertigLamp.Color='r';
        end

        % Value changed function: w_begEditField
        function w_begEditField_HenningValueChanged(app, event)
           %Einlesen: w_beg
            
           app.WP.w_beg = app.w_begEditField.Value;
           app.FertigLamp.Color='r';
         
           
        end

        % Value changed function: w_endEditField
        function w_endEditField_HenningValueChanged(app, event)
            %Einlesen: w_enb
            
            app.WP.w_end = app.w_endEditField.Value;
            app.FertigLamp.Color='r';
            
        end

        % Value changed function: ZeitpunktrelativEditField
        function PlotZeitpunktrelativEditFieldValueChanged(app, event)
            %Einlesen: t_plotbutton (EditField Zeitpunkt)
            %Wenn geändert wird ändert sich: 
            %   Ortsplot
            %   Zeitpunkt Absolut EditField 
            cla(app.UIAxes,'reset')

            app.t_plotbutton = app.ZeitpunktrelativEditField.Value;
            N_Tanz=round(app.t_plotbutton*(app.WP.N-1)+1);  
            
            updateplotOrt(app,app.WP.xi,app.WP.a,N_Tanz,app.zoom_Ort); 

            if app.plotSteilheitCheckBox.Value
                hold(app.UIAxes,'on')
                yyaxis(app.UIAxes,'right'); 
                plot(app.UIAxes,app.WP.xi,app.WP.Steilheit_max)
                hold(app.UIAxes,'off')
            end
            app.ZeitpunktabsolutEditField.Value=N_Tanz/app.WP.N*app.WP.T;
            
        end

        % Value changed function: ZeitpunktSlider
        function PlotZeitpunktSliderValueChanged(app, event)
            %Einlesen: t_plotslider (Sliderposition Zeit)
            %Wenn geändert wird ändert sich: 
            %     Ortsplot
            %     Zeitpunkt Absolut EditField
            
            cla(app.UIAxes); 

            app.t_plotslider = app.ZeitpunktSlider.Value;
            N_Tanz=round(app.t_plotslider*(app.WP.N-1)+1);  
            
            cla(app.UIAxes,'reset')
            updateplotOrt(app,app.WP.xi,app.WP.a,N_Tanz,app.zoom_Ort);
            
            if app.plotSteilheitCheckBox.Value
                hold(app.UIAxes,'on')
                yyaxis(app.UIAxes,'right'); 
                plot(app.UIAxes,app.WP.xi,app.WP.Steilheit_max)
                hold(app.UIAxes,'off')
            end
            app.ZeitpunktabsolutEditField.Value=N_Tanz/app.WP.N*app.WP.T;
        end

        % Callback function
        function PlotZoomZeitbereichEditFieldValueChanged(app, event)
           %Einlesen: zoom_Zeit
           app.zoom_Zeit = app.ZoomZeitbereichEditField.Value;
            
        end

        % Value changed function: OrtspunktSlider
        function PlotOrtspunktSliderValueChanged(app, event)
           %Einlesen: x_plotslider (Sliderposition Ort)
           %Wenn geändert wird ändert sich: 
           %     Zeitplot
           %     Ortspunkt Absolut EditField
            
          
           cla(app.UIAxes,'reset')
           app.x_plotslider = app.OrtspunktSlider.Value;
            
           N_xanz=round(app.x_plotslider*(app.WP.N_x-1)+1);  
            
           updateplotZeit(app,app.WP.t,app.WP.a,N_xanz,app.zoom_Zeit);
           app.OrtspunktabsolutEditField.Value=N_xanz/app.WP.N_x*app.WP.L; 
            
        end

        % Value changed function: Z1EditField
        function WellenMasch_Z1EditField_1ValueChanged(app, event)
            app.WP.z_1 = app.Z1EditField.Value;
            
        end

        % Value changed function: OrtspunktrelativEditField
        function PlotOrtspunktrelativEditFieldValueChanged(app, event)
            %Einlesen: Zeitpunkt relativ (Edit Field Zeit)
            %Wenn geändert wird ändert sich: 
            %     Zeitplot
            %     Zeitpunkt Absolut EditField
            
            cla(app.UIAxes)
            N_xanz=round(app.OrtspunktrelativEditField.Value*(app.WP.N_x-1));  
            
            updateplotZeit(app,app.WP.t,app.WP.a,N_xanz,app.zoom_Zeit);
             
            app.OrtspunktabsolutEditField.Value=N_xanz/app.WP.N_x*app.WP.L; 
        end

        % Callback function
        function PlotZoomOrtEditFieldValueChanged(app, event)
            %Einlesen: Zoom Ort 
            
            app.zoom_Ort = app.ZoomOrtEditField.Value;
            
        end

        % Value changed function: ZoomOrtEditField_2
        function VideoZoomOrtEditFieldValueChanged(app, event)
            %Einlesen: Zoom Ort Video
            
            app.zoom_Ort = app.ZoomOrtEditField_2.Value;
            
        end

        % Value changed function: AbspielgeschindigkeitEditField
        function VideoAbspielgeschindigkeitEditFieldValueChanged(app, event)
            %Einlesen: kompression, v_abspiel, T_v_beg, T_v_end            
            %Wenn geändert wird ändert sich: 
            %     framerate, Videolänge 
            
            v_abspiel = app.AbspielgeschindigkeitEditField.Value;
            app.kompressionZeit = app.KomprimierungSpinner.Value;
            app.T_v_beg=app.t_v_begEditField.Value*app.WP.T;
            app.T_v_end=app.t_v_endEditField.Value*app.WP.T;
             
            %d_t_v=T/N * komp dh. Zeitschritt wird mit komp größer auch
            %Größer
            d_t_v=app.WP.T*app.kompressionZeit/app.WP.N;
             
            
            %framerate= v* 1/d_t_v wegen f=1/d_t, sakliert mit Video
            app.framerate=v_abspiel*1/d_t_v; 
            
            %Berechnen der Videolänge
            app.T_video=(app.T_v_end-app.T_v_beg)/v_abspiel; 
            
            %ausgabe framerate und Videolänge 
            app.FramerateberechnetEditField.Value=app.framerate; 
            app.VideolaengeberechnetEditField.Value=app.T_video;
        end

        % Value changed function: KomprimierungSpinner
        function VideoKomprimierungSpinnerValueChanged(app, event)
            %Einlesen: kompression, v_abspiel, T_v_beg, T_v_end            
            %Wenn geändert wird ändert sich: 
            %     framerate, Videolänge 
           
            
            app.kompressionZeit = app.KomprimierungSpinner.Value;
            v_abspiel = app.AbspielgeschindigkeitEditField.Value; 
            app.T_v_beg=app.t_v_begEditField.Value*app.WP.T;
            app.T_v_end=app.t_v_endEditField.Value*app.WP.T;

            %d_t_v=T/N * komp dh. Zeitschritt wird mit komp größer auch
            %Größer
            d_t_v=app.WP.T/app.WP.N*app.kompressionZeit;
            
            %framerate= v* 1/d_t_v wegen f=1/d_t, sakliert mit Video
            app.framerate=v_abspiel*1/d_t_v; 
            
            %Berechnen der Videolänge
            app.T_video=(app.T_v_end-app.T_v_beg)/v_abspiel; 
            

            %1234 Test
            %ausgabe framerate und Videolänge 
            app.FramerateberechnetEditField.Value=app.framerate; 
           
        end

        % Button pushed function: VideoErstellenButton
        function VideoErstellenButtonPushed(app, event)
            %Erstellung eines Viedos     
            
                app.FertigLamp_2.Color='r';
            
                %laden von T_v_beg, T_v_end, kompression, v_abspiel 
                app.T_v_beg=app.t_v_begEditField.Value*app.WP.T;
                app.T_v_end=app.t_v_endEditField.Value*app.WP.T;
                app.kompressionZeit=round(app.KomprimierungSpinner.Value);
                v_abspiel=app.AbspielgeschindigkeitEditField.Value;
                
                %d_t_v=T/N * komp dh. Zeitschritt wird mit komp größer auch
                %Größer
                d_t_v=app.kompressionZeit*app.WP.T/app.WP.N;
                
                %framerate= v* 1/d_t_v wegen f=1/d_t, sakliert mit Video
                app.framerate=v_abspiel*1/d_t_v; 
      
                %bestimmen der Indizes für T_v_beg und T_v_end aus N
                n_v_beg=round(app.T_v_beg/app.WP.T*app.WP.N)+1;
                n_v_end=round(app.T_v_end/app.WP.T*app.WP.N);
                
                
                %Eigenschaften (name, qualität, framerate) von Vidoe
                %bestimmen
                video=VideoWriter('video_linear');
                video.Quality = 95;
                video.FrameRate=app.framerate;
                
                %Vidoe starten
                open(video) 
                
                
                fig = figure('WindowState','maximized');  
                pause(1);
                fig.Position
 
                for n_v=n_v_beg:app.kompressionZeit:n_v_end                
               
                      
                    axisVideo=axis(gca);
                    
                    x = app.WP.xi; 
                    y = app.WP.a(:,n_v);
                    axis(axisVideo, 'tight');
                    plot(x,y); 
                    ylim([-app.WP.a_maxmax/app.zoom_Zeit app.WP.a_maxmax/app.zoom_Zeit]);
                    title('Ortsverlauf')
                    xlabel('Ort in [m]') 
                    ylabel('Auslenkung in [cm]') 
    
                    
                    frame=getframe(fig);
                 
                    
                    writeVideo(video,frame);
                    
                    
                    %abbruch durch Stop-Haken
                    if app.STOPCheckBox.Value==true
                        close(video)
                        app.FertigLamp_2.Color='g';
                        close(fig)
                        break;    
                    end
                
               end
                    close(video)
                    
                    app.FertigLamp_2.Color='g';
                    
                    close(fig)

        end

        % Value changed function: AmplitudenmodusDropDown
        function AmplitudenmodusDropDownValueChanged(app, event)
            %Einlesen: Amplitudenmodus
            
            app.WP.Amplitudenmodus = app.AmplitudenmodusDropDown.Value;
            
            app.FertigLamp.Color='r';
        end

        % Value changed function: WellenlngeSollwertEditField
        function RegWellenWellenlngeSollwertEditFieldValueChanged(app, event)
            %Einlesen: Wellenlänge       
            %Wenn geändert wird ändert sich: 
            %     Wellenperiode  
            
            app.WP.lamda_reg = app.WellenlngeSollwertEditField.Value;
            
            %k=2pi/lamda 
            app.WP.k_reg=2*pi()/app.WP.lamda_reg; 
            
            %lösung Dispersionsgleichung von k nach w 
            app.WP.w_reg=sqrt(app.WP.k_reg*app.WP.g);
            
            for l=1:4000
                app.WP.w_reg=sqrt(app.WP.k_reg*app.WP.g*tanh(app.WP.k_reg*app.WP.g));
            end
            
            %T=2pi/w
            app.WellenperiodeSollwertEditField.Value=2*pi()/app.WP.w_reg;
            
            %Ausgabe Wellenperiode 
            app.WP.T_reg=app.WellenperiodeSollwertEditField.Value;
            
            app.FertigLamp.Color='r';
            
        end

        % Value changed function: WellenperiodeSollwertEditField
        function RegWellenWellenperiodeSollwertEditFieldValueChanged(app, event)

            %Einlesen: Wellenperiode      
            %Wenn geändert wird ändert sich: 
            %     Wellenlänge   
            
            %T_reg ist Wellenperiode 
            app.WP.T_reg = app.WellenperiodeSollwertEditField.Value;

            %w=2pi/T
            app.WP.w_reg=2*pi()/app.WP.T_reg; 
            
            %Lösung der Dispersionsgleichung von w nach k 
            app.WP.k_reg=app.WP.w_reg^2/app.WP.g;
        
            k_alt=0; 
            i=0; 


            while (max(abs(k_alt-app.WP.k_reg))>0.00001 && i<10000)
                k_alt=app.WP.k_reg; 
                app.WP.k_reg=app.WP.w_reg.^2./(tanh(app.WP.k_reg.*app.WP.d)*app.WP.g); 
                i=i+1;
            end


            %lamda=2pi/k
            app.WP.lamda_reg= 2*pi()/app.WP.k_reg;
            
            %Auasgabe Wellenlänge 
            app.WellenlngeSollwertEditField.Value=app.WP.lamda_reg; 
            
            app.FertigLamp.Color='r';
            
            
        end

        % Button pushed function: BerechnenButton
        function BerechnenButtonPushed(app, event)
        %Einlesen:        
            %Wenn geändert wird ändert sich: 
            %     Wellenperiode  
        
           
        app.FertigLamp.Color='red'; 
        
        Grundvariablen(app)
        
        %Auswahl der Amplitudenmodi
        switch app.WP.Amplitudenmodus
            case 'Henning'
                    %Henningschen verlauf erzeugen 
                    app.WP.A_real=zeros(1,app.WP.N); 
                    s=4/27*(app.WP.w_beg-app.WP.w_end)^3*1.05;
                    app.WP.A_real=-(app.WP.w-app.WP.w_beg).*(app.WP.w-app.WP.w_end).^2*(1/s);
                    
                    %indize der höchsten Frequenz finden  
                    N_wend=round(app.WP.w_end/app.WP.d_w);
                    
                    %verlauf beschneiden
                    app.WP.A_real(app.WP.A_real<0)=0; 
                    app.WP.A_real(N_wend:end)=0;
                    
            case 'regelmäßige Wellen' 
                    app.WP.A_real=zeros(1,app.WP.N); 
                    %w_reg runden, so dass es Vielfaches von d_w wird
                    app.WP.w_reg=round(app.WP.w_reg/app.WP.d_w)*app.WP.d_w; 
                    
                    %Indize von w_reg finden
                    N_reg=app.WP.w_reg/app.WP.d_w;
                    app.WP.A_real(N_reg)=1; 
                    N_wend=N_reg; 
                    
                    app.WellenlngeIstwertEditField.Value=2*pi()/app.WP.k_reg;
                    app.WP.lamda_reg_ist=app.WellenlngeIstwertEditField.Value;
                    app.WellenperiodeIstwertEditField.Value=2*pi()/app.WP.w_reg;
                    app.WP.T_reg_ist=app.WellenperiodeIstwertEditField.Value; 
                    
                    app.WP.t_shift=0; 
                    
            case 'konstante Wellensteilheit'
                   app.WP.A_real=zeros(1,app.WP.N); 
                   app.WP.A_real=app.WP.steilheit_max.*app.WP.g./app.WP.w.^2; 
                
                   %verlauf beschneiden
                   N_wbeg=round(app.WP.w_beg/app.WP.d_w);
                   app.WP.A_real(1:N_wbeg)=0;
                   
                   N_wend=round(app.WP.w_end/app.WP.d_w);
                   app.WP.A_real(N_wend:end)=0;
                   
                   if app.HllkurveanwendenCheckBox.Value==1
                        konstWellensteilhheit_Huellkurve(app) 
                        app.WP.A_real=app.WP.A_real.*app.WP.Huellkurve'; 
                   end
            case 'Amplitudenverlauf geladen'
                 %  app.WP.A_real=app.WP.A_real_geladen; 
                   N_wend=round(app.WP.w_end/app.WP.d_w);
        end
     
        %Amplitudenfunktion macht realteil der Fouriertransformierten aus
        A_start=complex(app.WP.A_real,zeros(1,app.WP.N));

        X=0;
            
        %Berechnung von k Feld in Grundvariablen Funktion 
        
        %Ortsschrittweite, berechnet sich aus maximaler Frequenz 
        app.WP.d_x=2*pi()/(2*app.WP.k(N_wend))/app.WP.aufl_Ort;
        
        %Anzahl der Ortsschritte
        app.WP.N_x=round(app.WP.L/app.WP.d_x); 
        
        %Neuanpassung der Ortsschrittweite aus gerundeter Anzahl N_x
        app.WP.d_x=app.WP.L/(app.WP.N_x-1);
        app.WP.xi=linspace(0,app.WP.L,app.WP.N_x);
        
        %Schleife über alle Orte mit Abstand d_x. An jedem Ort wird der
        %neue Zeitverlauf berechnet
        
        app.WP.a=[];
        
        v_m=linspace(0,app.WP.N-1,app.WP.N); 
        
        app.FortschrittEditField.Value=0; 
        
        drawnow()
        
        for b=1:app.WP.N_x

            app.WP.A=A_start;    
           
            app.WP.A=app.WP.A.*exp(-v_m.*app.WP.d_w*1i*app.WP.T*app.WP.t_shift-1i*app.WP.k.*(X-app.WP.L*app.WP.t_shift));

            X=app.WP.d_x+X; 
            
            app.FortschrittEditField.Value=round(b/app.WP.N_x,2)*100; 
            
            drawnow()
            
            %IFFT Wobei Zeilen:     Zeitverlauf an festem Ort 
            %           Spalten:    Ortsverlauf an fester Zeit 
            %==> a(Nx,N)
            
            
            app.WP.a(b,:)=app.WP.N*real(ifft(app.WP.A)); 
   
            if app.StopCheckBox.Value==true
                app.FertigLamp.Color='r';
                return;    
                
            end
        end
        
        app.WP.A=A_start; 
        %skalierung aus maximaler Amplitude, x_b*L/d_x ist indize von Absolut angegebenen Ort der Max amplitude 
        
        %Indize von a finden, auf den sich die Skallierung bezieht und dort
        %das Maximum finden
        
        b_skal=round(app.WP.x_skal*app.WP.L/app.WP.d_x);
        
        a_max_ist=max(app.WP.a(b_skal,:));

        app.WP.a(:,:)=app.WP.a.*app.WP.a_skal/a_max_ist;

        app.WP.A(:,:)=app.WP.A*app.WP.a_skal/a_max_ist;

        app.FertigLamp.Color='g';
      
        %absolutes maximum von Wellenzug finden
        app.WP.a_maxmax=max(app.WP.a(:));
        
        %Wellensteiheit berechnen 
        v=ones(app.WP.N_x,1);
        
        DX=spdiags([-v,v],[-1,1],app.WP.N_x,app.WP.N_x);
        
        Steilheit=DX*app.WP.a./app.WP.d_x; 
        
        app.WP.Steilheit_max=max(Steilheit.');
        app.WP.Steilheit_max(1)=0;
        app.WP.Steilheit_max(end)=0; 
        app.WP.Steilheit_max=abs(app.WP.Steilheit_max); 
        %Pegel Psotionen Default Value
        app.PositionenEditField.Value=num2str(round(app.WP.L*[0.1,0.2,0.3,0.4,0.5,0.6,0.7,0.8,0.9],2)); 
        
        app.TabGroup9SelectionChanged; 
        
%GEÄNDERT!! A-real=0 auskommentiert

       % app.WP.A_real=0; 
        end

        % Button pushed function: speichernButton
        function speichernButtonPushed(app, event)


        switch app.WP.Amplitudenmodus
            case 'Henning'
                app.dirname=[app.WP.Amplitudenmodus,'_mit_g=',num2str(app.WP.g),'_L=',num2str(app.WP.L),'_d=',num2str(app.WP.d),'_f=',num2str(app.WP.f),'_t_shift=',num2str(app.WP.t_shift),'_maxAmp=',num2str(app.WP.a_skal),'(bei',num2str(app.WP.x_skal),')_w_beg=',num2str(app.WP.w_beg),'_w_end=',num2str(app.WP.w_end)]; 
            case 'regelmäßige Wellen'
                app.dirname=[app.WP.Amplitudenmodus,'_mit_g=',num2str(app.WP.g),'_L=',num2str(app.WP.L),'_d=',num2str(app.WP.d),'_f=',num2str(app.WP.f),'_maxAmp=',num2str(app.WP.a_skal),'_lamda=',num2str(app.WP.lamda_reg)]; 
            case 'konstante Wellensteilheit'
                app.dirname=[app.WP.Amplitudenmodus,'_mit_g=',num2str(app.WP.g),'_L=',num2str(app.WP.L),'_d=',num2str(app.WP.d),'_f=',num2str(app.WP.f),'_t_shift=',num2str(app.WP.t_shift),'_maxAmp=',num2str(app.WP.a_skal),'(bei',num2str(app.WP.x_skal),')_w_beg=',num2str(app.WP.w_beg),'_w_end=',num2str(app.WP.w_end),'a_links=',num2str(app.WP.a_links),'a_rechts=',num2str(app.WP.a_rechts)]; 
            case 'Ampliudenverlauf geladen'
                app.dirname=[app.WP.Amplitudenmodus,'_mit_g=',num2str(app.WP.g),'_L=',num2str(app.WP.L),'_d=',num2str(app.WP.d),'_f=',num2str(app.WP.f),'_t_shift=',num2str(app.WP.t_shift),'_maxAmp=',num2str(app.WP.a_skal),'(bei',num2str(app.WP.x_skal),')_w_beg=',num2str(app.WP.w_beg),'_w_end=',num2str(app.WP.w_end)]; 

            otherwise 
                app.dirname=['WellenpaketGenerator'];
        end 
     
        if ~exist(['.\Speich\',app.dirname], 'dir') && app.neuenAusgabeOrdnererstellenCheckBox.Value
        
            mkdir(['.\Speich\',app.dirname])
               
            pathfile=['.\Speich\',app.dirname,'\',app.dirname,'.mat'];
            %Das UI liefert den Namen und den Pfad der Datei
            [savefile,pathsave]=uiputfile(pathfile);
            drawnow;
        
        else

            [savefile,pathsave]=uiputfile(['.\Speich',app.dirname,'.mat']);
            drawnow;
 
        end 

        app.dirname=pathsave;

        %Hauptanwendnung in den Vordergrund
        figure(app.UIFigure)
        app.UIFigure.Visible = 'on';
        
        %Dateiname und Pfad zusammenführen
        matname = fullfile(pathsave, savefile);
        
        %speichern der Datei wenn Benutzerauswahl über UI
        %erfolgreich war
        if savefile~=00 
            WP_save=app.WP;
            save(matname,'-struct','WP_save')
        end
           
        end

        % Button pushed function: ladenButton
        function ladenButtonPushed(app, event)
           
            %Laden der Variablen aus Savefile 
            
            %Das UI liefert den Namen und den Pfad der Datei
            [variables,pathload] = uigetfile('*.mat');
            
            %Hauptanwendnung in den Vordergrund
            drawnow;
            figure(app.UIFigure)

            app.FertigLamp.Color='r';
            %speichern der Datei wenn Benutzerauswahl über UI
            %erfolgreich war
            if variables~=0
                %Dateiname und Pfad zusammenführen
                matname = fullfile(pathload, variables);
                
                %laden 
                app.WP=load(matname);
                
                updateVariablenAnzeige(app)    

            end

            if ~exist('app.WP.T_versatz','var')
               app.WP.T_versatz=0; 
            end 


            app.FertigLamp.Color='g'; 
            app.FortschrittEditField.Value=100; 
        end

        % Button pushed function: PlotHenningButton
        function PlotHenningButtonPushed(app, event)
            
            Grundvariablen(app); 
        
            %Funktionsbeschreibung
            s=4/27*(app.WP.w_beg-app.WP.w_end)^3*1.05;
            app.WP.A_real=-(app.WP.w-app.WP.w_beg).*(app.WP.w-app.WP.w_end).^2*(1/s);
            
            %beschneidung 
            N_wend=round(app.WP.w_end/app.WP.d_w);
            app.WP.A_real(app.WP.A_real<0)=0; 
            app.WP.A_real(N_wend:end)=0;
            
            cla(app.UIAxes2) 
         
            %plot
            hold(app.UIAxes2,'on')
            scatter(app.UIAxes2,app.WP.w(1:N_wend),app.WP.A_real(1:N_wend))
            plot(app.UIAxes2,app.WP.w(1:N_wend),app.WP.A_real(1:N_wend))
            
            
            title(app.UIAxes2,'Amplitudenverlauf')
            xlabel(app.UIAxes2,'Kreisfrequenz in [1/s]') 
            ylabel(app.UIAxes2,'Amplitude in [1]') 
            
            
            hold(app.UIAxes2,'off')
        end

        % Button pushed function: AnwendenPlotButton
        function AnwendenPlotButtonPushed(app, event)
        
        
        %WB:     Spalte 1: translatorisch
        %        Spalte 2: Winkel beta
        %        Spalte 3: 0 
        app.WP.Klappenmodus=app.KlappenmodusDropDown.Value; 

        cla(app.UIAxes)
        app.WP.wb=zeros(app.WP.N,3);
        app.WP.WB=zeros(app.WP.N,3);
        WB_1=zeros(app.WP.N,1);
        WB_2=zeros(app.WP.N,1);
        WB_3=zeros(app.WP.N,1);
        
        %Benennung nach Bsc. Joachim Steinberg
        h=app.WP.d;
        d=-app.WP.long; 

        A_klappe=app.WP.A; 
        v_m=linspace(0,app.WP.N-1,app.WP.N); 

        A_klappe=A_klappe.*exp(-v_m.*app.WP.d_w*1i*app.WP.T*app.WP.t_shift-1i*app.WP.k.*(-app.WP.L*app.WP.t_shift));
              
        %Fourierbereich multiplikation Wasserspiegel, Übertragungsfunktion
        %und amplitudenskallierung 
        
        switch app.WP.Klappenmodus
            case 'Piston'
    	        WB_1=(2.*(cosh(2.*app.WP.k.*h)-1)./(sinh(2.*h.*app.WP.k)+2.*h.*app.WP.k));        
                app.WP.WB(:,1)=A_klappe./WB_1;
                app.WP.WB(:,1)=app.WP.WB(:,1).*exp(-(1i*pi()/2));
                      
            case 'singleFlap'
                
                WB_2=4.*(sinh(app.WP.k.*h)./app.WP.k.*d).*(cosh(app.WP.k*(h-d))+app.WP.k.*d.*sinh(app.WP.k.*h)-cosh(app.WP.k.*h))./(2.*app.WP.k.*h+sinh(2.*app.WP.k.*h));                
                %abs_WB_2=atan(abs(A_klappe)./(WB_2.*-app.WP.long'))*360/(2*pi());
                %deg_WB_2=angle(A_klappe);
                %app.WP.WB(:,2)=complex(abs_WB_2.*cos(deg_WB_2),abs_WB_2.*sind(deg_WB_2));
                
                %app.WP.WB(:,2)=atan((A_klappe)./(WB_2.*-app.WP.long'))*360/(2*pi());
                app.WP.WB(:,2)=atan((A_klappe)./(WB_2.*-app.WP.long'))*pi()^2;
               
        end


        app.WP.WB(isnan(app.WP.WB))=0;
       
        app.WP.WB=app.WP.WB.*app.a_max_sollEditField.Value/app.a_max_istEditField.Value; 

        %Alle Pegel usw skallieren
        app.WP.A=app.WP.A.*app.a_max_sollEditField.Value/app.a_max_istEditField.Value; 
        app.WP.a=app.WP.a.*app.a_max_sollEditField.Value/app.a_max_istEditField.Value; 
        app.WP.a_skal=app.WP.a_skal.*app.a_max_sollEditField.Value/app.a_max_istEditField.Value;
        app.WP.a_maxmax=app.WP.a_maxmax.*app.a_max_sollEditField.Value/app.a_max_istEditField.Value; 
        app.WP.steilheit_max=app.WP.steilheit_max.*app.a_max_sollEditField.Value/app.a_max_istEditField.Value; 

        app.WP.wb=real(app.WP.N*ifft(app.WP.WB));

       
        %Nullen einfügen für vorlauf: 
        app.WP.T_versatz=app.T_versatzEditField.Value; 
        N_versatz=round(app.WP.T_versatz/app.WP.d_t);
        app.WP.N_versatz=N_versatz;
        app.WP.N_erweitert=N_versatz+app.WP.N; 
        
        %WP um nullen erweitern 
        app.WP.wb_versatz=zeros(app.WP.N_erweitert,3);       
        app.WP.wb_erweitert=zeros(app.WP.N+app.WP.N_erweitert,3);
        app.WP.wb_erweitert=[app.WP.wb(app.WP.N-N_versatz+1:app.WP.N,:);app.WP.wb(1:app.WP.N-N_versatz,:);zeros(N_versatz,3)];

        
        %t um nullen erweitern
        app.WP.t_erweitert=linspace(-app.WP.T_versatz,app.WP.T-app.WP.d_t,app.WP.N_erweitert);
        app.WP.T_erweitert=app.WP.T+app.WP.T_versatz; 


        app.ENDTIMEEditField_anzeige.Value=app.WP.T_erweitert;

        %Plot
               
        x = app.WP.t_erweitert;
        y_1 = app.WP.wb_erweitert(:,1);
        y_2 = app.WP.wb_erweitert(:,2);
        y_3 = app.WP.wb_erweitert(:,3);
        axis(app.UIAxes, 'tight');
        
        yyaxis(app.UIAxes,'left')

        plot(app.UIAxes,x,y_1); 
    	ylabel(app.UIAxes,'Auslenkung in [m]')  
        yyaxis(app.UIAxes,'right')
     

        plot(app.UIAxes,x,y_2,x,y_3); 
        ylabel(app.UIAxes,'Klappenwinkel in [°]') 
        
        title(app.UIAxes,'Klappenbewegung Piston')
        xlabel(app.UIAxes,'Zeit in [s]') 


        end

        % Value changed function: w_begEditField_2
        function w_begEditField_SteilheitValueChanged(app, event)
           app.WP.w_beg = app.w_begEditField_3.Value;
           app.FertigLamp.Color='r';
            
        end

        % Value changed function: w_endEditField_2
        function w_endEditField_SteilheitValueChanged(app, event)
            %Einlesen: w_enb
            
            app.WP.w_end = app.w_endEditField_2.Value;
            app.FertigLamp.Color='r';
            
        end

        % Button pushed function: PlotkonstanteWellensteilheitButton
        function PlotkonstanteWellensteilheitButtonPushed(app, event)
            
            Grundvariablen(app)
        
            %Funktionsbeschreibung
            app.WP.A_real=app.WP.steilheit_max.*app.WP.g./app.WP.w.^2; 

            %beschneidung 
            N_wbeg=round(app.WP.w_beg/app.WP.d_w);
            N_wend=round(app.WP.w_end/app.WP.d_w);
            
            app.WP.A_real(N_wend:end)=0;
            app.WP.A_real(1:N_wbeg)=0; 
            
            cla(app.UIAxes3) 
         
            %plot
            hold(app.UIAxes3,'on')
            %scatter(app.UIAxes3,app.w(1:N_wend),app.A_real(1:N_wend))
            plot(app.UIAxes3,app.WP.w(1:N_wend),app.WP.A_real(1:N_wend))
            
            
            title(app.UIAxes3,'Amplitudenverlauf')
            xlabel(app.UIAxes3,'Kreisfrequenz in [1/s]') 
            ylabel(app.UIAxes3,'Amplitude in [1]') 
            
            
            if app.HllkurveanwendenCheckBox.Value==1

               konstWellensteilhheit_Huellkurve(app)

               hold(app.UIAxes3,'on')
               plot(app.UIAxes3,app.WP.w(1:N_wend),app.WP.Huellkurve(1:N_wend)')
               
               %Area=sum(app.WP.Huellkurve)*app.WP.d_w; 
 
               A_real_Huell=app.WP.A_real.*app.WP.Huellkurve';
               plot(app.UIAxes3,app.WP.w(1:N_wend),A_real_Huell(1:N_wend)')
              
            end
            
            hold(app.UIAxes3,'on')
          
            
        end

        % Callback function
        function WellensteilheitmaxEditFieldValueChanged(app, event)
            app.WP.steilheit_max = app.WellensteilheitmaxEditField.Value;
            
        end

        % Callback function
        function PlotAmlitudenverlaufButtonPushed(app, event)
            konstWellensteilhheit_Huellkurve(app)
            
        end

        % Button pushed function: ExportprowbxButton
        function ExportprowbxButtonPushed(app, event)
            AnwendenPlotButtonPushed(app, event)
            

            wb_save=[app.WP.N;real(app.WP.wb_erweitert)];
            row2=[app.WP.d_t;zeros(app.WP.N,1)];
            row3=[0;zeros(app.WP.N,1)];
            
            
            Table=table(wb_save,row2,row3);


            %Das UI liefert den Namen und den Pfad der Datei
            [savefile,pathsave]=uiputfile([app.dirname,'prowbx.txt']);
            drawnow;
                    
            %Hauptanwendnung in den Vordergrund
            figure(app.UIFigure)
            app.UIFigure.Visible = 'on';
                    
            %Dateiname und Pfad zusammenführen
            matname = fullfile(pathsave, savefile);

            %speichern der Datei wenn Benutzerauswahl über UI
            %erfolgreich war
            if savefile~=00 
                writetable(Table,matname,'Delimiter',' ','WriteVariableNames',0);
                app.FertigLamp.Color='green';
            end


        end

        % Selection change function: TabGroup9
        function TabGroup9SelectionChanged(app, event)
            app.LAENGEEditField_anzeige.Value=app.WP.L; 
            app.TIEFEEditField_anzeige.Value=app.WP.d;
            app.longEditField_anzeige.Value=app.WP.long;
            %app.ENDTIMEEditField_anzeige.Value=app.WP.T; 
            app.DTEditField_anzeige.Value=app.WP.d_t;
            app.mitLEditField.Value=app.WP.L; 
            app.NPEGELEditField.Value=length(str2num(app.PositionenEditField.Value));
            app.NTRACKEditField.Value=length(str2num(app.PostionenXEditField.Value));
            app.Z1EditField_anzeige.Value=app.WP.z_1;
            app.Z1EditField.Value=app.WP.z_1;
            app.NXEditField.Value=round(app.WP.L*32);


        end

        % Value changed function: LAENGEEditField
        function LAENGEEditFieldValueChanged(app, event)
            app.WP.L = app.LAENGEEditField.Value;

      
            k_wend=app.WP.w_end^2/app.WP.g;
  
            k_alt=0; 
            i=0; 

            while (max(abs(k_alt-app.WP.k))>0.00001 && i<10000)
                k_alt=k_wend; 
                k_wend=app.WP.w_end.^2./(tanh(k_wend.*app.WP.d)*app.WP.g); 
                i=i+1;
            end
            

            app.WP.T=round(app.WP.L/(1/2*app.WP.w_end/k_wend*(1+2*k_wend*app.WP.d/sinh(2*k_wend*app.WP.d))));
            app.ENDTIMEEditField.Value=app.WP.T;
            app.NEditField.Value=round(app.WP.T/app.WP.d_t);
            
            
        end

        % Value changed function: TIEFEEditField
        function TIEFEEditFieldValueChanged(app, event)
            app.WP.d = app.TIEFEEditField.Value;
            app.WP.z_1=-app.WP.d; 
            app.Z1EditField.Value=-app.WP.d; 
            
        end

        % Value changed function: DTEditField
        function DTEditFieldValueChanged(app, event)
            app.WP.d_t = app.DTEditField.Value;
            app.WP.f=1/app.WP.d_t; 
            
            k_wend=app.WP.w_end^2/app.WP.g;
  
            k_alt=0; 
            i=0; 


            while (max(abs(k_alt-app.WP.k))>0.00001 && i<10000)
                k_alt=k_wend; 
                k_wend=app.WP.w_end.^2./(tanh(k_wend.*app.WP.d)*app.WP.g); 
                i=i+1;
            end

            app.WP.T=round(app.WP.L/(1/2*app.WP.w_end/k_wend*(1+2*k_wend*app.WP.d/sinh(2*k_wend*app.WP.d))));
            app.NEditField.Value=round(app.WP.T/app.WP.d_t);
            
            
        end

        % Callback function
        function ENDTIMEEditFieldValueChanged(app, event)
            app.WP.T = app.ENDTIMEEditField.Value;
            app.WP.N =round(app.WP.T/app.WP.d_t);
            
        end

        % Value changed function: ParameterverwendenWaveTUBCheckBox
        function ParameterverwendenWaveTUBCheckBoxValueChanged(app, event)
          if app.ParameterverwendenWaveTUBCheckBox.Value==true
              app.ParameterverwendenKanalCheckBox.Value=false;
          else 
              app.ParameterverwendenKanalCheckBox.Value=true;
          end
            
        end

        % Value changed function: ParameterverwendenKanalCheckBox
        function ParameterverwendenKanalCheckBoxValueChanged(app, event)
          if app.ParameterverwendenKanalCheckBox.Value==true
              app.ParameterverwendenWaveTUBCheckBox.Value=false;
          else 
              app.ParameterverwendenWaveTUBCheckBox.Value=true;
          end
        end

        % Button pushed function: ExportProDateiButton
        function ExportProDateiButtonPushed(app, event)
            
            %Das UI liefert den Namen und den Pfad der Datei
            [savefile,pathsave]=uiputfile([app.dirname,'propar.txt']);
            drawnow;
            
            %Hauptanwendnung in den Vordergrund
            figure(app.UIFigure)
            app.UIFigure.Visible = 'on';
                    
            %Dateiname und Pfad zusammenführen
            matname = fullfile(pathsave, savefile);

            %speichern der Datei wenn Benutzerauswahl über UI
            %erfolgreich war
            if savefile~=00 
                file=fopen(matname,'w');
                fprintf(file,[num2str(app.WP.L),' ',num2str(app.WP.d),' \n',num2str(app.WP.z_1),' ',num2str(app.longEditField.Value),' \n',num2str(app.NXEditField.Value),' ',num2str(app.NZEditField.Value),' \n',num2str(app.alphaEditField.Value),' \n',num2str(app.WP.d_t),' ',num2str(app.WP.T),' \n',num2str(app.NFOTOEditField.Value),' \n',num2str(app.NPEGELEditField.Value),' \n',app.PositionenEditField.Value,' \n',num2str(app.NTRACKEditField.Value),'\n',num2str(app.NTRACKEditField.Value),'\n',app.PostionenXEditField.Value,'\n',app.PositionenYEditField.Value]);
                fclose(file);

                app.FertigLamp.Color='green';
            end
        end

        % Value changed function: PositionenEditField
        function PositionenEditFieldValueChanged(app, event)
           
            app.NPEGELEditField.Value=length(str2num(app.PositionenEditField.Value));
            
        end

        % Button pushed function: prowbxproparVariablenAusgabeWaveTUBButton
        function prowbxproparVariablenAusgabeWaveTUBButtonPushed(app, event)
            app.dirname=string();


            speichernButtonPushed(app, event)

            if ~strcmp(app.dirname,string())
  
            AnwendenPlotButtonPushed(app, event)

            %PROWBX
            wb_save=[app.WP.N_erweitert;real(app.WP.wb_erweitert(:,1))];
            row2=[app.WP.d_t;real(app.WP.wb_erweitert(:,2))];
            row3=[nan;real(app.WP.wb_erweitert(:,3))];

            
            WBX=[wb_save,row2,row3];
            

            WBX=num2cell(WBX);

            WBX(cellfun(@(WBX) any(isnan(WBX)),WBX)) = {''};

            %Das UI liefert den Namen und den Pfad der Datei
            %[savefile,pathsave]=uiputfile([app.dirname,'prowbx.txt']);
            
            drawnow;
                    
            %Hauptanwendnung in den Vordergrund
            figure(app.UIFigure)
            app.UIFigure.Visible = 'on';
                    
            %Dateiname und Pfad zusammenführen
            %matname = fullfile(pathsave, savefile);
            matname=[app.dirname,'\prowbx.txt'];

            %speichern der Datei wenn Benutzerauswahl über UI
            %erfolgreich war
           
            writecell(WBX,matname,'delimiter','space');
            

            %data= [Table.Properties.VariableNames; table2cell(Table)];

            app.FertigLamp.Color='green';
            %end
            movefile([app.dirname,'prowbx.txt'],[app.dirname,'pro.wbx'],'f');


            %PROPAR
            %Das UI liefert den Namen und den Pfad der Datei
            %[savefile,pathsave]=uiputfile([app.dirname,'propar.txt']);
            
            drawnow;
            
            %Hauptanwendnung in den Vordergrund
            figure(app.UIFigure)
            app.UIFigure.Visible = 'on';
                    
            %Dateiname und Pfad zusammenführen
            %matname = fullfile(pathsave, savefile);
            matname=[app.dirname,'\propar.txt'];
            %speichern der Datei wenn Benutzerauswahl über UI
            %erfolgreich war
            %if savefile~=00 
                file=fopen(matname,'w');
                fprintf(file,[num2str(app.WP.L),' ',num2str(app.WP.d),' \n',num2str(app.WP.z_1),' ',num2str(app.longEditField.Value),' \n',num2str(app.NXEditField.Value),' ',num2str(app.NZEditField.Value),' \n',num2str(app.alphaEditField.Value),' \n',num2str(app.WP.d_t),' ',num2str(app.WP.T+app.WP.T_versatz),' \n',num2str(app.NFOTOEditField.Value),' \n',num2str(app.NPEGELEditField.Value),' \n',app.PositionenEditField.Value,' \n',num2str(app.NTRACKEditField.Value),'\n',num2str(app.NTRACKEditField.Value),'\n',app.PostionenXEditField.Value,'\n',app.PositionenYEditField.Value]);
                fclose(file);

                app.FertigLamp.Color='green';
            %end
            movefile([app.dirname,'propar.txt'],[app.dirname,'pro.par']);
            
            Checkbox=app.AusgabedatenladenCheckBox.Value; 
            if app.WaveTUBausfhrenCheckBox.Value 
                copyfile([app.dirname,'pro.par'], pwd,'f'); 
                copyfile([app.dirname,'pro.wbx'], pwd,'f'); 
                run("PRO.m") 
            end 

            app.Gesamtenversuchladendirekt=false;

            if Checkbox
                app.Gesamtenversuchladendirekt=true; 
                VersuchLadenButtonPushed(app,event)
            end 
            end
        end

        % Button pushed function: VersuchLadenButton
        function VersuchLadenButtonPushed(app, event)
            %Laden der Variablen aus Savefile 
            
            %Das UI liefert den Namen und den Pfad der Datei
   
        
            app.Lamp.Color='r';
            
            cla(app.UIAxesWaveTUBKlein01,'reset')
            cla(app.UIAxesWaveTUBKlein02,'reset')
        
            cla(app.UIAxesWaveTUBSurface,'reset')
            cla(app.UIAxesWaveTUBKlein03,'reset')
          

            if app.Gesamtenversuchladendirekt
                dirpath=app.dirname; 
                app.Gesamtenversuchladendirekt=false;
            else
                dirpath = uigetdir('.\AusgabeDaten');
            end
            
           
            if  ~exist([dirpath,'\data.mat'], 'file')

            figure(app.UIFigure)
            app.WaveTUBData.dirpath=dirpath; 
            %Namen der Dateien
            files_all=cellstr(ls(dirpath));
          
            %konvertieren der Dateinamen in Table zum rausfiltern der
            %benötigten Dateien
            filesTable=cell2table(files_all);
            filesTable=filesTable(contains(filesTable.(1),["daten" "pegel" "surface" "track" "time" ]),:);
            files=table2cell(filesTable);
            files=sort(files);
           
            %Filepaths für zu ladende Files erzeugen 
            filepaths = fullfile(dirpath,files);
            
            DataRaw=cell([max(size(filepaths)),1]);

            if min(size(filepaths))>0

            %Daten auslesen in cell array mit den jeweiligen Tables 
            for i=1:max(size(filepaths))
 
                DataRaw{i,1}=readtable(char(filepaths(i)),'Headerlines',0); 
            end 
          
            %speichern der Namen und jeweiligen Daten in Strukt array
            app.WaveTUBData.filenames=files; 
            app.WaveTUBData.Data=DataRaw;


            %Hauptanwendnung in den Vordergrund
            drawnow;
            %figure(app.UIFigure)
            

            %Tabelle Laden
       
            %contains liefert 1, wenn daten in der jeweiligen Cell
            %enthalten. Wird in Vektor Index_Daten abgespeichert und Daten
         

            %Pegelstände Dropdown Laden
            Index_pegel=contains(app.WaveTUBData.filenames, 'pegel');
            Index_track=contains(app.WaveTUBData.filenames, 'track');

            app.PegelDropDown.Items=app.WaveTUBData.filenames(Index_pegel,1);
            app.PegelauswhlenDropDown.Items=app.WaveTUBData.filenames(Index_pegel,1);
            
            %Track laden 

            %Index_track=contains(app.WaveTUBData.files, 'daten');
            %app.VersuchsdatenTab.Data=app.WaveTUBData.Data{Index_track,:};
  
            app.TrackauswhlenDropDown.Items=app.WaveTUBData.filenames(Index_track,1);
           

            end


            %TABLEDATEN TEIL1
            %versuchsdaten Wellenpaketerzeuger laden
            
            files=dir(fullfile(dirpath,'*.mat'));
            
            if ~isempty(files)
                
                filename=files.name;

                filepath=fullfile(dirpath,filename);

                %TableDaten_WellenPaket: struct datei mit 21 fields 
                app.Varapp=open(filepath);

           
            else 
                app.Varapp=struct();
            end

            Index_TableDaten=contains(app.WaveTUBData.filenames, 'daten');
 
            %TableDaten_WaveTUB: Cell Array 1x1 mit einem 12x4 Table 
            TableDaten_WaveTUB=app.WaveTUBData.Data(Index_TableDaten,1);
            
            %TableDaten_WaveTUBStruct=table2struct(TableDaten_WaveTUB{1,1},'ToScalar',true)
            

            %nur noch der 12x4 table
            TableDaten_WaveTUB=TableDaten_WaveTUB{1,1}; 

            WellenpaketGeneratorData_raw=app.Varapp;


            filesPar=cell2table(files_all);
            filesPar=filesPar(contains(filesPar.(1),["par" "pro.par"]),:);
            filesPar=table2cell(filesPar);
            
            
            %Filepaths für zu ladende Files erzeugen 

            filepaths = fullfile(dirpath,filesPar);
            
            for i=1:max(size(filepaths))
            text = fileread(filepaths{i,:});
            end
            
            %propar laden
            propar = sscanf(text,'%f');

            %PROPAR
            %lesen von pro.par, macht nur sinn wenn so viele Pegel und
            %Tracks wie NPEGEL und NTRACK für die zuordnung der Positionen
            

            if(sum(Index_pegel))==TableDaten_WaveTUB{11,3} && sum(Index_track)==TableDaten_WaveTUB{12,3}

            app.WaveTUBData.Positionen=cell(max(size(app.WaveTUBData.Data)),2);
 
            j=0;
            m=0;
            j_end=sum(Index_pegel);
            m_end=sum(Index_track);

            for i=1:length(Index_pegel)
                
                j=j+1*Index_pegel(i);
                if Index_pegel(i)==1
                app.WaveTUBData.Positionen(i,:)={Index_pegel(i)*propar(11+j),0};  
                end
                
                m=m+1*Index_track(i);
                if Index_track(i)==1
                app.WaveTUBData.Positionen(i,:)={Index_track(i)*propar(13+j_end+m),Index_track(i)*propar(13+j_end+m+m_end)};
                end
            end
            end

            TIEFE=propar(2);

            %pro.wbx laden für Klappenmodus: 
            filesWbx=cell2table(files_all);
            filesWbx=filesWbx(contains(filesWbx.(1),["wbx" "pro.wbx"]),:);
            filesWbx=table2cell(filesWbx);
            
            
            %Filepaths für zu ladende Files erzeugen 

            filepaths = fullfile(dirpath,filesWbx);
            
            for i=1:max(size(filepaths))
            text = fileread(filepaths{i,:});
            end

            prowbx = sscanf(text,'%f');
            prowbx=reshape(prowbx(3:end)',[3,(max(length(prowbx(3:end))))/3])';


            sum2=sum(prowbx(:,2));
            sum3=sum(prowbx(:,3));
            
            if sum2==0 && sum3==0
                Klappenmodus='piston';
            end

            if sum2~=0 && sum3==0
                Klappenmodus='sigleFlap';
            end

            if sum2~=0 && sum3~=0
                Klappenmodus='dualFlap';
            end

            %TABLEDATEN TEIL 2
            %Tables Erzeugen: Versuchs

            if isfile(fullfile(dirpath,files.name))

                fn = fieldnames(WellenpaketGeneratorData_raw);
                WellenpaketGeneratorData={};

                for i=1:numel(fn)
                    numeric=isnumeric(WellenpaketGeneratorData_raw.(fn{i}));
                  
                    if numeric && max(size(WellenpaketGeneratorData_raw.(fn{i})))==1 || ~numeric 
                        fn_aktuell=char(fn(i));
                        WellenpaketGeneratorData.(fn_aktuell)=WellenpaketGeneratorData_raw.(fn_aktuell);
                    end
                end

                WellenpaketGeneratorData.Klappenmodus=Klappenmodus; 

                app.TableVersuchsdaten.Data=table(fieldnames(WellenpaketGeneratorData),struct2cell(WellenpaketGeneratorData));
               
            
            %Dateiinfos
            Erklaerung_Dateiinfos=["#Pegel";"#Teilchen";"#Fotos";"#Knoten";"#Netz"];
            Variable_Dateiinfos=["NPEGEL";"NTRACK";"NFOTO";"NKNOTEN";"NNETZ"];
            Wert_Dateiinfos=[TableDaten_WaveTUB{11,3};TableDaten_WaveTUB{12,3};TableDaten_WaveTUB{13,3};TableDaten_WaveTUB{14,3};TableDaten_WaveTUB{15,3}]; 

            app.TableDateiinfos.Data=table(Variable_Dateiinfos,Wert_Dateiinfos,Erklaerung_Dateiinfos); 

            end
            
            app.TableGesamt=struct('Versuchsdaten',app.TableVersuchsdaten.Data,'Dateiinfos',app.TableDateiinfos.Data,'WellenpaketGenerator',WellenpaketGeneratorData_raw);


            PegelData_cell=app.WaveTUBData.Data(Index_pegel,1);
            filenames=app.WaveTUBData.filenames(Index_pegel,1);
            
           
            for i=1:max(size(PegelData_cell))

                pegel(:,i) = table2array(PegelData_cell{i,1}).*TIEFE; 


            end

    %%%track laden
            
            Index_track=contains(app.WaveTUBData.filenames, 'track');
           
            app.track.Data=app.WaveTUBData.Data{Index_track,:};
            
    %%%surface laden 
            Index_surface=contains(app.WaveTUBData.filenames, 'surface');
           
            app.surface.Data=app.WaveTUBData.Data{Index_surface,:};

            IndexNAN=app.surface.Data{:,'Var3'}; 
            SurfaceRow2=app.surface.Data{:,"Var2"};
            SurfaceRow1=app.surface.Data{:,"Var1"};
            
            app.surface.N_x_eingestellt=TableDaten_WaveTUB{6,3}+2;

            [row, ~] = find(~isnan(IndexNAN));
            app.surface.N_x=max(row(2:end)-row(1:end-1));

            N_x_diff=row(2:end)-row(1:end-1);
            
            N_x_diff_diff=zeros(length(N_x_diff)+1,1);

            N_x_diff_diff(2:end-1)=N_x_diff(2:end)-N_x_diff(1:end-1); 
            N_x_diff_diff(1)=0; 
            N_x_diff_diff(end)=0; 
            
            app.surface.Brechstelle=logical(N_x_diff_diff);

            app.surface.N_z=TableDaten_WaveTUB{7,3};
            app.surface.d_t=TableDaten_WaveTUB{8,3};
            app.surface.T=TableDaten_WaveTUB{10,3}; 
            app.surface.N=length(row); 
        
            app.surface.L=SurfaceRow1(row(2)-1); 
            app.surface.x=linspace(0,app.surface.L,app.surface.N_x);
            app.surface.d_x=app.surface.L/app.surface.N_x; 

            if max(size((app.surface.Data)))==app.surface.N_x*(app.surface.N+1)+2*app.surface.N+2
                app.surface.gebrochen='false';
            else
                app.surface.gebrochen='true';
            end
               
            app.surface.surface=zeros(app.surface.N_x,app.surface.N);

            for i=1:app.surface.N-1
                
                surface_aktuell=SurfaceRow2(row(i)+1:row(i+1)-1);
                L_aktuell=SurfaceRow1(row(i)+1:row(i+1)-1);
                surface_aktuell_inter=interp1(L_aktuell,surface_aktuell,linspace(0,app.surface.L,app.surface.N_x));
                
                app.surface.surface(:,i)=surface_aktuell_inter; 
    
            end 
            
            app.surface.N_x=size(app.surface.surface,1);
           % app.surface.surface(isnan(app.surface.surface))=0; 
            
            app.surface.surface(1,:)=app.surface.surface(3,:);
            app.surface.surface(end,:)=app.surface.surface(end-2,:);
            app.surface.surface(2,:)=app.surface.surface(3,:);
            app.surface.surface(end-1,:)=app.surface.surface(end-2,:);
    
            app.surface.max=max(max(app.surface.surface));

            v=ones(app.surface.N_x,1);
        
            DX=spdiags([-v,v],[-1,1],app.surface.N_x,app.surface.N_x);
            app.surface.steilheit=DX*app.surface.surface./app.surface.d_x; 

            app.surface.steilheit(1,:)=app.surface.steilheit(2,:);
            app.surface.steilheit(end,:)=app.surface.steilheit(end-1,:);
            app.surface.steilheit(:,1)=app.surface.steilheit(:,2);
            app.surface.steilheit(:,end)=app.surface.steilheit(:,end-1);

%%%Fourier 
            PEGEL=fft(pegel)/max(length(pegel))*2;
            

            %app.Fourierdata={pegel;PEGEL;filenames};
            app.Fourierdata.pegel=pegel;

            dw=2*pi()/(app.surface.d_t*app.surface.N);
            
            %größte Freuenz w_N mit w=2pi*f=2pi*1/(2*d_t)=pi/f
            w_N=2*pi()/app.surface.d_t;
            
            %Kreisfrequenzbereich mit N stellen mit d_w als abstand 
            app.Fourierdata.w=linspace(0,w_N-dw,app.surface.N);


            app.Fourierdata.fftPegel=PEGEL; 
            app.Fourierdata.filenames=filenames; 
            
            app.Fourierdata.pegelmax=max(max(app.Fourierdata.pegel));


            %Videodaten initialisieren 
            Abspielgeschindigkeit_WaveTUBEditFieldValueChanged(app, event)
            
             %data Datei speichern
            if app.DataDateispeichernautomatischbeimLadenCheckBox.Value
                surface_save=app.surface; 
                fourier_save=app.Fourierdata;
                WaveTUBData_save=app.WaveTUBData;
                TableGesamt_save=app.TableGesamt; 
                track_save=app.track;
    
                data=struct('surface',surface_save,'fourier',fourier_save,'WaveTUBData',WaveTUBData_save,'TableGesamt',TableGesamt_save,'track',track_save);
         
                save([app.WaveTUBData.dirpath,'\data'],'-struct','data','-v7.3')
            end
            

            %passiert wenn es schon data gibt
            else 
                dummy=load([dirpath,'\data.mat']);
                app.TableGesamt=dummy.TableGesamt;
                app.surface=dummy.surface; 

                app.Fourierdata=dummy.fourier;
                app.WaveTUBData=dummy.WaveTUBData; 
            
                app.TableVersuchsdaten.Data=app.TableGesamt.Versuchsdaten;
                app.TableDateiinfos.Data=app.TableGesamt.Dateiinfos; 


                app.surface.surface(1,:)=app.surface.surface(3,:);
                app.surface.surface(end,:)=app.surface.surface(end-2,:);

                app.surface.steilheit(:,1)=app.surface.steilheit(:,3);
                app.surface.steilheit(:,end)=app.surface.steilheit(:,end-1);         

                Index_pegel=contains(app.WaveTUBData.filenames, 'pegel');
                Index_track=contains(app.WaveTUBData.filenames, 'track');
                app.PegelDropDown.Items=app.WaveTUBData.filenames(Index_pegel,1);
                app.PegelauswhlenDropDown.Items=app.WaveTUBData.filenames(Index_pegel,1);
            
                app.TrackauswhlenDropDown.Items=app.WaveTUBData.filenames(Index_track,1);

            end 

            app.plotbiswEditField.Value=round(app.Fourierdata.w(end)/2);

            app.Lamp.Color='g';

           % if exist('app.surface.')
        end

        % Callback function
        function loadPegelButtonPushed(app, event)
            %Laden der Variablen aus Savefile 
            
            %Das UI liefert den Namen und den Pfad der Datei
            [variables,pathload] = uigetfile('*.dat','.\Speich\','MultiSelect','on');
            

            %Hauptanwendnung in den Vordergrund
            drawnow;
            figure(app.UIFigure)
            
            
            %speichern der Datei wenn Benutzerauswahl über UI
            %erfolgreich war
 

            if isempty(variables)~=1

                %Dateiname und Pfad zusammenführen
                if max(size(variables))~=1

                        
                        matname = fullfile(pathload,variables);
                        %laden 
                        Data   = cell(max(size(variables)),1);

                        for i=1:max(size(variables))
                        
                        Data{i,1}=readtable(char(matname(i)));
                        end 

                else

                    Data   = cell(1,1);
                    matname = fullfile(pathload,variables);
                    %laden 
                    Data{1,1}=readtable(char(matname));

                end

            app.VersuchsdatenTab.Data=Data{1,1};
            else 
                braeak;
            end 
        end

        % Button pushed function: PlotUIPegelButton
        function PlotUIPegelButtonPushed(app, event)
           Index_pegel=contains(app.WaveTUBData.filenames, app.PegelDropDown.Value);

           PegelNum = find(ismember(app.PegelDropDown.Items, app.PegelDropDown.Value));
           Index_time=contains(app.WaveTUBData.filenames, 'time');

           y=app.Fourierdata.pegel(:,PegelNum);
           x=app.WaveTUBData.Data{Index_time,1}.Var1;

           cla(app.UIAxesWaveTUBSurface,'reset')

           
           ylim(app.UIAxesWaveTUBSurface,[-app.Fourierdata.pegelmax app.Fourierdata.pegelmax]);

           plot(app.UIAxesWaveTUBSurface,x,y)
           
           X=app.WaveTUBData.Positionen{Index_pegel,1};
           axis(app.UIAxesWaveTUBSurface, 'tight');

           tt=[app.PegelDropDown.Value,' bei L= ',num2str(X),' m'];
           title(app.UIAxesWaveTUBSurface,tt);
            
           
        end

        % Callback function
        function PlotUITrackButtonPushed(app, event)

           Index_track=contains(app.WaveTUBData.filenames, app.TrackauswhlenDropDown.Value);

           x=app.WaveTUBData.Data{Index_track,1}.Var1;
           y=app.WaveTUBData.Data{Index_track,1}.Var2;

        switch app.PlotnachTrackDropDown.Value
            case 'Fenster_01'
                axis=app.UIAxesWaveTUBKlein01;
            case '´Fenster_02'
                axis=app.UIAxesWaveTUBTrack02;
            case 'Fenster_03'
                axis=app.UIAxesWaveTUBTrack03;
        end
           X=app.WaveTUBData.Positionen{Index_track,1};
           Y=app.WaveTUBData.Positionen{Index_track,2};
           tt=[app.TrackauswhlenDropDown.Value,' bei P=(' ,num2str(X),' , ',num2str(Y),' ) m'];
           title(axis,tt);
           plot(axis,x,y)
           
        end

        % Value changed function: plotUIFourierButton
        function plotUIFourierButtonValueChanged(app, event)

            
           Index_pegel=contains(app.WaveTUBData.filenames, app.PegelauswhlenDropDown.Value);

           FourierNum = find(ismember(app.PegelauswhlenDropDown.Items, app.PegelauswhlenDropDown.Value));

           x=app.Fourierdata.w;
           y=abs(app.Fourierdata.fftPegel(:,FourierNum));
           

        switch app.PlotnachFourierDropDown.Value
            case 'Fenster_01'
                axis=app.UIAxesWaveTUBKlein01;
            case 'Fenster_02'
                axis=app.UIAxesWaveTUBKlein02;
            case 'Fenster_03'
                axis=app.UIAxesWaveTUBKlein03;
        end
           
        X=app.WaveTUBData.Positionen{Index_pegel,1};
        
        tt=[app.PegelauswhlenDropDown.Value,' bei L= ',num2str(X),' m'];
        title(axis,tt);
       
        N_end=round(app.plotbiswEditField.Value/(app.Fourierdata.w(2)-app.Fourierdata.w(1)));

        
       % isNZ=(y>0.000001);           % addressing logical array of nonzero elements
        plot(axis, x(1:N_end),y(1:N_end))   % plot only the subset
       xlabel(axis,'Kreisfrequenz w [1/s]')
        end

        % Callback function
        function plotUIIdealFourierButtonPushed(app, event)
          
        if ~isempty(app.Varapp)
           x=app.Varapp.w';
           y=app.Varapp.A';

        switch app.PlotnachFourierIdealDropDown.Value
            case 'Fourier_01'
                axis=app.UIAxesWaveTUBKlein01;
            case 'Fourier_02'
                axis=app.UIAxesWaveTUBKlein02;
            case 'Fourier_03'
                axis=app.UIAxesWaveTUBKlein03;
        end
           hold(axis,'on')

           N_end=round(app.plotbiswEditField.Value/(app.Fourierdata.w(2)-app.Fourierdata.w(1)));

    
           plot(axis, x(1:N_end),y(1:N_end))   % plot only the subset

 
        end
        end

        % Value changed function: PostionenXEditField
        function PostionenXEditFieldValueChanged(app, event)

             app.NTRACKEditField.Value=length(str2num(app.PostionenXEditField.Value));
        end

        % Button pushed function: VideoErstellen_WaveTUBButton
        function VideoErstellen_WaveTUBButtonPushed(app, event)
   % ---

%      app.FertigLamp_2.Color='r';
%             
%                 %laden von T_v_beg, T_v_end, kompression, v_abspiel 
%                 app.T_v_beg=app.t_v_begEditField.Value*app.WP.T;
%                 app.T_v_end=app.t_v_endEditField.Value*app.WP.T;
%                 app.kompressionZeit=round(app.KomprimierungSpinner.Value);
%                 v_abspiel=app.AbspielgeschindigkeitEditField.Value;
%                 
%                 %d_t_v=T/N * komp dh. Zeitschritt wird mit komp größer auch
%                 %Größer
%                 d_t_v=app.kompressionZeit*app.WP.T/app.WP.N;
%                 
%                 %framerate= v* 1/d_t_v wegen f=1/d_t, sakliert mit Video
%                 app.framerate=v_abspiel*1/d_t_v; 
%       
%                 %bestimmen der Indizes für T_v_beg und T_v_end aus N
%                 n_v_beg=round(app.T_v_beg/app.WP.T*app.WP.N)+1;
%                 n_v_end=round(app.T_v_end/app.WP.T*app.WP.N);
%                 
%                 
%                 %Eigenschaften (name, qualität, framerate) von Vidoe
%                 %bestimmen
%                 video=VideoWriter('video_linear');
%                 video.Quality = 95;
%                 video.FrameRate=app.framerate;
%                 
%                 %Vidoe starten
%                 open(video) 
%                 
%                 
%                 fig = figure('WindowState','maximized');  
%                 pause(1);
%                 fig.Position
%  
%                 for n_v=n_v_beg:app.kompressionZeit:n_v_end                
%                
%                       
%                     axisVideo=axis(gca);
%                     
%                     x = app.WP.xi; 
%                     y = app.WP.a(:,n_v);
%                     axis(axisVideo, 'tight');
%                     plot(x,y); 
%                     ylim([-app.WP.a_maxmax/app.zoom_Zeit app.WP.a_maxmax/app.zoom_Zeit]);
%                     title('Ortsverlauf')
%                     xlabel('Ort in [m]') 
%                     ylabel('Auslenkung in [cm]') 
%     
%                     
%                     frame=getframe(fig);
%                  
%                     
%                     writeVideo(video,frame);
%                     
%                     
%                     %abbruch durch Stop-Haken
%                     if app.STOPCheckBox.Value==true
%                         close(video)
%                         app.FertigLamp_2.Color='g';
%                         close(fig)
%                         break;    
%                     end
%                 
%                end
%                     close(video)
%                     
%                     app.FertigLamp_2.Color='g';
%                     
%                     close(fig)


                %Erstellung eines Viedos     
            
                app.Fertig_WaveTUBLamp.Color='r';
            
                %laden von T_v_beg, T_v_end, kompression, v_abspiel 
                app.T_v_beg_WaveTUB=app.t_v_beg_WaveTUBEditField.Value*app.surface.T;
                app.T_v_end_WaveTUB=app.t_v_end_WaveTUBEditField.Value*app.surface.T;
                app.kompressionZeit_WaveTUB=round(app.Komprimierung_WaveTUBSpinner.Value);
                v_abspiel=app.Abspielgeschindigkeit_WaveTUBEditField.Value;
                
                %d_t_v=T/N * komp dh. Zeitschritt wird mit komp größer auch
                %Größer
                d_t_v=app.kompressionZeit_WaveTUB*app.surface.T/app.surface.N;
                
                %framerate= v* 1/d_t_v wegen f=1/d_t, sakliert mit Video
                app.framerate_WaveTUB=v_abspiel*1/d_t_v; 
      
                %bestimmen der Indizes für T_v_beg und T_v_end aus N
                n_v_beg=round(app.T_v_beg_WaveTUB/app.surface.T*app.surface.N)+1;
                n_v_end=round(app.T_v_end_WaveTUB/app.surface.T*app.surface.N);
                
                videoname=['video'];
                %videoname=['Wellenpaket_WaveTUB_mit L=',num2str(app.L),', d=',num2str(app.d),', f=',num2str(app.f),', t_shift=',num2str(app.t_shift),', w_beg=',num2str(app.w_beg),', w_end=',num2str(app.w_end),' zoom=',num2str(app.zoom_Ort),', aufl_Ort=',num2str(app.aufl_Ort),',Kompression_Zeit=',num2str(app.kompressionZeit),', Framerate=',num2str(app.framerate),', Abspielgeschwindigkeit=',num2str(v_abspiel), '_Wellensteilheit=',num2str(double(app.WellensteilheitplottenCheckBox.Value)),'.avi'];
                videopath=fullfile(app.WaveTUBData.dirpath,videoname);

                [savefile,pathsave]=uiputfile(videopath);
                drawnow;
             

                %Hauptanwendnung in den Vordergrund
                figure(app.UIFigure)
                app.UIFigure.Visible = 'on';
                    
                %Dateiname und Pfad zusammenführen
                matname = fullfile(pathsave, savefile);
       
                if savefile~=00 
                %Eigenschaften (name, qualität, framerate) von Vidoe
                %bestimmen
                
               
                video=VideoWriter(matname); 
                video.Quality = 95;
                video.FrameRate=app.framerate_WaveTUB;
                
                %Vidoe starten
                open(video) 
                
                
                fig = figure('WindowState','maximized');  
                pause(1);
                fig.Position
                

                x=linspace(0,app.surface.L,app.surface.N_x);
                [N_x,~]=size(app.TableGesamt.WellenpaketGenerator.a);
                
                if isfield(app.TableGesamt.WellenpaketGenerator,'T_versatz')
                    a_lin=[zeros(N_x,app.TableGesamt.WellenpaketGenerator.N_versatz),app.TableGesamt.WellenpaketGenerator.a];
                    a_interpol=interp1(app.TableGesamt.WellenpaketGenerator.xi,a_lin,x);
                else
                    a_interpol=interp1(app.TableGesamt.WellenpaketGenerator.xi,app.TableGesamt.WellenpaketGenerator.a,x);

                end


                for n_v=n_v_beg:app.kompressionZeit_WaveTUB:n_v_end                
                                     
                    axisVideo=gca;
   
                    dx_max_zeit=round(max(app.surface.steilheit(:,n_v)),3);
                    dx_max_insgesamt=round(max(max(app.surface.steilheit)),3);
        
                    surface_max=max(max(app.surface.surface));
        
                    cla(axisVideo,'reset')


                    title(axisVideo,['Ortsverlauf bei t= ',(num2str(round(n_v/app.surface.N*app.surface.T,2)))])                
                    xlabel(axisVideo,'Ort in [m]') 
             
                    xlim(axisVideo,'tight')
                    
                    if app.IdealverlaufplottenCheckBox.Value 
                        

                        if app.WellensteilheitundBrechenCheckBox.Value


                            yyaxis(axisVideo,'left')
                            hold(axisVideo,'on'); 
               
                            anteil_brechen=sum(app.surface.Brechstelle)/length(app.surface.Brechstelle)*100;
                          
                            yl=max(ylim(axisVideo));
                            xl=max(xlim(axisVideo));
    
                            
                            if app.surface.Brechstelle(n_v)
                                
                                plot(axisVideo,x,app.surface.surface(:,n_v),"Color",'r','LineWidth',1)
                                plot(axisVideo,x,a_interpol(:,n_v),"Color","b","LineStyle","--")
    
                            else 
            
                                plot(axisVideo,x,app.surface.surface(:,n_v),"Color","k",'LineWidth',1)
                                plot(axisVideo,x,a_interpol(:,n_v),"Color","b","LineStyle","--")
    
    
                            end
    
                            ylim(axisVideo,[-surface_max surface_max]);
                            ylabel(axisVideo,'Auslenkung [m]')
            
                             
                            hold(axisVideo,'off'); 
                            yyaxis(axisVideo,'right')


                            plot(axisVideo,x,max(app.surface.steilheit'))


                            ylim(app.UIAxesWaveTUBSurface ...
                                ,[0 2.5*dx_max_insgesamt]);
                            ylabel(axisVideo,'Steilheit [1]')
            
                            hold(axisVideo,'off'); 
                            
                            yyaxis(axisVideo,'left')
                                
                            text(axisVideo,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an zur Zeit: ',num2str(dx_max_zeit)],'Color','black');
                            text(axisVideo,0.1,0.85*surface_max,['Welle bricht ',num2str(anteil_brechen),' % der Zeit'])
                            
            
                        else
                            hold(axisVideo,'on')
                            plot(axisVideo,x,app.surface.surface(:,n_v),"Color","k",'LineWidth',1)
                            plot(axisVideo,x,a_interpol(:,n_v),"Color","b","LineStyle","--",'LineWidth',1)
    
                            ylabel(axisVideo,'Auslenkung [m]')
            
                            ylim(axisVideo,[-surface_max surface_max]);
                            title(axisVideo,['Ortsverlauf bei t= ',(num2str(round(n_v/app.surface.N*app.surface.T,2)))])                
                            hold(axisVideo,'on')
                        end 

                   %Ideoalverlauf 
                    else 
                   
                        if app.WellensteilheitundBrechenCheckBox.Value
    
                            yyaxis(axisVideo,'left')
                            hold(axisVideo,'on'); 
               
                            anteil_brechen=sum(app.surface.Brechstelle)/length(app.surface.Brechstelle)*100;
                          
                            yl=max(ylim(axisVideo));
                            xl=max(xlim(axisVideo));
    
                            
                            if app.surface.Brechstelle(n_v)
                                
                                plot(axisVideo,x,app.surface.surface(:,n_v),"Color",'r','LineWidth',1)
                            else 
            
                                plot(axisVideo,x,app.surface.surface(:,n_v),"Color",'k','LineWidth',1)
    
                            end
    
                            ylim(axisVideo,[-surface_max surface_max]);
                            ylabel(axisVideo,'Auslenkung [m]')
            
                             
                            hold(axisVideo,'off'); 
                            yyaxis(axisVideo,'right')


                            plot(axisVideo,x,max(app.surface.steilheit'))
                            
                            ylim(app.UIAxesWaveTUBSurface ...
                                ,[0 2.5*dx_max_insgesamt]);
                            ylabel(axisVideo,'Steilheit [1]')
            
                            hold(axisVideo,'off'); 
                            
                            yyaxis(axisVideo,'left')
                                
                            text(axisVideo,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an zur Zeit: ',num2str(dx_max_zeit)],'Color','black');
                            text(axisVideo,0.1,0.85*surface_max,['Welle bricht ',num2str(anteil_brechen),' % der Zeit'])
                            
            
                        else
                          
                            plot(axisVideo,x,app.surface.surface(:,n_v))
                            
                           
                            ylabel(axisVideo,'Auslenkung [m]')
                            
                            ylim(axisVideo,[-surface_max surface_max]);
                            title(axisVideo,['Ortsverlauf bei t= ',(num2str(round(n_v/app.surface.N*app.surface.T,2)))])                
                            
    
                        end
                    end

                    if app.STOPCheckBox.Value==true
                        close(video)
                        app.Fertig_WaveTUBLamp='g';
                        close(fig)
                        break;    
                    end
               
               frame=getframe(fig);
               writeVideo(video,frame);
               end
                    close(video)
                    
                    app.Fertig_WaveTUBLamp='g';
                    
                    close(fig)
                end
        end

        % Callback function
        function ZoomOrt_WaveTUBEditFieldValueChanged(app, event)
             %Einlesen: Zoom Ort Video
            
            app.zoom_Ort_WaveTUB = app.ZoomOrt_WaveTUBEditField.Value;
            
            
        end

        % Value changed function: Abspielgeschindigkeit_WaveTUBEditField
        function Abspielgeschindigkeit_WaveTUBEditFieldValueChanged(app, event)
            %Einlesen: kompression, v_abspiel, T_v_beg, T_v_end            
            %Wenn geändert wird ändert sich: 
            %     framerate, Videolänge 
            
            v_abspiel = app.Abspielgeschindigkeit_WaveTUBEditField.Value;
            app.kompressionZeit_WaveTUB = app.Komprimierung_WaveTUBSpinner.Value;
            app.T_v_beg_WaveTUB=app.t_v_beg_WaveTUBEditField.Value*app.surface.T;
            app.T_v_end_WaveTUB=app.t_v_end_WaveTUBEditField.Value*app.surface.T;
             
            %d_t_v=T/N * komp dh. Zeitschritt wird mit komp größer auch
            %Größer
            d_t_v=app.surface.T*app.kompressionZeit_WaveTUB/app.surface.N;
             
            
            %framerate= v* 1/d_t_v wegen f=1/d_t, sakliert mit Video
            app.framerate_WaveTUB=v_abspiel*1/d_t_v; 
            
            %Berechnen der Videolänge
            app.T_video_WaveTUB=(app.T_v_end_WaveTUB-app.T_v_beg_WaveTUB)/v_abspiel; 
            
            %ausgabe framerate und Videolänge 
            app.Framerateberechnet_WaveTUBEditField.Value=app.framerate_WaveTUB; 
            app.Videolaengeberechnet_WaveTUBEditField.Value=app.T_video_WaveTUB;
            
        end

        % Value changed function: Komprimierung_WaveTUBSpinner
        function Komprimierung_WaveTUBSpinnerValueChanged(app, event)
            %Einlesen: kompression, v_abspiel, T_v_beg, T_v_end            
            %Wenn geändert wird ändert sich: 
            %     framerate, Videolänge 
           
            
            app.kompressionZeit_WaveTUB = app.Komprimierung_WaveTUBSpinner.Value;
            v_abspiel = app.Abspielgeschindigkeit_WaveTUBEditField.Value; 
            app.T_v_beg_WaveTUB=app.t_v_beg_WaveTUBEditField.Value*app.surface.T;
            app.T_v_end_WaveTUB=app.t_v_end_WaveTUBEditField.Value*app.surface.T;

            %d_t_v=T/N * komp dh. Zeitschritt wird mit komp größer auch
            %Größer
            d_t_v=app.surface.T/app.surface.N*app.kompressionZeit_WaveTUB;
            
            %framerate= v* 1/d_t_v wegen f=1/d_t, sakliert mit Video
            app.framerate_WaveTUB=v_abspiel*1/d_t_v; 
            
            %Berechnen der Videolänge
            app.T_video_WaveTUB=(app.T_v_end_WaveTUB-app.T_v_beg_WaveTUB)/v_abspiel; 
            
            %ausgabe framerate und Videolänge 
            app.Framerateberechnet_WaveTUBEditField.Value=app.framerate_WaveTUB; 
            app.Videolaengeberechnet_WaveTUBEditField.Value=app.T_video_WaveTUB;
            
        end

        % Value changed function: Zeitpunktrelativsurface_WaveTUBEditField
        function Zeitpunktrelativsurface_WaveTUBEditFieldValueChanged(app, event)
       
            
            app.t_plotbutton_WaveTUB = app.Zeitpunktrelativsurface_WaveTUBEditField.Value; 

          
            N_plot=round(app.t_plotbutton_WaveTUB*(app.surface.N-1)+1);  
            
            x=linspace(0,app.surface.L,app.surface.N_x);

            dx_max_zeit=round(max(app.surface.steilheit(:,N_plot)),3);
            dx_max_insgesamt=round(max(max(app.surface.steilheit)),3);

            surface_max=max(max(app.surface.surface));

            cla(app.UIAxesWaveTUBSurface,'reset')
            

            title(app.UIAxesWaveTUBSurface,['Ortsverlauf bei t= ',(num2str(round(app.t_plotbutton_WaveTUB*app.surface.T,2)))])                
            xlabel(app.UIAxesWaveTUBSurface,'Ort in [m]') 
     
            xlim(app.UIAxesWaveTUBSurface,'tight')
            

            if app.plotWellensteilheitundbrechenCheckBox_2.Value
        
                yyaxis(app.UIAxesWaveTUBSurface,'left')


                plot(app.UIAxesWaveTUBSurface,x,app.surface.surface(:,N_plot),"Color",'k','LineWidth',1)
                ylim(app.UIAxesWaveTUBSurface,[-surface_max surface_max]);
                ylabel(app.UIAxesWaveTUBSurface,'Auslenkung [cm]')

                yyaxis(app.UIAxesWaveTUBSurface,'right')


                plot(app.UIAxesWaveTUBSurface,x,max(app.surface.steilheit'))
                
                ylim(app.UIAxesWaveTUBSurface ...
                    ,[0 2.5*dx_max_insgesamt]);
                ylabel(app.UIAxesWaveTUBSurface,'Steilheit [1]')

                hold(app.UIAxesWaveTUBSurface,'off'); 
                
                yyaxis(app.UIAxesWaveTUBSurface,'left')
                    
                text(app.UIAxesWaveTUBSurface,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an zur Zeit: ',num2str(dx_max_zeit)],'Color','black');
    

            else
                plot(app.UIAxesWaveTUBSurface,x,app.surface.surface(:,N_plot))
                ylabel(app.UIAxesWaveTUBSurface,'Auslenkung [cm]')

                ylim(app.UIAxesWaveTUBSurface,[-surface_max surface_max]);
                title(app.UIAxesWaveTUBSurface,['Ortsverlauf bei t= ',(num2str(round(app.t_plotbutton_WaveTUB*app.surface.T,2)))])                

            end 
             
            xlabel(app.UIAxesWaveTUBSurface,'Ort x [m]')
            app.Zeitpunktabsolut_WaveTUBEditField.Value=N_plot/app.surface.N*app.surface.T;
            
        end

        % Callback function
        function ZoomOrtsurface_EditFieldEditFieldValueChanged(app, event)
                  %Einlesen: Zoom Ort 
            
            app.zoom_Ort_WaveTUB = app.ZoomOrtsurface_WaveTUBEditField.Value;
            
        end

        % Value changed function: Zeitpunkt_WaveTUBSlider
        function Zeitpunkt_WaveTUBSliderValueChanged(app, event)
            %Einlesen: t_plotslider (Sliderposition Zeit)
            %Wenn geändert wird ändert sich: 
            %     Ortsplot
            %     Zeitpunkt Absolut EditField
            
            app.t_plotslider_WaveTUB = app.Zeitpunkt_WaveTUBSlider.Value;
            N_plot=round(app.t_plotslider_WaveTUB*(app.surface.N-1)+1);  
            
            x=linspace(0,app.surface.L,app.surface.N_x);

            dx_max_zeit=round(max(app.surface.steilheit(:,N_plot)),3);
            dx_max_insgesamt=round(max(max(app.surface.steilheit)),3);

            surface_max=max(max(app.surface.surface));

            cla(app.UIAxesWaveTUBSurface,'reset')
            

            title(app.UIAxesWaveTUBSurface,['Ortsverlauf bei t= ',(num2str(round(app.t_plotslider_WaveTUB*app.surface.T,2)))])                
            xlabel(app.UIAxesWaveTUBSurface,'Ort in [m]') 
     
            xlim(app.UIAxesWaveTUBSurface,'tight')
            

            if app.plotWellensteilheitundbrechenCheckBox_2.Value
        
                yyaxis(app.UIAxesWaveTUBSurface,'left')


                plot(app.UIAxesWaveTUBSurface,x,app.surface.surface(:,N_plot),"Color",'k','LineWidth',1)
                ylim(app.UIAxesWaveTUBSurface,[-surface_max surface_max]);
                ylabel(app.UIAxesWaveTUBSurface,'Auslenkung [m]')

                yyaxis(app.UIAxesWaveTUBSurface,'right')


                plot(app.UIAxesWaveTUBSurface,x,max(app.surface.steilheit'))
                
                ylim(app.UIAxesWaveTUBSurface ...
                    ,[0 2.5*dx_max_insgesamt]);
                ylabel(app.UIAxesWaveTUBSurface,'Steilheit [1]')

                hold(app.UIAxesWaveTUBSurface,'off'); 
                
                yyaxis(app.UIAxesWaveTUBSurface,'left')
                    
                text(app.UIAxesWaveTUBSurface,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an zur Zeit: ',num2str(dx_max_zeit)],'Color','black');
    

            else
                plot(app.UIAxesWaveTUBSurface,x,app.surface.surface(:,N_plot))
                ylabel(app.UIAxesWaveTUBSurface,'Auslenkung [m]')

                ylim(app.UIAxesWaveTUBSurface,[-surface_max surface_max]);
                title(app.UIAxesWaveTUBSurface,['Ortsverlauf bei t= ',(num2str(round(app.t_plotslider_WaveTUB*app.surface.T,2)))])                

            end 
           
            xlabel(app.UIAxesWaveTUBSurface,'Ort x [m]')

            app.Zeitpunktabsolut_WaveTUBEditField.Value=N_plot/app.surface.N*app.surface.T;


        end

        % Value changed function: neuenAusgabeOrdnererstellenCheckBox_2
        function neuenAusgabeOrdnererstellenCheckBox_2ValueChanged(app, event)
            Checkbox = app.neuenAusgabeOrdnererstellenCheckBox_2.Value;
            app.neuenAusgabeOrdnererstellenCheckBox.Value=Checkbox; 
        end

        % Value changed function: neuenAusgabeOrdnererstellenCheckBox
        function neuenAusgabeOrdnererstellenCheckBoxValueChanged(app, event)
            Checkbox = app.neuenAusgabeOrdnererstellenCheckBox.Value;
            app.neuenAusgabeOrdnererstellenCheckBox_2.Value=Checkbox;
            
        end

        % Button pushed function: 
        % WaveTUBfrexternesWellenpaketausfhrenButton
        function WaveTUBfrexternesWellenpaketausfhrenButtonPushed(app, event)
            
            [pathsave]=uigetdir(pwd);
            copyfile([pathsave,'\pro.par'], pwd); 
            copyfile([pathsave,'\pro.wbx'], pwd); 

            run("PRO.m") 

            if app.AusgabedatenladenCheckBox_2.Value
                app.Gesamtenversuchladendirekt=true; 
                VersuchLadenButtonPushed(app,event)
            end 
        end

        % Value changed function: SteilheitEditField
        function SteilheitEditFieldValueChanged(app, event)
            app.steilheit_max = app.SteilheitEditField.Value;
            
        end

        % Callback function
        function speichernButton_2Pushed(app, event)
            surface_save=app.surface; 
            fourier_save=app.Fourierdata;
            WaveTUBData_save=app.WaveTUBData;
            TableGesamt_save=app.TableGesamt; 

            data=struct('surface',surface_save,'fourier',fourier_save,'WaveTUBData',WaveTUBData_save,'TableGesamt',TableGesamt_save);
     
            save([app.WaveTUBData.dirpath,'\data'],'-struct','data')

            
        end

        % Value changed function: Ortspunkt_WaveTUBSlider
        function Ortspunkt_WaveTUBSliderValueChanged(app, event)
            app.x_plotslider_WaveTUB = app.Ortspunkt_WaveTUBSlider.Value;
            N_xplot=round(app.x_plotslider_WaveTUB*(app.surface.N_x-1)+1);  
            
            t=linspace(0,app.surface.T,app.surface.N);

            dx_max_ort=round(max(app.surface.steilheit(N_xplot,:)),3);
            dx_max_insgesamt=round(max(max(app.surface.steilheit)),3);
            surface_max=max(max(app.surface.surface));
            cla(app.UIAxesWaveTUBPegel,'reset')
            binarymap=[1 1 1; 0.8 0.8 0.8];
            
            title(app.UIAxesWaveTUBPegel,['Zeitverlauf bei x= ',(num2str(round(app.x_plotslider_WaveTUB*app.surface.L,2)))])                
            xlabel(app.UIAxesWaveTUBPegel,'Zeit in [s]') 
     
            xlim(app.UIAxesWaveTUBPegel,'tight')
            
            if app.plotWellensteilheitundbrechenCheckBox.Value
        
                yyaxis(app.UIAxesWaveTUBPegel,'left')

                plot(app.UIAxesWaveTUBPegel,t,app.surface.surface(N_xplot,:),"Color",'k','LineWidth',1)
                ylim(app.UIAxesWaveTUBPegel,[-surface_max surface_max]);
                ylabel(app.UIAxesWaveTUBPegel,'Auslenkung [m]')

                yyaxis(app.UIAxesWaveTUBPegel,'right')

                plot(app.UIAxesWaveTUBPegel,t,max(app.surface.steilheit))
                
                ylim(app.UIAxesWaveTUBPegel,[0 2.5*dx_max_insgesamt]);
                ylabel(app.UIAxesWaveTUBPegel,'Steilheit [1]')

                hold(app.UIAxesWaveTUBPegel,'off'); 
                
                yyaxis(app.UIAxesWaveTUBPegel,'left')
                    
                text(app.UIAxesWaveTUBPegel,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an Ort: ',num2str(dx_max_ort)],'Color','black');


                hold(app.UIAxesWaveTUBPegel,'on');
                colormap(app.UIAxesWaveTUBPegel,binarymap)
                im=image(app.UIAxesWaveTUBPegel,[0,app.surface.T],[-1 1], app.surface.Brechstelle','CDataMapping','scaled');
    	        im.AlphaData = 0.6;
                set ( app.UIAxesWaveTUBPegel, 'ydir', 'normal' )
                hold(app.UIAxesWaveTUBPegel,'off');     

            else
                plot(app.UIAxesWaveTUBPegel,t,app.surface.surface(N_xplot,:))
                ylabel(app.UIAxesWaveTUBPegel,'Auslenkung [m]')

                ylim(app.UIAxesWaveTUBPegel,[-surface_max surface_max]);
                title(app.UIAxesWaveTUBPegel,['Zeitverlauf bei x= ',(num2str(round(app.x_plotslider_WaveTUB*app.surface.L,2)))])                

            end 

            xlim(app.UIAxesWaveTUBPegel,'tight')
            xlabel(app.UIAxesWaveTUBPegel,'Zeit t [s]')
            app.Ortspunktabsolut_WaveTUBEditField.Value=N_xplot/app.surface.N_x*app.surface.L;

        end

        % Value changed function: Ortspunktrelativsurface_WaveTUBEditField
        function Ortspunktrelativsurface_WaveTUBEditFieldValueChanged(app, event)
            app.x_plotEditField_WaveTUB = app.Ortspunktrelativsurface_WaveTUBEditField.Value;
          
            N_xplot=round(app.x_plotEditField_WaveTUB*(app.surface.N_x-1)+1);  
            
            t=linspace(0,app.surface.T,app.surface.N);

            dx_max_ort=round(max(app.surface.steilheit(N_xplot,:)),3);
            dx_max_insgesamt=round(max(max(app.surface.steilheit)),3);
            surface_max=max(max(app.surface.surface));
            cla(app.UIAxesWaveTUBPegel,'reset')
            binarymap=[1 1 1; 0.8 0.8 0.8];
            
            title(app.UIAxesWaveTUBPegel,['Zeitverlauf bei x= ',(num2str(round(app.x_plotEditField_WaveTUB*app.surface.L,2)))])                
            xlabel(app.UIAxesWaveTUBPegel,'Zeit in [s]') 
     
            xlim(app.UIAxesWaveTUBPegel,'tight')
            
            if app.plotWellensteilheitundbrechenCheckBox.Value
        
                yyaxis(app.UIAxesWaveTUBPegel,'left')

                plot(app.UIAxesWaveTUBPegel,t,app.surface.surface(N_xplot,:),"Color",'k','LineWidth',1)
                ylim(app.UIAxesWaveTUBPegel,[-surface_max surface_max]);
                ylabel(app.UIAxesWaveTUBPegel,'Auslenkung [m]')

                yyaxis(app.UIAxesWaveTUBPegel,'right')

                plot(app.UIAxesWaveTUBPegel,t,max(app.surface.steilheit))
                
                ylim(app.UIAxesWaveTUBPegel,[0 2.5*dx_max_insgesamt]);
                ylabel(app.UIAxesWaveTUBPegel,'Steilheit [1]')

                hold(app.UIAxesWaveTUBPegel,'off'); 
                
                yyaxis(app.UIAxesWaveTUBPegel,'left')
                    
                text(app.UIAxesWaveTUBPegel,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an Ort: ',num2str(dx_max_ort)],'Color','black');


                hold(app.UIAxesWaveTUBPegel,'on');
                colormap(app.UIAxesWaveTUBPegel,binarymap)
                im=image(app.UIAxesWaveTUBPegel,[0,app.surface.T],[-1 1], app.surface.Brechstelle','CDataMapping','scaled');
    	        im.AlphaData = 0.6;
                set ( app.UIAxesWaveTUBPegel, 'ydir', 'normal' )
                hold(app.UIAxesWaveTUBPegel,'off');     

            else
                plot(app.UIAxesWaveTUBPegel,t,app.surface.surface(N_xplot,:))
                ylabel(app.UIAxesWaveTUBPegel,'Auslenkung [m]')

                ylim(app.UIAxesWaveTUBPegel,[-surface_max surface_max]);
                title(app.UIAxesWaveTUBPegel,['Zeitverlauf bei x= ',(num2str(round(app.x_plotEditField_WaveTUB *app.surface.L,2)))])                

            end 
            app.Ortspunktabsolut_WaveTUBEditField.Value=N_xplot/app.surface.N_x*app.surface.L;

            xlim(app.UIAxesWaveTUBPegel,'tight')
            xlabel(app.UIAxesWaveTUBPegel,'Zeit t [s]')
            
        end

        % Value changed function: a_linksSlider
        function a_linksSliderValueChanged(app, event)
            app.WP.a_links = app.a_linksSlider.Value;
            
        end

        % Value changed function: a_rechtsSlider
        function a_rechtsSliderValueChanged(app, event)
            app.WP.a_rechts = app.a_rechtsSlider.Value;
            
        end

        % Button down function: UIAxesWaveTUBSurface
        function UIAxesWaveTUBSurfaceButtonDown(app, event)
            
        end

        % Value changed function: Ortspunktabsolut_WaveTUBEditField
        function Ortspunktabsolut_WaveTUBEditFieldValueChanged(app, event)
            app.x_plotEditField_WaveTUB = app.Ortspunktabsolut_WaveTUBEditField.Value/app.surface.L;
            
            app.Ortspunktrelativsurface_WaveTUBEditField.Value=app.x_plotEditField_WaveTUB;


            N_xplot=round(app.x_plotEditField_WaveTUB*(app.surface.N_x-1)+1);  
            
            t=linspace(0,app.surface.T,app.surface.N);

            dx_max_ort=round(max(app.surface.steilheit(N_xplot,:)),3);
            dx_max_insgesamt=round(max(max(app.surface.steilheit)),3);
            surface_max=max(max(app.surface.surface));
            cla(app.UIAxesWaveTUBPegel,'reset')
            binarymap=[1 1 1; 0.8 0.8 0.8];
            
            title(app.UIAxesWaveTUBPegel,['Zeitverlauf bei x= ',(num2str(round(app.Ortspunktabsolut_WaveTUBEditField.Value,2)))])                
            xlabel(app.UIAxesWaveTUBPegel,'Zeit in [s]') 
     
            xlim(app.UIAxesWaveTUBPegel,'tight')
            
            if app.plotWellensteilheitundbrechenCheckBox.Value
        
                yyaxis(app.UIAxesWaveTUBPegel,'left')

                plot(app.UIAxesWaveTUBPegel,t,app.surface.surface(N_xplot,:),"Color",'k','LineWidth',1)
                ylim(app.UIAxesWaveTUBPegel,[-surface_max surface_max]);
                ylabel(app.UIAxesWaveTUBPegel,'Auslenkung [m]')

                yyaxis(app.UIAxesWaveTUBPegel,'right')

                plot(app.UIAxesWaveTUBPegel,t,max(app.surface.steilheit))
                
                ylim(app.UIAxesWaveTUBPegel,[0 2.5*dx_max_insgesamt]);
                ylabel(app.UIAxesWaveTUBPegel,'Steilheit [1]')

                hold(app.UIAxesWaveTUBPegel,'off'); 
                
                yyaxis(app.UIAxesWaveTUBPegel,'left')
                    
                text(app.UIAxesWaveTUBPegel,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an Ort: ',num2str(dx_max_ort)],'Color','black');


                hold(app.UIAxesWaveTUBPegel,'on');
                colormap(app.UIAxesWaveTUBPegel,binarymap)
                im=image(app.UIAxesWaveTUBPegel,[0,app.surface.T],[-1 1], app.surface.Brechstelle','CDataMapping','scaled');
    	        im.AlphaData = 0.6;
                set ( app.UIAxesWaveTUBPegel, 'ydir', 'normal' )
                hold(app.UIAxesWaveTUBPegel,'off');     

            else
                plot(app.UIAxesWaveTUBPegel,t,app.surface.surface(N_xplot,:))
                ylabel(app.UIAxesWaveTUBPegel,'Auslenkung [m]')

                ylim(app.UIAxesWaveTUBPegel,[-surface_max surface_max]);
                title(app.UIAxesWaveTUBPegel,['Zeitverlauf bei x= ',(num2str(round(app.Ortspunktabsolut_WaveTUBEditField.Value,2)))])                

            end 
            xlabel(app.UIAxesWaveTUBPegel,'Zeit t [s]')
             xlim(app.UIAxesWaveTUBPegel,'tight')
         
            
        end

        % Button pushed function: PlotUITrackButton
        function PlotUITrackButtonPushed2(app, event)
             Index_track=contains(app.WaveTUBData.filenames, app.TrackauswhlenDropDown.Value);

           x=app.WaveTUBData.Data{Index_track,1}.Var1;
           y=app.WaveTUBData.Data{Index_track,1}.Var2;

        switch app.PlotnachTrackDropDown.Value
            case 'Fenster_01'
                axis=app.UIAxesWaveTUBKlein01;
            case 'Fenster_02'
                axis=app.UIAxesWaveTUBKlein02;
            case 'Fenster_03'
                axis=app.UIAxesWaveTUBKlein03;
        end

           
           X=app.WaveTUBData.Positionen{Index_track,1};
           Y=app.WaveTUBData.Positionen{Index_track,2};
           tt=[app.TrackauswhlenDropDown.Value,' bei P=(' ,num2str(X),' , ',num2str(Y),' ) m'];
           title(axis,tt);
           plot(axis,x,y)
           ylabel(axis,'Y-Position [m]')
           xlabel(axis,'X-Position [m]')
        end

        % Value changed function: Zeitpunktabsolut_WaveTUBEditField
        function Zeitpunktabsolut_WaveTUBEditFieldValueChanged(app, event)
               
            app.t_plotbutton_WaveTUB = app.Zeitpunktabsolut_WaveTUBEditField.Value/app.surface.T; 
            app.Zeitpunktrelativsurface_WaveTUBEditField.Value=app.t_plotbutton_WaveTUB;

          
            N_plot=round(app.t_plotbutton_WaveTUB*(app.surface.N-1)+1);  
            
            x=linspace(0,app.surface.L,app.surface.N_x);

            dx_max_zeit=round(max(app.surface.steilheit(:,N_plot)),3);
            dx_max_insgesamt=round(max(max(app.surface.steilheit)),3);

            surface_max=max(max(app.surface.surface));

            cla(app.UIAxesWaveTUBSurface,'reset')
            

            title(app.UIAxesWaveTUBSurface,['Ortsverlauf bei t= ',(num2str(round(app.t_plotbutton_WaveTUB*app.surface.T,2)))])                
            xlabel(app.UIAxesWaveTUBSurface,'Ort in [m]') 
     
            xlim(app.UIAxesWaveTUBSurface,'tight')
            

            if app.plotWellensteilheitundbrechenCheckBox_2.Value
        
                yyaxis(app.UIAxesWaveTUBSurface,'left')


                plot(app.UIAxesWaveTUBSurface,x,app.surface.surface(:,N_plot),"Color",'k','LineWidth',1)
                ylim(app.UIAxesWaveTUBSurface,[-surface_max surface_max]);
                ylabel(app.UIAxesWaveTUBSurface,'Auslenkung [cm]')

                yyaxis(app.UIAxesWaveTUBSurface,'right')


                plot(app.UIAxesWaveTUBSurface,x,max(app.surface.steilheit'))
                
                ylim(app.UIAxesWaveTUBSurface ...
                    ,[0 2.5*dx_max_insgesamt]);
                ylabel(app.UIAxesWaveTUBSurface,'Steilheit [1]')

                hold(app.UIAxesWaveTUBSurface,'off'); 
                
                yyaxis(app.UIAxesWaveTUBSurface,'left')
                    
                text(app.UIAxesWaveTUBSurface,0.1,0.9*surface_max,[' Maximale Steilheit (insgesamt): ',num2str(dx_max_insgesamt),'  Maximale Steilheit an zur Zeit: ',num2str(dx_max_zeit)],'Color','black');
    

            else
                plot(app.UIAxesWaveTUBSurface,x,app.surface.surface(:,N_plot))
                ylabel(app.UIAxesWaveTUBSurface,'Auslenkung [cm]')

                ylim(app.UIAxesWaveTUBSurface,[-surface_max surface_max]);
                title(app.UIAxesWaveTUBSurface,['Ortsverlauf bei t= ',(num2str(round(app.t_plotbutton_WaveTUB*app.surface.T,2)))])                

            end 
             
            xlabel(app.UIAxesWaveTUBSurface,'Ort x [m]')
            
            
        end

        % Button pushed function: AmplitudeLadenButton
        function AmplitudeLadenButtonPushed(app, event)
            %Laden der Variablen aus Savefile 
            
            %Das UI liefert den Namen und den Pfad der Datei
            [variables,pathload] = uigetfile('*.txt');
            
            %Hauptanwendnung in den Vordergrund
            drawnow;
            figure(app.UIFigure)
            %speichern der Datei wenn Benutzerauswahl über UI
            %erfolgreich war
            if variables~=0
                %Dateiname und Pfad zusammenführen
                matname = fullfile(pathload, variables);
                
                %laden 
                fileID=fopen(matname);
                A_real_raw=fscanf(fileID,'%f');

                app.WP.A_real_geladen=A_real_raw(5:end)'; 
                app.WP.A_real=app.WP.A_real_geladen;
                
                app.WP.d_t=A_real_raw(1);
                app.WP.t_shift=A_real_raw(2);
                app.WP.w_beg=A_real_raw(3);
                app.WP.w_end=A_real_raw(4);
                app.WP.N=size(app.WP.A_real,2);
                app.WP.A_real(end/2:end)=0;

                app.WP.T=app.WP.d_t*app.WP.N;

                app.WP.t=linspace(0,app.WP.T-app.WP.T/app.WP.N,app.WP.N);
         
                app.WP.d_w=2*pi()/(app.WP.d_t*app.WP.N);
                                
                w_N=2*pi()/app.WP.d_t;
                              
                app.WP.w=linspace(0,w_N-app.WP.d_w,app.WP.N);
                              
                k0=app.WP.w.^2/app.WP.g;
                
                app.WP.k=k0;
                
                N_wend=round(app.WP.w_end/app.WP.d_w);
                app.WP.d_x=2*pi()/(2*app.WP.k(N_wend))/app.WP.aufl_Ort;
                
                app.WP.k=k0;
                k_alt=0; 
            
                k_wend=app.WP.w_end^2/app.WP.g;
                i=0;

                while (max(abs(k_alt-app.WP.k))>0.00001 && i<10000)
                    k_alt=app.WP.k; 
                    app.WP.k=app.WP.w.^2./(tanh(app.WP.k.*app.WP.d)*app.WP.g); 
                    k_wend=app.WP.w_end.^2./(tanh(k_wend*app.WP.d)*app.WP.g); 
                    i=i+1; 
                end
            %
                app.WP.k(1)=0;


                app.WP.L=app.WP.T*(1/2*app.WP.w_end/k_wend*(1+2*k_wend*app.WP.d/sinh(2*k_wend*app.WP.d)));

                updateVariablenAnzeige(app)    

                app.plotbiswAmplitudenverlaufLaden.Value=app.WP.w(end);
                plotAmplituenverlaufGeladen(app) 
                
            end


        end

        % Value changed function: plotbiswAmplitudenverlaufLaden
        function plotbiswAmplitudenverlaufLadenValueChanged(app, event)
            plotAmplituenverlaufGeladen(app)
            
        end

        % Value changed function: KlappenmodusDropDown
        function KlappenmodusDropDownValueChanged(app, event)
            app.WP.Klappenmodus = app.KlappenmodusDropDown.Value;
            
            switch app.WP.Klappenmodus
                case 'Piston'
                    app.longEditField.Value=0; 
                case 'singleFlap'
                    app.longEditField.Value=app.Z1EditField.Value; 
            end

            app.WP.long=app.longEditField.Value;
        end

        % Value changed function: longEditField
        function longEditFieldValueChanged(app, event)
            app.WP.long = app.longEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup10 = uitabgroup(app.UIFigure);
            app.WellenpaketgenerierenTab = uitab(app.TabGroup10);
            app.WellenpaketgenerierenTab.Title = 'Wellenpaket generieren ';
            app.GridLayout = uigridlayout(app.WellenpaketgenerierenTab);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout11 = uigridlayout(app.Panel);
            app.BerechnenButton = uibutton(app.GridLayout11, 'push');
            app.BerechnenButton.ButtonPushedFcn = createCallbackFcn(app, @BerechnenButtonPushed, true);
            app.BerechnenButton.Text = 'Berechnen';
            app.AmplitudenmodusDropDown = uidropdown(app.GridLayout11);
            app.AmplitudenmodusDropDown.Items = {'Henning', 'regelmäßige Wellen', 'konstante Wellensteilheit', 'Amplitudenverlauf geladen'};
            app.AmplitudenmodusDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplitudenmodusDropDownValueChanged, true);
            app.AmplitudenmodusDropDown.Value = 'Henning';
            app.AmplitudenmodusDropDownLabel = uilabel(app.GridLayout11);
            app.AmplitudenmodusDropDownLabel.Text = {'Amplituden-'; 'modus:'};
            app.FortschrittEditField = uieditfield(app.GridLayout11, 'numeric');
            app.FortschrittEditField.Editable = 'off';
            app.FortschrittEditFieldLabel = uilabel(app.GridLayout11);
            app.FortschrittEditFieldLabel.Text = 'Fortschritt';
            app.FertigLamp = uilamp(app.GridLayout11);
            app.StopCheckBox = uicheckbox(app.GridLayout11);
            app.StopCheckBox.Text = 'Stop';
            app.Label = uilabel(app.GridLayout11);
            app.Label.Text = '%';
            app.speichernButton = uibutton(app.GridLayout11, 'push');
            app.speichernButton.ButtonPushedFcn = createCallbackFcn(app, @speichernButtonPushed, true);
            app.speichernButton.Text = 'speichern';
            app.ladenButton = uibutton(app.GridLayout11, 'push');
            app.ladenButton.ButtonPushedFcn = createCallbackFcn(app, @ladenButtonPushed, true);
            app.ladenButton.Text = 'laden';
            app.neuenAusgabeOrdnererstellenCheckBox_2 = uicheckbox(app.GridLayout11);
            app.neuenAusgabeOrdnererstellenCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @neuenAusgabeOrdnererstellenCheckBox_2ValueChanged, true);
            app.neuenAusgabeOrdnererstellenCheckBox_2.Text = 'neuen Ausgabe Ordner erstellen';
            app.DateneingabeLabel = uilabel(app.GridLayout);
            app.DateneingabeLabel.Text = '1. Dateneingabe';
            app.BerechnungEinlesenLabel = uilabel(app.GridLayout);
            app.BerechnungEinlesenLabel.Text = '2. Berechnung/Einlesen';
            app.PlotVideoLabel = uilabel(app.GridLayout);
            app.PlotVideoLabel.Text = '3. Plot/Video';
            app.TabGroup2 = uitabgroup(app.GridLayout);
            app.StandortdatenTab = uitab(app.TabGroup2);
            app.StandortdatenTab.Title = 'Standortdaten';
            app.TabGroup5 = uitabgroup(app.StandortdatenTab);
            app.EingabefrWavetubTab = uitab(app.TabGroup5);
            app.EingabefrWavetubTab.Title = 'Eingabe für Wavetub ';
            app.GridLayout3 = uigridlayout(app.EingabefrWavetubTab);
            app.LAENGEEditFieldLabel = uilabel(app.GridLayout3);
            app.LAENGEEditFieldLabel.Text = 'LAENGE';
            app.LAENGEEditField = uieditfield(app.GridLayout3, 'numeric');
            app.LAENGEEditField.Limits = [0.01 Inf];
            app.LAENGEEditField.ValueChangedFcn = createCallbackFcn(app, @LAENGEEditFieldValueChanged, true);
            app.LAENGEEditField.Value = 20;
            app.TIEFEEditFieldLabel = uilabel(app.GridLayout3);
            app.TIEFEEditFieldLabel.Text = 'TIEFE';
            app.TIEFEEditField = uieditfield(app.GridLayout3, 'numeric');
            app.TIEFEEditField.Limits = [0.01 Inf];
            app.TIEFEEditField.ValueChangedFcn = createCallbackFcn(app, @TIEFEEditFieldValueChanged, true);
            app.TIEFEEditField.Value = 10;
            app.DTEditFieldLabel = uilabel(app.GridLayout3);
            app.DTEditFieldLabel.Text = 'DT';
            app.DTEditField = uieditfield(app.GridLayout3, 'numeric');
            app.DTEditField.Limits = [0 Inf];
            app.DTEditField.ValueChangedFcn = createCallbackFcn(app, @DTEditFieldValueChanged, true);
            app.DTEditField.Value = 0.04;
            app.AuflsungOrtsbereichEditField_WaveTUB = uieditfield(app.GridLayout3, 'numeric');
            app.AuflsungOrtsbereichEditField_WaveTUB.Limits = [0 Inf];
            app.AuflsungOrtsbereichEditField_WaveTUB.ValueChangedFcn = createCallbackFcn(app, @AuflsungOrtsbereichEditField_WaveTUBValueChanged, true);
            app.AuflsungOrtsbereichEditField_WaveTUB.Value = 10;
            app.AuflsungOrtsbereichEditField_3Label = uilabel(app.GridLayout3);
            app.AuflsungOrtsbereichEditField_3Label.Text = 'Auflösung Ortsbereich';
            app.MaxAmplitudeOrtEditField_WaveTUB = uieditfield(app.GridLayout3, 'numeric');
            app.MaxAmplitudeOrtEditField_WaveTUB.Limits = [0 1];
            app.MaxAmplitudeOrtEditField_WaveTUB.ValueChangedFcn = createCallbackFcn(app, @MaxAmplitudeOrtEditField_WaveTUBValueChanged, true);
            app.MaxAmplitudeOrtEditField_WaveTUB.Value = 0.5;
            app.ReferenzstelleAmplitudenkaliebrierungLabel = uilabel(app.GridLayout3);
            app.ReferenzstelleAmplitudenkaliebrierungLabel.Text = 'Referenzstelle Amplitudenkaliebrierung';
            app.AmplitudeLabel = uilabel(app.GridLayout3);
            app.AmplitudeLabel.Text = 'Amplitude';
            app.MaxAmplitudeEditField_WaveTUB = uieditfield(app.GridLayout3, 'numeric');
            app.MaxAmplitudeEditField_WaveTUB.ValueChangedFcn = createCallbackFcn(app, @MaxAmplitudeEditField_WaveTUBValueChanged, true);
            app.MaxAmplitudeEditField_WaveTUB.Value = 1;
            app.mLabel = uilabel(app.GridLayout3);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.GridLayout3);
            app.mLabel_2.Text = 'm';
            app.Label_12 = uilabel(app.GridLayout3);
            app.Label_12.Text = '-';
            app.Label_2 = uilabel(app.GridLayout3);
            app.Label_2.Text = '-';
            app.xLLabel_2 = uilabel(app.GridLayout3);
            app.xLLabel_2.Text = 'x/L';
            app.mLabel_4 = uilabel(app.GridLayout3);
            app.mLabel_4.Text = 'm';
            app.sLabel = uilabel(app.GridLayout3);
            app.sLabel.Text = 's';
            app.sLabel_2 = uilabel(app.GridLayout3);
            app.sLabel_2.Text = 's';
            app.NEditFieldLabel = uilabel(app.GridLayout3);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.GridLayout3, 'numeric');
            app.NEditField.Editable = 'off';
            app.ParameterverwendenWaveTUBCheckBox = uicheckbox(app.GridLayout3);
            app.ParameterverwendenWaveTUBCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterverwendenWaveTUBCheckBoxValueChanged, true);
            app.ParameterverwendenWaveTUBCheckBox.Text = 'Parameter verwenden? ';
            app.ParameterverwendenWaveTUBCheckBox.Value = true;
            app.ausw_endLabel = uilabel(app.GridLayout3);
            app.ausw_endLabel.Text = ' (aus w_end)';
            app.KonzentrationszeitpunktLabel = uilabel(app.GridLayout3);
            app.KonzentrationszeitpunktLabel.Text = 'Konzentrationspunkt (relativ)';
            app.KonzpunktEditField_WaveTUB = uieditfield(app.GridLayout3, 'numeric');
            app.KonzpunktEditField_WaveTUB.Limits = [0 Inf];
            app.KonzpunktEditField_WaveTUB.Value = 0.5;
            app.ENDTIMEEditFieldLabel = uilabel(app.GridLayout3);
            app.ENDTIMEEditFieldLabel.Text = 'ENDTIME';
            app.ENDTIMEEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ENDTIMEEditField.Editable = 'off';
            app.EingabeStandardTab = uitab(app.TabGroup5);
            app.EingabeStandardTab.Title = 'Eingabe Standard';
            app.GridLayout3_2 = uigridlayout(app.EingabeStandardTab);
            app.ErdbeschleunigungEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.ErdbeschleunigungEditField.Limits = [0.01 Inf];
            app.ErdbeschleunigungEditField.ValueChangedFcn = createCallbackFcn(app, @ErdbeschleunigungEditFieldValueChanged, true);
            app.ErdbeschleunigungEditField.Value = 9.81;
            app.ErdbeschleunigungEditFieldLabel = uilabel(app.GridLayout3_2);
            app.ErdbeschleunigungEditFieldLabel.Text = 'Erdbeschleunigung';
            app.LaengeEditFieldLabel = uilabel(app.GridLayout3_2);
            app.LaengeEditFieldLabel.Text = 'Laenge ';
            app.LaengeEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.LaengeEditField.Limits = [0.01 Inf];
            app.LaengeEditField.Value = 10;
            app.TiefeEditFieldLabel = uilabel(app.GridLayout3_2);
            app.TiefeEditFieldLabel.Text = 'Tiefe ';
            app.TiefeEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.TiefeEditField.Limits = [0.01 Inf];
            app.TiefeEditField.ValueChangedFcn = createCallbackFcn(app, @LaengeEditFieldValueChanged, true);
            app.TiefeEditField.Value = 10;
            app.AbtastfrequenzEditFieldLabel = uilabel(app.GridLayout3_2);
            app.AbtastfrequenzEditFieldLabel.Text = 'Abtastfrequenz ';
            app.AbtastfrequenzEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.AbtastfrequenzEditField.Limits = [0.01 Inf];
            app.AbtastfrequenzEditField.ValueChangedFcn = createCallbackFcn(app, @AbtastfrequenzEditFieldValueChanged, true);
            app.AbtastfrequenzEditField.Value = 10;
            app.Konzpunkt_relativEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.Konzpunkt_relativEditField.Limits = [0 Inf];
            app.Konzpunkt_relativEditField.ValueChangedFcn = createCallbackFcn(app, @Konzpunkt_relativEditFieldValueChanged, true);
            app.Konzpunkt_relativEditField.Value = 0.5;
            app.KonzentrationspunktEditFieldLabel_2 = uilabel(app.GridLayout3_2);
            app.KonzentrationspunktEditFieldLabel_2.Text = 'Konzentrationspunkt ';
            app.beiLEditField_2Label_2 = uilabel(app.GridLayout3_2);
            app.beiLEditField_2Label_2.Text = 'bei L:';
            app.Konzpunkt_absolutEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.Konzpunkt_absolutEditField.ValueChangedFcn = createCallbackFcn(app, @Konzpunkt_absolutEditFieldValueChanged, true);
            app.AuflsungOrtsbereichEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.AuflsungOrtsbereichEditField.Limits = [0 Inf];
            app.AuflsungOrtsbereichEditField.ValueChangedFcn = createCallbackFcn(app, @AuflsungOrtsbereichEditFieldValueChanged, true);
            app.AuflsungOrtsbereichEditField.Value = 10;
            app.AuflsungOrtsbereichEditFieldLabel = uilabel(app.GridLayout3_2);
            app.AuflsungOrtsbereichEditFieldLabel.Text = 'Auflösung Ortsbereich';
            app.MaxAmplitudeOrtEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.MaxAmplitudeOrtEditField.Limits = [0 1];
            app.MaxAmplitudeOrtEditField.ValueChangedFcn = createCallbackFcn(app, @MaxAmplitudeOrtditFieldValueChanged, true);
            app.MaxAmplitudeOrtEditField.Value = 0.5;
            app.maximaleAplitudebeiEditFieldLabel = uilabel(app.GridLayout3_2);
            app.maximaleAplitudebeiEditFieldLabel.Text = 'maximale Aplitude bei';
            app.istEditFieldLabel = uilabel(app.GridLayout3_2);
            app.istEditFieldLabel.Text = 'ist ';
            app.MaxAmplitudeEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.MaxAmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @MaxAmplitudeEditFieldValueChanged, true);
            app.MaxAmplitudeEditField.Value = 2;
            app.ms2Label = uilabel(app.GridLayout3_2);
            app.ms2Label.Text = 'm/s^2';
            app.mLabel_5 = uilabel(app.GridLayout3_2);
            app.mLabel_5.Text = 'm';
            app.mLabel_6 = uilabel(app.GridLayout3_2);
            app.mLabel_6.Text = 'm';
            app.sLabel_3 = uilabel(app.GridLayout3_2);
            app.sLabel_3.Text = '1/s';
            app.xLLabel_3 = uilabel(app.GridLayout3_2);
            app.xLLabel_3.Text = 'x/L';
            app.Label_3 = uilabel(app.GridLayout3_2);
            app.Label_3.Text = '-';
            app.xLLabel_4 = uilabel(app.GridLayout3_2);
            app.xLLabel_4.Text = 'x/L';
            app.mLabel_7 = uilabel(app.GridLayout3_2);
            app.mLabel_7.Text = 'm';
            app.mLabel_8 = uilabel(app.GridLayout3_2);
            app.mLabel_8.Text = 'm';
            app.ParameterverwendenKanalCheckBox = uicheckbox(app.GridLayout3_2);
            app.ParameterverwendenKanalCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParameterverwendenKanalCheckBoxValueChanged, true);
            app.ParameterverwendenKanalCheckBox.Text = 'Parameter verwenden? ';
            app.AmplitudenverlaufTab = uitab(app.TabGroup2);
            app.AmplitudenverlaufTab.Title = 'Amplitudenverlauf';
            app.TabGroup3 = uitabgroup(app.AmplitudenverlaufTab);
            app.AmplitudenverlaufladenTab = uitab(app.TabGroup3);
            app.AmplitudenverlaufladenTab.Title = 'Amplitudenverlauf laden';
            app.GridLayout25 = uigridlayout(app.AmplitudenverlaufladenTab);
            app.UIAxesAmplituedenverlaufGeladen = uiaxes(app.GridLayout25);
            title(app.UIAxesAmplituedenverlaufGeladen, 'Title')
            xlabel(app.UIAxesAmplituedenverlaufGeladen, 'X')
            ylabel(app.UIAxesAmplituedenverlaufGeladen, 'Y')
            zlabel(app.UIAxesAmplituedenverlaufGeladen, 'Z')
            app.AmplitudeLadenButton = uibutton(app.GridLayout25, 'push');
            app.AmplitudeLadenButton.ButtonPushedFcn = createCallbackFcn(app, @AmplitudeLadenButtonPushed, true);
            app.AmplitudeLadenButton.Text = 'Amplitude Laden';
            app.plotbiswAmplitudenverlaufLaden = uieditfield(app.GridLayout25, 'numeric');
            app.plotbiswAmplitudenverlaufLaden.ValueChangedFcn = createCallbackFcn(app, @plotbiswAmplitudenverlaufLadenValueChanged, true);
            app.plotbiswLabel = uilabel(app.GridLayout25);
            app.plotbiswLabel.Text = 'plot bis w: ';
            app.HenningscherVerlaufTab = uitab(app.TabGroup3);
            app.HenningscherVerlaufTab.Title = 'Henningscher Verlauf';
            app.GridLayout4 = uigridlayout(app.HenningscherVerlaufTab);
            app.UIAxes2 = uiaxes(app.GridLayout4);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.w_begEditField = uieditfield(app.GridLayout5, 'numeric');
            app.w_begEditField.Limits = [0 Inf];
            app.w_begEditField.ValueChangedFcn = createCallbackFcn(app, @w_begEditField_HenningValueChanged, true);
            app.w_begEditField.Value = 1;
            app.w_begEditFieldLabel = uilabel(app.GridLayout5);
            app.w_begEditFieldLabel.Text = 'w_beg';
            app.w_endEditField = uieditfield(app.GridLayout5, 'numeric');
            app.w_endEditField.Limits = [0 Inf];
            app.w_endEditField.ValueChangedFcn = createCallbackFcn(app, @w_endEditField_HenningValueChanged, true);
            app.w_endEditField.Value = 10;
            app.w_endEditFieldLabel = uilabel(app.GridLayout5);
            app.w_endEditFieldLabel.Text = 'w_end';
            app.PlotHenningButton = uibutton(app.GridLayout5, 'push');
            app.PlotHenningButton.ButtonPushedFcn = createCallbackFcn(app, @PlotHenningButtonPushed, true);
            app.PlotHenningButton.Text = 'Plot Henning';
            app.sLabel_4 = uilabel(app.GridLayout5);
            app.sLabel_4.Text = '1/s';
            app.sLabel_5 = uilabel(app.GridLayout5);
            app.sLabel_5.Text = '1/s';
            app.regulreWellenTab = uitab(app.TabGroup3);
            app.regulreWellenTab.Title = 'reguläre Wellen';
            app.GridLayout10 = uigridlayout(app.regulreWellenTab);
            app.WellenlngeSollwertEditFieldLabel = uilabel(app.GridLayout10);
            app.WellenlngeSollwertEditFieldLabel.Text = 'Wellenlänge (Sollwert)';
            app.WellenlngeSollwertEditField = uieditfield(app.GridLayout10, 'numeric');
            app.WellenlngeSollwertEditField.ValueChangedFcn = createCallbackFcn(app, @RegWellenWellenlngeSollwertEditFieldValueChanged, true);
            app.WellenlngeSollwertEditField.Value = 1;
            app.WellenperiodeSollwertEditFieldLabel = uilabel(app.GridLayout10);
            app.WellenperiodeSollwertEditFieldLabel.Text = 'Wellenperiode (Sollwert)';
            app.WellenperiodeSollwertEditField = uieditfield(app.GridLayout10, 'numeric');
            app.WellenperiodeSollwertEditField.ValueChangedFcn = createCallbackFcn(app, @RegWellenWellenperiodeSollwertEditFieldValueChanged, true);
            app.WellenperiodeSollwertEditField.Value = 0.800304816240038;
            app.mLabel_9 = uilabel(app.GridLayout10);
            app.mLabel_9.Text = 'm';
            app.sLabel_6 = uilabel(app.GridLayout10);
            app.sLabel_6.Text = 's';
            app.WellenlngeIstwertEditFieldLabel = uilabel(app.GridLayout10);
            app.WellenlngeIstwertEditFieldLabel.Text = 'Wellenlänge (Istwert)';
            app.WellenlngeIstwertEditField = uieditfield(app.GridLayout10, 'numeric');
            app.WellenlngeIstwertEditField.Editable = 'off';
            app.WellenperiodeIstwertEditFieldLabel = uilabel(app.GridLayout10);
            app.WellenperiodeIstwertEditFieldLabel.Text = 'Wellenperiode (Istwert)';
            app.WellenperiodeIstwertEditField = uieditfield(app.GridLayout10, 'numeric');
            app.WellenperiodeIstwertEditField.Editable = 'off';
            app.mLabel_10 = uilabel(app.GridLayout10);
            app.mLabel_10.Text = 'm';
            app.sLabel_7 = uilabel(app.GridLayout10);
            app.sLabel_7.Text = 's';
            app.AusgabenachBerechnenLabel = uilabel(app.GridLayout10);
            app.AusgabenachBerechnenLabel.Text = '(Ausgabe nach "Berechnen")';
            app.AusgabenachBerechnenLabel_2 = uilabel(app.GridLayout10);
            app.AusgabenachBerechnenLabel_2.Text = '(Ausgabe nach "Berechnen")';
            app.konstanteWellensteilheitTab = uitab(app.TabGroup3);
            app.konstanteWellensteilheitTab.Title = 'konstante Wellensteilheit';
            app.GridLayout4_2 = uigridlayout(app.konstanteWellensteilheitTab);
            app.UIAxes3 = uiaxes(app.GridLayout4_2);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.TabGroup4 = uitabgroup(app.GridLayout4_2);
            app.GrenzfrequnezenTab = uitab(app.TabGroup4);
            app.GrenzfrequnezenTab.Title = 'Grenzfrequnezen';
            app.GridLayout5_2 = uigridlayout(app.GrenzfrequnezenTab);
            app.w_begEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.w_begEditField_2.Limits = [0 Inf];
            app.w_begEditField_2.ValueChangedFcn = createCallbackFcn(app, @w_begEditField_SteilheitValueChanged, true);
            app.w_begEditField_2.Value = 1;
            app.w_begEditField_2Label = uilabel(app.GridLayout5_2);
            app.w_begEditField_2Label.Text = 'w_beg';
            app.w_endEditField_2 = uieditfield(app.GridLayout5_2, 'numeric');
            app.w_endEditField_2.Limits = [0 Inf];
            app.w_endEditField_2.ValueChangedFcn = createCallbackFcn(app, @w_endEditField_SteilheitValueChanged, true);
            app.w_endEditField_2.Value = 10;
            app.w_endEditField_2Label = uilabel(app.GridLayout5_2);
            app.w_endEditField_2Label.Text = 'w_end';
            app.sLabel_8 = uilabel(app.GridLayout5_2);
            app.sLabel_8.Text = '1/s';
            app.sLabel_9 = uilabel(app.GridLayout5_2);
            app.sLabel_9.Text = '1/s';
            app.Label_4 = uilabel(app.GridLayout5_2);
            app.Label_4.Text = '1';
            app.SteilheitEditFieldLabel = uilabel(app.GridLayout5_2);
            app.SteilheitEditFieldLabel.Text = 'Steilheit';
            app.SteilheitEditField = uieditfield(app.GridLayout5_2, 'numeric');
            app.SteilheitEditField.ValueChangedFcn = createCallbackFcn(app, @SteilheitEditFieldValueChanged, true);
            app.SteilheitEditField.Value = 0.2;
            app.AchtungSteilheitskaliertsichberAmlitudeLabel = uilabel(app.GridLayout5_2);
            app.AchtungSteilheitskaliertsichberAmlitudeLabel.Text = {'Achtung, Steilheit skaliert sich '; 'über Amlitude'};
            app.HllkurveTab = uitab(app.TabGroup4);
            app.HllkurveTab.Title = 'Hüllkurve';
            app.GridLayout5_3 = uigridlayout(app.HllkurveTab);
            app.a_linksSliderLabel = uilabel(app.GridLayout5_3);
            app.a_linksSliderLabel.Text = 'a_links';
            app.a_linksSlider = uislider(app.GridLayout5_3);
            app.a_linksSlider.Limits = [0 1];
            app.a_linksSlider.MajorTicks = [0 0.5 1];
            app.a_linksSlider.ValueChangedFcn = createCallbackFcn(app, @a_linksSliderValueChanged, true);
            app.a_linksSlider.Value = 1;
            app.a_rechtsSlider = uislider(app.GridLayout5_3);
            app.a_rechtsSlider.Limits = [0 1];
            app.a_rechtsSlider.MajorTicks = [0 0.5 1];
            app.a_rechtsSlider.ValueChangedFcn = createCallbackFcn(app, @a_rechtsSliderValueChanged, true);
            app.a_rechtsSlider.Value = 1;
            app.a_rechtsSliderLabel = uilabel(app.GridLayout5_3);
            app.a_rechtsSliderLabel.Text = 'a_rechts';
            app.HllkurveanwendenCheckBox = uicheckbox(app.GridLayout4_2);
            app.HllkurveanwendenCheckBox.Text = {'Hüllkurve'; 'anwenden '};
            app.PlotkonstanteWellensteilheitButton = uibutton(app.GridLayout4_2, 'push');
            app.PlotkonstanteWellensteilheitButton.ButtonPushedFcn = createCallbackFcn(app, @PlotkonstanteWellensteilheitButtonPushed, true);
            app.PlotkonstanteWellensteilheitButton.Text = {'Plot konstante '; 'Wellensteilheit'};
            app.TabGroup7 = uitabgroup(app.GridLayout);
            app.ExportWaveTUBTab = uitab(app.TabGroup7);
            app.ExportWaveTUBTab.Title = 'Export WaveTUB';
            app.TabGroup9 = uitabgroup(app.ExportWaveTUBTab);
            app.TabGroup9.SelectionChangedFcn = createCallbackFcn(app, @TabGroup9SelectionChanged, true);
            app.DatenausgabeWaveTUBTab = uitab(app.TabGroup9);
            app.DatenausgabeWaveTUBTab.Title = 'Datenausgabe/WaveTUB';
            app.GridLayout22 = uigridlayout(app.DatenausgabeWaveTUBTab);
            app.AktuellgeladenesWellenpaketPanel = uipanel(app.GridLayout22);
            app.AktuellgeladenesWellenpaketPanel.Title = 'Aktuell geladenes Wellenpaket';
            app.GridLayout23 = uigridlayout(app.AktuellgeladenesWellenpaketPanel);
            app.prowbxproparVariablenAusgabeWaveTUBButton = uibutton(app.GridLayout23, 'push');
            app.prowbxproparVariablenAusgabeWaveTUBButton.ButtonPushedFcn = createCallbackFcn(app, @prowbxproparVariablenAusgabeWaveTUBButtonPushed, true);
            app.prowbxproparVariablenAusgabeWaveTUBButton.Text = 'pro.wbx, pro.par, Variablen Ausgabe, WaveTUB';
            app.neuenAusgabeOrdnererstellenCheckBox = uicheckbox(app.GridLayout23);
            app.neuenAusgabeOrdnererstellenCheckBox.ValueChangedFcn = createCallbackFcn(app, @neuenAusgabeOrdnererstellenCheckBoxValueChanged, true);
            app.neuenAusgabeOrdnererstellenCheckBox.Text = 'neuen Ausgabe Ordner erstellen';
            app.WaveTUBausfhrenCheckBox = uicheckbox(app.GridLayout23);
            app.WaveTUBausfhrenCheckBox.Text = 'WaveTUB ausführen';
            app.WaveTUBausfhrenCheckBox.Value = true;
            app.AusgabedatenladenCheckBox = uicheckbox(app.GridLayout23);
            app.AusgabedatenladenCheckBox.Text = 'Ausgabedaten laden';
            app.ExternesWellenpaketPanel = uipanel(app.GridLayout22);
            app.ExternesWellenpaketPanel.Title = 'Externes Wellenpaket';
            app.GridLayout24 = uigridlayout(app.ExternesWellenpaketPanel);
            app.WaveTUBfrexternesWellenpaketausfhrenButton = uibutton(app.GridLayout24, 'push');
            app.WaveTUBfrexternesWellenpaketausfhrenButton.ButtonPushedFcn = createCallbackFcn(app, @WaveTUBfrexternesWellenpaketausfhrenButtonPushed, true);
            app.WaveTUBfrexternesWellenpaketausfhrenButton.Text = {'WaveTUB für '; 'externes Wellenpaket ausführen '};
            app.AusgabedatenladenCheckBox_2 = uicheckbox(app.GridLayout24);
            app.AusgabedatenladenCheckBox_2.Text = 'Ausgabedaten laden';
            app.KlappenfunktionprowbxTab = uitab(app.TabGroup9);
            app.KlappenfunktionprowbxTab.Title = 'Klappenfunktion (pro.wbx)';
            app.TabGroup6 = uitabgroup(app.KlappenfunktionprowbxTab);
            app.PistonTab = uitab(app.TabGroup6);
            app.PistonTab.Title = 'Piston';
            app.GridLayout9 = uigridlayout(app.PistonTab);
            app.AnwendenPlotButton = uibutton(app.GridLayout9, 'push');
            app.AnwendenPlotButton.ButtonPushedFcn = createCallbackFcn(app, @AnwendenPlotButtonPushed, true);
            app.AnwendenPlotButton.Text = 'Anwenden+Plot';
            app.ExportprowbxButton = uibutton(app.GridLayout9, 'push');
            app.ExportprowbxButton.ButtonPushedFcn = createCallbackFcn(app, @ExportprowbxButtonPushed, true);
            app.ExportprowbxButton.Text = 'Export pro.wbx';
            app.Z1EditFieldLabel = uilabel(app.GridLayout9);
            app.Z1EditFieldLabel.Text = 'Z1';
            app.Z1EditField = uieditfield(app.GridLayout9, 'numeric');
            app.Z1EditField.ValueChangedFcn = createCallbackFcn(app, @WellenMasch_Z1EditField_1ValueChanged, true);
            app.Z1EditField.Value = -2;
            app.a_max_istEditFieldLabel = uilabel(app.GridLayout9);
            app.a_max_istEditFieldLabel.Text = 'a_max_ist';
            app.a_max_istEditField = uieditfield(app.GridLayout9, 'numeric');
            app.a_max_istEditField.Value = 1;
            app.a_max_sollEditFieldLabel = uilabel(app.GridLayout9);
            app.a_max_sollEditFieldLabel.Text = 'a_max_soll';
            app.a_max_sollEditField = uieditfield(app.GridLayout9, 'numeric');
            app.a_max_sollEditField.Value = 1;
            app.T_versatzEditFieldLabel = uilabel(app.GridLayout9);
            app.T_versatzEditFieldLabel.Text = 'T_versatz';
            app.T_versatzEditField = uieditfield(app.GridLayout9, 'numeric');
            app.longEditField_2Label = uilabel(app.GridLayout9);
            app.longEditField_2Label.Text = 'long';
            app.longEditField = uieditfield(app.GridLayout9, 'numeric');
            app.longEditField.ValueChangedFcn = createCallbackFcn(app, @longEditFieldValueChanged, true);
            app.KlappenmodusDropDownLabel = uilabel(app.GridLayout9);
            app.KlappenmodusDropDownLabel.Text = 'Klappenmodus';
            app.KlappenmodusDropDown = uidropdown(app.GridLayout9);
            app.KlappenmodusDropDown.Items = {'Piston', 'singleFlap'};
            app.KlappenmodusDropDown.ValueChangedFcn = createCallbackFcn(app, @KlappenmodusDropDownValueChanged, true);
            app.KlappenmodusDropDown.Value = 'Piston';
            app.singleFlapTab = uitab(app.TabGroup6);
            app.singleFlapTab.Title = 'singleFlap';
            app.dobbleFlapTab = uitab(app.TabGroup6);
            app.dobbleFlapTab.Title = 'dobbleFlap';
            app.ParametereingabeproparTab = uitab(app.TabGroup9);
            app.ParametereingabeproparTab.Title = 'Parametereingabe (pro.par)';
            app.GridLayout12 = uigridlayout(app.ParametereingabeproparTab);
            app.LAENGEEditField_2Label = uilabel(app.GridLayout12);
            app.LAENGEEditField_2Label.Text = 'LAENGE';
            app.LAENGEEditField_anzeige = uieditfield(app.GridLayout12, 'numeric');
            app.LAENGEEditField_anzeige.Editable = 'off';
            app.TIEFEEditField_2Label = uilabel(app.GridLayout12);
            app.TIEFEEditField_2Label.Text = 'TIEFE';
            app.TIEFEEditField_anzeige = uieditfield(app.GridLayout12, 'numeric');
            app.TIEFEEditField_anzeige.Editable = 'off';
            app.Z1EditFieldLabel_2 = uilabel(app.GridLayout12);
            app.Z1EditFieldLabel_2.Text = 'Z1';
            app.Z1EditField_anzeige = uieditfield(app.GridLayout12, 'numeric');
            app.Z1EditField_anzeige.Editable = 'off';
            app.longEditFieldLabel_2 = uilabel(app.GridLayout12);
            app.longEditFieldLabel_2.Text = 'long';
            app.longEditField_anzeige = uieditfield(app.GridLayout12, 'numeric');
            app.longEditField_anzeige.Editable = 'off';
            app.NXEditFieldLabel = uilabel(app.GridLayout12);
            app.NXEditFieldLabel.Text = 'NX';
            app.NXEditField = uieditfield(app.GridLayout12, 'numeric');
            app.NXEditField.Value = 320;
            app.NZEditFieldLabel = uilabel(app.GridLayout12);
            app.NZEditFieldLabel.Text = 'NZ';
            app.NZEditField = uieditfield(app.GridLayout12, 'numeric');
            app.NZEditField.Value = 20;
            app.alphaEditFieldLabel = uilabel(app.GridLayout12);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.GridLayout12, 'numeric');
            app.alphaEditField.Value = 2.25;
            app.DTEditField_2Label = uilabel(app.GridLayout12);
            app.DTEditField_2Label.Text = 'DT';
            app.DTEditField_anzeige = uieditfield(app.GridLayout12, 'numeric');
            app.DTEditField_anzeige.Editable = 'off';
            app.ENDTIMEEditField_2Label = uilabel(app.GridLayout12);
            app.ENDTIMEEditField_2Label.Text = 'ENDTIME';
            app.ENDTIMEEditField_anzeige = uieditfield(app.GridLayout12, 'numeric');
            app.ENDTIMEEditField_anzeige.Editable = 'off';
            app.NFOTOEditFieldLabel = uilabel(app.GridLayout12);
            app.NFOTOEditFieldLabel.Text = 'NFOTO';
            app.NFOTOEditField = uieditfield(app.GridLayout12, 'numeric');
            app.NPEGELEditFieldLabel = uilabel(app.GridLayout12);
            app.NPEGELEditFieldLabel.Text = 'NPEGEL';
            app.NPEGELEditField = uieditfield(app.GridLayout12, 'numeric');
            app.NPEGELEditField.Editable = 'off';
            app.NPEGELEditField.Value = 1;
            app.NTRACKEditFieldLabel = uilabel(app.GridLayout12);
            app.NTRACKEditFieldLabel.Text = 'NTRACK';
            app.NTRACKEditField = uieditfield(app.GridLayout12, 'numeric');
            app.NTRACKEditField.Editable = 'off';
            app.ExportProDateiButton = uibutton(app.GridLayout12, 'push');
            app.ExportProDateiButton.ButtonPushedFcn = createCallbackFcn(app, @ExportProDateiButtonPushed, true);
            app.ExportProDateiButton.Text = 'Export Pro-Datei';
            app.PositionenEditFieldLabel = uilabel(app.GridLayout12);
            app.PositionenEditFieldLabel.Text = 'Positionen';
            app.PositionenEditField = uieditfield(app.GridLayout12, 'text');
            app.PositionenEditField.ValueChangedFcn = createCallbackFcn(app, @PositionenEditFieldValueChanged, true);
            app.PositionenEditField.Value = '2.0 ';
            app.Empfehlungalpha225Nx32mLNz20Label = uilabel(app.GridLayout12);
            app.Empfehlungalpha225Nx32mLNz20Label.Text = 'Empfehlung: alpha=2.25, Nx=32/m (L) Nz=20';
            app.NPEGELPositionenmitLeerzichengetrenntLabel = uilabel(app.GridLayout12);
            app.NPEGELPositionenmitLeerzichengetrenntLabel.Text = 'NPEGEL=#Positionen, mit Leerzichen getrennt';
            app.mitLEditFieldLabel = uilabel(app.GridLayout12);
            app.mitLEditFieldLabel.Text = '   mit L:';
            app.mitLEditField = uieditfield(app.GridLayout12, 'numeric');
            app.mitLEditField.Editable = 'off';
            app.NPEGELPositionenX1X2Y1Y2Label = uilabel(app.GridLayout12);
            app.NPEGELPositionenX1X2Y1Y2Label.Text = 'NPEGEL=#Positionen (X1 X2 ... Y1 Y2...) ';
            app.PostionenXEditFieldLabel = uilabel(app.GridLayout12);
            app.PostionenXEditFieldLabel.Text = 'Postionen X';
            app.PostionenXEditField = uieditfield(app.GridLayout12, 'text');
            app.PostionenXEditField.ValueChangedFcn = createCallbackFcn(app, @PostionenXEditFieldValueChanged, true);
            app.PostionenXEditField.Value = '2 4';
            app.PositionenYEditFieldLabel = uilabel(app.GridLayout12);
            app.PositionenYEditFieldLabel.Text = 'Positionen Y';
            app.PositionenYEditField = uieditfield(app.GridLayout12, 'text');
            app.PositionenYEditField.Value = '-2 -2 ';
            app.PlotTab = uitab(app.TabGroup7);
            app.PlotTab.Title = 'Plot';
            app.TabGroup8 = uitabgroup(app.PlotTab);
            app.OrtsbereichTab = uitab(app.TabGroup8);
            app.OrtsbereichTab.Title = 'Ortsbereich';
            app.GridLayout8 = uigridlayout(app.OrtsbereichTab);
            app.ZeitpunktrelativEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ZeitpunktrelativEditField.Limits = [0 1];
            app.ZeitpunktrelativEditField.ValueChangedFcn = createCallbackFcn(app, @PlotZeitpunktrelativEditFieldValueChanged, true);
            app.ZeitpunktrelativEditFieldLabel = uilabel(app.GridLayout8);
            app.ZeitpunktrelativEditFieldLabel.Text = 'Zeitpunkt (relativ)';
            app.ZeitpunktSlider = uislider(app.GridLayout8);
            app.ZeitpunktSlider.Limits = [0 1];
            app.ZeitpunktSlider.ValueChangedFcn = createCallbackFcn(app, @PlotZeitpunktSliderValueChanged, true);
            app.ZeitpunktSliderLabel = uilabel(app.GridLayout8);
            app.ZeitpunktSliderLabel.Text = 'Zeitpunkt';
            app.tTLabel = uilabel(app.GridLayout8);
            app.tTLabel.Text = 't/T';
            app.Label_5 = uilabel(app.GridLayout8);
            app.Label_5.Text = '-';
            app.ZeitpunktabsolutEditFieldLabel = uilabel(app.GridLayout8);
            app.ZeitpunktabsolutEditFieldLabel.Text = 'Zeitpunkt (absolut)';
            app.ZeitpunktabsolutEditField = uieditfield(app.GridLayout8, 'numeric');
            app.ZeitpunktabsolutEditField.Editable = 'off';
            app.sLabel_10 = uilabel(app.GridLayout8);
            app.sLabel_10.Text = 's';
            app.plotSteilheitCheckBox = uicheckbox(app.GridLayout8);
            app.plotSteilheitCheckBox.Text = 'plot Steilheit';
            app.ZeitbereichTab = uitab(app.TabGroup8);
            app.ZeitbereichTab.Title = 'Zeitbereich';
            app.GridLayout8_2 = uigridlayout(app.ZeitbereichTab);
            app.OrtspunktrelativEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.OrtspunktrelativEditField.Limits = [0 1];
            app.OrtspunktrelativEditField.ValueChangedFcn = createCallbackFcn(app, @PlotOrtspunktrelativEditFieldValueChanged, true);
            app.OrtspunktrelativEditFieldLabel = uilabel(app.GridLayout8_2);
            app.OrtspunktrelativEditFieldLabel.Text = 'Ortspunkt (relativ)';
            app.OrtspunktSlider = uislider(app.GridLayout8_2);
            app.OrtspunktSlider.Limits = [0 1];
            app.OrtspunktSlider.ValueChangedFcn = createCallbackFcn(app, @PlotOrtspunktSliderValueChanged, true);
            app.OrtspunktSliderLabel = uilabel(app.GridLayout8_2);
            app.OrtspunktSliderLabel.Text = 'Ortspunkt';
            app.xLLabel_5 = uilabel(app.GridLayout8_2);
            app.xLLabel_5.Text = 'x/L';
            app.OrtspunktabsolutEditFieldLabel = uilabel(app.GridLayout8_2);
            app.OrtspunktabsolutEditFieldLabel.Text = 'Ortspunkt (absolut)';
            app.OrtspunktabsolutEditField = uieditfield(app.GridLayout8_2, 'numeric');
            app.OrtspunktabsolutEditField.Editable = 'off';
            app.mLabel_11 = uilabel(app.GridLayout8_2);
            app.mLabel_11.Text = 'm';
            app.VideoTab = uitab(app.TabGroup8);
            app.VideoTab.Title = 'Video';
            app.GridLayout8_3 = uigridlayout(app.VideoTab);
            app.KomprimierungSpinnerLabel = uilabel(app.GridLayout8_3);
            app.KomprimierungSpinnerLabel.Text = 'Komprimierung';
            app.KomprimierungSpinner = uispinner(app.GridLayout8_3);
            app.KomprimierungSpinner.Limits = [0 Inf];
            app.KomprimierungSpinner.ValueChangedFcn = createCallbackFcn(app, @VideoKomprimierungSpinnerValueChanged, true);
            app.KomprimierungSpinner.Value = 1;
            app.AbspielgeschindigkeitEditFieldLabel = uilabel(app.GridLayout8_3);
            app.AbspielgeschindigkeitEditFieldLabel.Text = 'Abspielgeschindigkeit';
            app.AbspielgeschindigkeitEditField = uieditfield(app.GridLayout8_3, 'numeric');
            app.AbspielgeschindigkeitEditField.Limits = [0 Inf];
            app.AbspielgeschindigkeitEditField.ValueChangedFcn = createCallbackFcn(app, @VideoAbspielgeschindigkeitEditFieldValueChanged, true);
            app.AbspielgeschindigkeitEditField.Value = 1;
            app.ZoomOrtEditField_2 = uieditfield(app.GridLayout8_3, 'numeric');
            app.ZoomOrtEditField_2.Limits = [0 Inf];
            app.ZoomOrtEditField_2.ValueChangedFcn = createCallbackFcn(app, @VideoZoomOrtEditFieldValueChanged, true);
            app.ZoomOrtEditField_2.Value = 1;
            app.ZoomOrtEditField_2Label = uilabel(app.GridLayout8_3);
            app.ZoomOrtEditField_2Label.Text = 'Zoom Ort';
            app.ZeitfenstervonEditFieldLabel = uilabel(app.GridLayout8_3);
            app.ZeitfenstervonEditFieldLabel.Text = 'Zeitfenster von:';
            app.t_v_begEditField = uieditfield(app.GridLayout8_3, 'numeric');
            app.t_v_begEditField.Limits = [0 1];
            app.bisEditFieldLabel = uilabel(app.GridLayout8_3);
            app.bisEditFieldLabel.Text = 'bis';
            app.t_v_endEditField = uieditfield(app.GridLayout8_3, 'numeric');
            app.t_v_endEditField.Limits = [0 1];
            app.t_v_endEditField.Value = 1;
            app.FramerateberechnetEditField = uieditfield(app.GridLayout8_3, 'numeric');
            app.FramerateberechnetEditField.Editable = 'off';
            app.FramerateberechnetEditFieldLabel = uilabel(app.GridLayout8_3);
            app.FramerateberechnetEditFieldLabel.Text = 'Framerate (berechnet)';
            app.VideolaengeberechnetEditField = uieditfield(app.GridLayout8_3, 'numeric');
            app.VideolaengeberechnetEditField.Editable = 'off';
            app.VideolaengeberechnetEditFieldLabel = uilabel(app.GridLayout8_3);
            app.VideolaengeberechnetEditFieldLabel.Text = 'Videolaenge (berechnet)';
            app.VideoErstellenButton = uibutton(app.GridLayout8_3, 'push');
            app.VideoErstellenButton.ButtonPushedFcn = createCallbackFcn(app, @VideoErstellenButtonPushed, true);
            app.VideoErstellenButton.Text = 'VideoErstellen';
            app.FertigLamp_2 = uilamp(app.GridLayout8_3);
            app.STOPCheckBox = uicheckbox(app.GridLayout8_3);
            app.STOPCheckBox.Text = 'STOP';
            app.Label_7 = uilabel(app.GridLayout8_3);
            app.Label_7.Text = '-';
            app.Label_8 = uilabel(app.GridLayout8_3);
            app.Label_8.Text = '-';
            app.Label_9 = uilabel(app.GridLayout8_3);
            app.Label_9.Text = '-';
            app.sLabel_11 = uilabel(app.GridLayout8_3);
            app.sLabel_11.Text = '1/s';
            app.sLabel_12 = uilabel(app.GridLayout8_3);
            app.sLabel_12.Text = 's';
            app.tTLabel_2 = uilabel(app.GridLayout8_3);
            app.tTLabel_2.Text = 't/T';
            app.ExportWellenkanalTab = uitab(app.TabGroup7);
            app.ExportWellenkanalTab.Title = 'Export Wellenkanal';
            app.PlotWavetubTab = uitab(app.TabGroup10);
            app.PlotWavetubTab.Title = 'Plot Wavetub';
            app.GridLayout16 = uigridlayout(app.PlotWavetubTab);
            app.UIAxesWaveTUBKlein03 = uiaxes(app.GridLayout16);
            xlabel(app.UIAxesWaveTUBKlein03, 'X')
            ylabel(app.UIAxesWaveTUBKlein03, 'Y')
            zlabel(app.UIAxesWaveTUBKlein03, 'Z')
            app.UIAxesWaveTUBPegel = uiaxes(app.GridLayout16);
            title(app.UIAxesWaveTUBPegel, 'Pegel')
            xlabel(app.UIAxesWaveTUBPegel, 'X')
            ylabel(app.UIAxesWaveTUBPegel, 'Y')
            zlabel(app.UIAxesWaveTUBPegel, 'Z')
            app.UIAxesWaveTUBKlein01 = uiaxes(app.GridLayout16);
            xlabel(app.UIAxesWaveTUBKlein01, 'X')
            ylabel(app.UIAxesWaveTUBKlein01, 'Y')
            zlabel(app.UIAxesWaveTUBKlein01, 'Z')
            app.UIAxesWaveTUBKlein02 = uiaxes(app.GridLayout16);
            xlabel(app.UIAxesWaveTUBKlein02, 'X')
            ylabel(app.UIAxesWaveTUBKlein02, 'Y')
            zlabel(app.UIAxesWaveTUBKlein02, 'Z')
            app.UIAxesWaveTUBSurface = uiaxes(app.GridLayout16);
            title(app.UIAxesWaveTUBSurface, 'Surface')
            xlabel(app.UIAxesWaveTUBSurface, 'X')
            ylabel(app.UIAxesWaveTUBSurface, 'Y')
            zlabel(app.UIAxesWaveTUBSurface, 'Z')
            app.UIAxesWaveTUBSurface.ButtonDownFcn = createCallbackFcn(app, @UIAxesWaveTUBSurfaceButtonDown, true);
            app.TabGroup11 = uitabgroup(app.GridLayout16);
            app.Surface_OrtsplotTab = uitab(app.TabGroup11);
            app.Surface_OrtsplotTab.Title = 'Surface_Ortsplot';
            app.TabGroup13 = uitabgroup(app.Surface_OrtsplotTab);
            app.OrtsbereichTab_2 = uitab(app.TabGroup13);
            app.OrtsbereichTab_2.Title = 'Ortsbereich';
            app.GridLayout8_5 = uigridlayout(app.OrtsbereichTab_2);
            app.Zeitpunktrelativsurface_WaveTUBEditField = uieditfield(app.GridLayout8_5, 'numeric');
            app.Zeitpunktrelativsurface_WaveTUBEditField.Limits = [0 1];
            app.Zeitpunktrelativsurface_WaveTUBEditField.ValueChangedFcn = createCallbackFcn(app, @Zeitpunktrelativsurface_WaveTUBEditFieldValueChanged, true);
            app.ZeitpunktrelativEditField_2Label = uilabel(app.GridLayout8_5);
            app.ZeitpunktrelativEditField_2Label.Text = 'Zeitpunkt (relativ)';
            app.Zeitpunkt_WaveTUBSlider = uislider(app.GridLayout8_5);
            app.Zeitpunkt_WaveTUBSlider.Limits = [0 1];
            app.Zeitpunkt_WaveTUBSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Zeitpunkt_WaveTUBSlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.Zeitpunkt_WaveTUBSlider.ValueChangedFcn = createCallbackFcn(app, @Zeitpunkt_WaveTUBSliderValueChanged, true);
            app.ZeitpunktSlider_2Label = uilabel(app.GridLayout8_5);
            app.ZeitpunktSlider_2Label.Text = 'Zeitpunkt';
            app.tTLabel_4 = uilabel(app.GridLayout8_5);
            app.tTLabel_4.Text = 't/T';
            app.sLabel_15 = uilabel(app.GridLayout8_5);
            app.sLabel_15.Text = 's';
            app.plotWellensteilheitundbrechenCheckBox_2 = uicheckbox(app.GridLayout8_5);
            app.plotWellensteilheitundbrechenCheckBox_2.Text = 'plot Wellensteilheit und brechen';
            app.ZeitpunktabsolutEditField_2Label = uilabel(app.GridLayout8_5);
            app.ZeitpunktabsolutEditField_2Label.Text = 'Zeitpunkt (absolut)';
            app.Zeitpunktabsolut_WaveTUBEditField = uieditfield(app.GridLayout8_5, 'numeric');
            app.Zeitpunktabsolut_WaveTUBEditField.ValueChangedFcn = createCallbackFcn(app, @Zeitpunktabsolut_WaveTUBEditFieldValueChanged, true);
            app.VideoTab_2 = uitab(app.TabGroup13);
            app.VideoTab_2.Title = 'Video';
            app.GridLayout8_4 = uigridlayout(app.VideoTab_2);
            app.KomprimierungSpinner_2Label = uilabel(app.GridLayout8_4);
            app.KomprimierungSpinner_2Label.Text = 'Komprimierung';
            app.Komprimierung_WaveTUBSpinner = uispinner(app.GridLayout8_4);
            app.Komprimierung_WaveTUBSpinner.Limits = [0 Inf];
            app.Komprimierung_WaveTUBSpinner.ValueChangedFcn = createCallbackFcn(app, @Komprimierung_WaveTUBSpinnerValueChanged, true);
            app.Komprimierung_WaveTUBSpinner.Value = 1;
            app.AbspielgeschindigkeitEditField_2Label = uilabel(app.GridLayout8_4);
            app.AbspielgeschindigkeitEditField_2Label.Text = 'Abspielgeschindigkeit';
            app.Abspielgeschindigkeit_WaveTUBEditField = uieditfield(app.GridLayout8_4, 'numeric');
            app.Abspielgeschindigkeit_WaveTUBEditField.Limits = [0 Inf];
            app.Abspielgeschindigkeit_WaveTUBEditField.ValueChangedFcn = createCallbackFcn(app, @Abspielgeschindigkeit_WaveTUBEditFieldValueChanged, true);
            app.Abspielgeschindigkeit_WaveTUBEditField.Value = 1;
            app.ZeitfenstervonEditFieldLabel_2 = uilabel(app.GridLayout8_4);
            app.ZeitfenstervonEditFieldLabel_2.Text = 'Zeitfenster von:';
            app.t_v_beg_WaveTUBEditField = uieditfield(app.GridLayout8_4, 'numeric');
            app.t_v_beg_WaveTUBEditField.Limits = [0 1];
            app.bisEditFieldLabel_2 = uilabel(app.GridLayout8_4);
            app.bisEditFieldLabel_2.Text = 'bis';
            app.t_v_end_WaveTUBEditField = uieditfield(app.GridLayout8_4, 'numeric');
            app.t_v_end_WaveTUBEditField.Limits = [0 1];
            app.t_v_end_WaveTUBEditField.Value = 1;
            app.Framerateberechnet_WaveTUBEditField = uieditfield(app.GridLayout8_4, 'numeric');
            app.Framerateberechnet_WaveTUBEditField.Editable = 'off';
            app.FramerateberechnetEditField_2Label = uilabel(app.GridLayout8_4);
            app.FramerateberechnetEditField_2Label.Text = 'Framerate (berechnet)';
            app.Videolaengeberechnet_WaveTUBEditField = uieditfield(app.GridLayout8_4, 'numeric');
            app.Videolaengeberechnet_WaveTUBEditField.Editable = 'off';
            app.VideolaengeberechnetEditField_2Label = uilabel(app.GridLayout8_4);
            app.VideolaengeberechnetEditField_2Label.Text = 'Videolaenge (berechnet)';
            app.VideoErstellen_WaveTUBButton = uibutton(app.GridLayout8_4, 'push');
            app.VideoErstellen_WaveTUBButton.ButtonPushedFcn = createCallbackFcn(app, @VideoErstellen_WaveTUBButtonPushed, true);
            app.VideoErstellen_WaveTUBButton.Text = 'VideoErstellen';
            app.Fertig_WaveTUBLamp = uilamp(app.GridLayout8_4);
            app.Label_10 = uilabel(app.GridLayout8_4);
            app.Label_10.Text = '-';
            app.Label_11 = uilabel(app.GridLayout8_4);
            app.Label_11.Text = '-';
            app.sLabel_13 = uilabel(app.GridLayout8_4);
            app.sLabel_13.Text = '1/s';
            app.sLabel_14 = uilabel(app.GridLayout8_4);
            app.sLabel_14.Text = 's';
            app.tTLabel_3 = uilabel(app.GridLayout8_4);
            app.tTLabel_3.Text = 't/T';
            app.WellensteilheitundBrechenCheckBox = uicheckbox(app.GridLayout8_4);
            app.WellensteilheitundBrechenCheckBox.Text = 'Wellensteilheit und Brechen';
            app.STOP_WaveTUBCheckBox = uicheckbox(app.GridLayout8_4);
            app.STOP_WaveTUBCheckBox.Text = 'STOP';
            app.IdealverlaufplottenCheckBox = uicheckbox(app.GridLayout8_4);
            app.IdealverlaufplottenCheckBox.Text = 'Idealverlauf plotten';
            app.Surface_ZeitplotTab = uitab(app.TabGroup11);
            app.Surface_ZeitplotTab.Title = 'Surface_Zeitplot';
            app.GridLayout8_6 = uigridlayout(app.Surface_ZeitplotTab);
            app.Ortspunktrelativsurface_WaveTUBEditField = uieditfield(app.GridLayout8_6, 'numeric');
            app.Ortspunktrelativsurface_WaveTUBEditField.Limits = [0 1];
            app.Ortspunktrelativsurface_WaveTUBEditField.ValueChangedFcn = createCallbackFcn(app, @Ortspunktrelativsurface_WaveTUBEditFieldValueChanged, true);
            app.ZeitpunktrelativEditField_2Label_2 = uilabel(app.GridLayout8_6);
            app.ZeitpunktrelativEditField_2Label_2.Text = 'Ortspunkt (relativ)';
            app.Ortspunkt_WaveTUBSlider = uislider(app.GridLayout8_6);
            app.Ortspunkt_WaveTUBSlider.Limits = [0 1];
            app.Ortspunkt_WaveTUBSlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.Ortspunkt_WaveTUBSlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.Ortspunkt_WaveTUBSlider.ValueChangedFcn = createCallbackFcn(app, @Ortspunkt_WaveTUBSliderValueChanged, true);
            app.ZeitpunktSlider_2Label_2 = uilabel(app.GridLayout8_6);
            app.ZeitpunktSlider_2Label_2.Text = 'Ortspunkt';
            app.mLLabel = uilabel(app.GridLayout8_6);
            app.mLLabel.Text = 'm/L';
            app.ZeitpunktabsolutEditField_2Label_2 = uilabel(app.GridLayout8_6);
            app.ZeitpunktabsolutEditField_2Label_2.Text = 'Ortspunkt (absolut)';
            app.Ortspunktabsolut_WaveTUBEditField = uieditfield(app.GridLayout8_6, 'numeric');
            app.Ortspunktabsolut_WaveTUBEditField.ValueChangedFcn = createCallbackFcn(app, @Ortspunktabsolut_WaveTUBEditFieldValueChanged, true);
            app.mLabel_12 = uilabel(app.GridLayout8_6);
            app.mLabel_12.Text = 'm';
            app.plotWellensteilheitundbrechenCheckBox = uicheckbox(app.GridLayout8_6);
            app.plotWellensteilheitundbrechenCheckBox.Text = 'plot Wellensteilheit und brechen';
            app.PartikelTrackTab = uitab(app.TabGroup11);
            app.PartikelTrackTab.Title = 'Partikel Track';
            app.GridLayout18_2 = uigridlayout(app.PartikelTrackTab);
            app.GridLayout19_2 = uigridlayout(app.GridLayout18_2);
            app.TrackauswhlenDropDownLabel = uilabel(app.GridLayout19_2);
            app.TrackauswhlenDropDownLabel.Text = 'Track auswählen';
            app.TrackauswhlenDropDown = uidropdown(app.GridLayout19_2);
            app.TrackauswhlenDropDown.Editable = 'on';
            app.FensterauswhlenLabel_2 = uilabel(app.GridLayout19_2);
            app.FensterauswhlenLabel_2.Text = 'Fenster auswählen:';
            app.PlotnachTrackDropDown = uidropdown(app.GridLayout19_2);
            app.PlotnachTrackDropDown.Items = {'Fenster_01', 'Fenster_02 ', 'Fenster_03'};
            app.PlotnachTrackDropDown.Value = 'Fenster_01';
            app.PlotUITrackButton = uibutton(app.GridLayout19_2, 'push');
            app.PlotUITrackButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUITrackButtonPushed2, true);
            app.PlotUITrackButton.Text = 'Plot UI Track';
            app.FouriertransformationTab = uitab(app.TabGroup11);
            app.FouriertransformationTab.Title = 'Fouriertransformation';
            app.GridLayout21 = uigridlayout(app.FouriertransformationTab);
            app.PegelauswhlenDropDownLabel = uilabel(app.GridLayout21);
            app.PegelauswhlenDropDownLabel.Text = 'Pegel auswählen';
            app.PegelauswhlenDropDown = uidropdown(app.GridLayout21);
            app.FensterauswhlenLabel = uilabel(app.GridLayout21);
            app.FensterauswhlenLabel.Text = 'Fenster auswählen';
            app.PlotnachFourierDropDown = uidropdown(app.GridLayout21);
            app.PlotnachFourierDropDown.Items = {'Fenster_01', 'Fenster_02', 'Fenster_03'};
            app.PlotnachFourierDropDown.Value = 'Fenster_01';
            app.plotUIFourierButton = uibutton(app.GridLayout21, 'state');
            app.plotUIFourierButton.ValueChangedFcn = createCallbackFcn(app, @plotUIFourierButtonValueChanged, true);
            app.plotUIFourierButton.Text = 'plot UI Fourier';
            app.plotbiswEditFieldLabel = uilabel(app.GridLayout21);
            app.plotbiswEditFieldLabel.Text = 'plot bis w:';
            app.plotbiswEditField = uieditfield(app.GridLayout21, 'numeric');
            app.EinzelpegelTab = uitab(app.TabGroup11);
            app.EinzelpegelTab.Title = 'Einzelpegel';
            app.GridLayout18 = uigridlayout(app.EinzelpegelTab);
            app.GridLayout19_3 = uigridlayout(app.GridLayout18);
            app.PegelauswahlDropDownLabel = uilabel(app.GridLayout19_3);
            app.PegelauswahlDropDownLabel.Text = 'Pegelauswahl';
            app.PegelDropDown = uidropdown(app.GridLayout19_3);
            app.PlotUIPegelButton = uibutton(app.GridLayout19_3, 'push');
            app.PlotUIPegelButton.ButtonPushedFcn = createCallbackFcn(app, @PlotUIPegelButtonPushed, true);
            app.PlotUIPegelButton.Text = 'Plot UI Pegel';
            app.TabGroup12 = uitabgroup(app.GridLayout16);
            app.VersuchsdatenTab = uitab(app.TabGroup12);
            app.VersuchsdatenTab.Title = 'Versuchsdaten';
            app.TableVersuchsdaten = uitable(app.VersuchsdatenTab);
            app.TableVersuchsdaten.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.TableVersuchsdaten.RowName = {};
            app.DateiInfosTab = uitab(app.TabGroup12);
            app.DateiInfosTab.Title = 'DateiInfos';
            app.TableDateiinfos = uitable(app.DateiInfosTab);
            app.TableDateiinfos.ColumnName = {'Variable'; 'Wert'; 'Erklärung'};
            app.TableDateiinfos.RowName = {};
            app.GridLayout17 = uigridlayout(app.GridLayout16);
            app.VersuchLadenButton = uibutton(app.GridLayout17, 'push');
            app.VersuchLadenButton.ButtonPushedFcn = createCallbackFcn(app, @VersuchLadenButtonPushed, true);
            app.VersuchLadenButton.Text = 'Versuch Laden';
            app.Lamp = uilamp(app.GridLayout17);
            app.DataDateispeichernautomatischbeimLadenCheckBox = uicheckbox(app.GridLayout17);
            app.DataDateispeichernautomatischbeimLadenCheckBox.Text = {'"Data" Datei speichern?'; '(automatisch beim Laden)'};
            app.DataDateispeichernautomatischbeimLadenCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WellenpaketGenerator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end